classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveMenu                        matlab.ui.container.Menu
        SaveFiguresMenu                 matlab.ui.container.Menu
        SaveOutputLogMenu               matlab.ui.container.Menu
        ScreenshotMenu                  matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Step1                       matlab.ui.container.Tab
        ResultsTextAreaLabel            matlab.ui.control.Label
        ProjectDirectoryPanel           matlab.ui.container.Panel
        SelectFolderButton              matlab.ui.control.Button
        SelectedDirectoryEditField      matlab.ui.control.EditField
        SelectedDirectoryEditFieldLabel  matlab.ui.control.Label
        SelectDataPanel                 matlab.ui.container.Panel
        LFPDataDropDown                 matlab.ui.control.DropDown
        Data_fs                         matlab.ui.control.EditField
        HzLabel_4                       matlab.ui.control.Label
        SamplingRateLabel               matlab.ui.control.Label
        LFPDataDropDownLabel            matlab.ui.control.Label
        FitPSDPanel                     matlab.ui.container.Panel
        FreqBandEditField1              matlab.ui.control.NumericEditField
        FreqBandEditField2              matlab.ui.control.NumericEditField
        HzLabel_2                       matlab.ui.control.Label
        FitRangeButtonGroup             matlab.ui.container.ButtonGroup
        AutofitButton                   matlab.ui.control.RadioButton
        SpecifyButton_FitRange          matlab.ui.control.RadioButton
        FitRangeEditField1              matlab.ui.control.NumericEditField
        FitRangeEditField2              matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        EditField_2Label                matlab.ui.control.Label
        DBThresholdButtonGroup          matlab.ui.container.ButtonGroup
        DefaultButton_tDB               matlab.ui.control.RadioButton
        SpecifyButton_tDB               matlab.ui.control.RadioButton
        tDBEditField                    matlab.ui.control.NumericEditField
        tDBLabel                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OptionsButton_PSD               matlab.ui.control.Button
        FitPsdButton                    matlab.ui.control.Button
        SignalFrequencyBandLabel        matlab.ui.control.Label
        CharacterizeBurstsandFitProbabilityDistributionPanel  matlab.ui.container.Panel
        UnitofAmplitudeEditFieldLabel   matlab.ui.control.Label
        NBinsButtonGroup                matlab.ui.container.ButtonGroup
        DefaultButton_NBins             matlab.ui.control.RadioButton
        SpecifyButton_NBins             matlab.ui.control.RadioButton
        NBinsEditField                  matlab.ui.control.NumericEditField
        UnitofAmplitudeEditField        matlab.ui.control.EditField
        OptionsButton_Burst             matlab.ui.control.Button
        FitBurstsButton                 matlab.ui.control.Button
        SaveCharacterizationDataPanel   matlab.ui.container.Panel
        EditField_Dataname              matlab.ui.control.EditField
        AlsoSaveBurstStatisticsCheckBox  matlab.ui.control.CheckBox
        SaveCharacterizationDataButton  matlab.ui.control.Button
        ResultsTextArea_1               matlab.ui.control.TextArea
        TabGroup1                       matlab.ui.container.TabGroup
        linearscaleTab                  matlab.ui.container.Tab
        Tab_linear                      matlab.ui.container.TabGroup
        APTab                           matlab.ui.container.Tab
        UIAxes2_1                       matlab.ui.control.UIAxes
        CNTab                           matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        BFTab                           matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        logscaleTab                     matlab.ui.container.Tab
        Tab_log                         matlab.ui.container.TabGroup
        APTab_2                         matlab.ui.container.Tab
        UIAxes2_4                       matlab.ui.control.UIAxes
        CNTab_2                         matlab.ui.container.Tab
        UIAxes2_5                       matlab.ui.control.UIAxes
        BFTab_2                         matlab.ui.container.Tab
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
        Tab_Step2                       matlab.ui.container.Tab
        ResultsTextArea_2Label          matlab.ui.control.Label
        CharacterizationDataPanel       matlab.ui.container.Panel
        SelectRealDataButton            matlab.ui.control.Button
        EditField_SelectRealDataname    matlab.ui.control.EditField
        SynthesisSettingsPanel          matlab.ui.container.Panel
        SamplingRateGroup               matlab.ui.container.ButtonGroup
        SyntheticSamplingRateLabel      matlab.ui.control.Label
        DefaultButton_fs                matlab.ui.control.RadioButton
        SpecifyButton_fs                matlab.ui.control.RadioButton
        fsEditField                     matlab.ui.control.NumericEditField
        HzLabel_3                       matlab.ui.control.Label
        RngSeedEditField                matlab.ui.control.NumericEditField
        OptionsButton_SynSettings       matlab.ui.control.Button
        LoadRealDataButton              matlab.ui.control.Button
        RngSeedLabel                    matlab.ui.control.Label
        OptimizeAmplitudeDistributionParametersPanel  matlab.ui.container.Panel
        SyntheticLengthOptimButtonGroup  matlab.ui.container.ButtonGroup
        DefaultButton_SynlenOptim       matlab.ui.control.RadioButton
        SpecifyButton_SynlenOptim       matlab.ui.control.RadioButton
        SynlenOptimEditField            matlab.ui.control.NumericEditField
        secondsLabel                    matlab.ui.control.Label
        DistributionTypesListBox        matlab.ui.control.ListBox
        PopulationDropDown              matlab.ui.control.DropDown
        ResetButton                     matlab.ui.control.Button
        nPopEditField                   matlab.ui.control.NumericEditField
        ButtonGroup_FitTarget           matlab.ui.container.ButtonGroup
        FitTargetLabel                  matlab.ui.control.Label
        Fit_AP                          matlab.ui.control.RadioButton
        Fit_Amp                         matlab.ui.control.RadioButton
        OptionsButton_Optim             matlab.ui.control.Button
        FitAmpButton                    matlab.ui.control.Button
        PopulationDropDownLabel         matlab.ui.control.Label
        DistributionTypesListBoxLabel   matlab.ui.control.Label
        GenerateSyntheticLFPPanel       matlab.ui.container.Panel
        SyntheticLengthButtonGroup      matlab.ui.container.ButtonGroup
        DefaultButton_Synlen            matlab.ui.control.RadioButton
        SpecifyButton_Synlen            matlab.ui.control.RadioButton
        SynlenEditField                 matlab.ui.control.NumericEditField
        secondsLabel2                   matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        SaveSynthesisParametersPanel    matlab.ui.container.Panel
        EditField_SynParamDataname      matlab.ui.control.EditField
        AlsoSaveSyntheticLFPCheckBox    matlab.ui.control.CheckBox
        SaveSyntheticButton             matlab.ui.control.Button
        ResultsTextArea_2               matlab.ui.control.TextArea
        TabGroup2                       matlab.ui.container.TabGroup
        PSDTab                          matlab.ui.container.Tab
        UIAxes4_1                       matlab.ui.control.UIAxes
        BurstStatsTab                   matlab.ui.container.Tab
        Tab_Bursts                      matlab.ui.container.TabGroup
        APTab_3                         matlab.ui.container.Tab
        UIAxes4_2                       matlab.ui.control.UIAxes
        CNTab_3                         matlab.ui.container.Tab
        UIAxes4_3                       matlab.ui.control.UIAxes
        BFTab_3                         matlab.ui.container.Tab
        UIAxes4_4                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        Tab_Step3                       matlab.ui.container.Tab
        ResultsTextArea_3Label          matlab.ui.control.Label
        SyntheticDataPanel              matlab.ui.container.Panel
        SelectSynDataButton             matlab.ui.control.Button
        EditField_SelectSynDataname     matlab.ui.control.EditField
        ReviewSettingsButton            matlab.ui.control.Button
        LoadSynDataButton               matlab.ui.control.Button
        PreselectedBoundsPanel          matlab.ui.container.Panel
        ButtonGroup_LowerBound          matlab.ui.container.ButtonGroup
        LowerBoundLLabel                matlab.ui.control.Label
        DefaultButton_LowerBound        matlab.ui.control.RadioButton
        SpecifyButton_LowerBound        matlab.ui.control.RadioButton
        EditField_LowerBound            matlab.ui.control.NumericEditField
        EditField_LowerBound_sig        matlab.ui.control.NumericEditField
        ButtonGroup_UpperBound          matlab.ui.container.ButtonGroup
        UpperBoundULabel                matlab.ui.control.Label
        DefaultButton_UpperBound        matlab.ui.control.RadioButton
        SpecifyButton_UpperBound        matlab.ui.control.RadioButton
        EditField_UpperBound            matlab.ui.control.NumericEditField
        EditField_UpperBound_sig        matlab.ui.control.NumericEditField
        ButtonGroup_LowerBound_prop     matlab.ui.container.ButtonGroup
        EditField_TL                    matlab.ui.control.NumericEditField
        FalsePeakRateEditField          matlab.ui.control.NumericEditField
        FalsepeakrateEditField_perc     matlab.ui.control.NumericEditField
        FalsePeakRateEditFieldLabel     matlab.ui.control.Label
        TimeLLabel                      matlab.ui.control.Label
        ButtonGroup_UpperBound_prop     matlab.ui.container.ButtonGroup
        TruePeakRateEditFieldLabel      matlab.ui.control.Label
        EditField_TU                    matlab.ui.control.NumericEditField
        TruePeakRateEditField           matlab.ui.control.NumericEditField
        TruepeakrateEditField_perc      matlab.ui.control.NumericEditField
        TimeULabel                      matlab.ui.control.Label
        PreboundButton                  matlab.ui.control.Button
        DetectionPerformanceandROCCurvePanel  matlab.ui.container.Panel
        ButtonGroup_NThresholds         matlab.ui.container.ButtonGroup
        NumberofThresholdsLabel         matlab.ui.control.Label
        DefaultButton_NThresh           matlab.ui.control.RadioButton
        SpecifyButton_NThresh           matlab.ui.control.RadioButton
        EditField_NThresh               matlab.ui.control.NumericEditField
        ButtonGroup_DetectOn            matlab.ui.container.ButtonGroup
        DetectonLabel                   matlab.ui.control.Label
        Detect_AP                       matlab.ui.control.RadioButton
        Detect_Amp                      matlab.ui.control.RadioButton
        DetectButton                    matlab.ui.control.Button
        DetectionThresholdLabel         matlab.ui.control.Label
        EditField_Threshold             matlab.ui.control.NumericEditField
        Slider_Threshold                matlab.ui.control.Slider
        EditField_Threshold_slider      matlab.ui.control.NumericEditField
        VLabel                          matlab.ui.control.Label
        EditField_CFAR                  matlab.ui.control.NumericEditField
        FalsePositiveRateLabel          matlab.ui.control.Label
        Slider_CFAR                     matlab.ui.control.Slider
        EditField_CFAR_slider           matlab.ui.control.NumericEditField
        FPRLabel                        matlab.ui.control.Label
        ProbabilityGivenDetection       matlab.ui.container.Panel
        ProbabilityGivenDetectionLabel  matlab.ui.control.Label
        DropDown_Condition              matlab.ui.control.DropDown
        EditField_PF                    matlab.ui.control.NumericEditField
        EditField_PI                    matlab.ui.control.NumericEditField
        EditField_PT                    matlab.ui.control.NumericEditField
        TruePPVLabel                    matlab.ui.control.Label
        IntermediateLabel               matlab.ui.control.Label
        FalseFDRLabel                   matlab.ui.control.Label
        ResultsTextArea_3               matlab.ui.control.TextArea
        Button_Normalize                matlab.ui.control.StateButton
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % gui items
        menu_timer  % for variable menu update
        step1_stage = 0 % 0:start, 1:data ready, 2:PSD fit, 3:burst fit
        step2_stage = 0 % 0:start, 1:setup done, 2:Amp fit, 3:generate synthetic data
        step3_stage = 0 % 0:start, 1:review, 2:data ready, 3:pre-bounds, 4:detection
        Panels      % list of figure axes
        h_3         % handles for plot 3
        h_5         % handles for plot 5
        AmpOptimDone
        % parameters
        default     % default parameter values
        specified   % specified parameter values
        data_fs = 1000  % sampling rate of real data
        AmpDistTypes = {'gamma','lognormal','exponential'};
        % data
        cache_attr  % data attributes that need cache
        LFP_seg
        RealData
        BurstStats
        SynParam_h
        SynData
        CompositeTrace
        SynStats
        BoundStats
        Detection
        % file
        working_dir
        suffix = {'_Charac','_SynParam','_SynData','_BurstStats','_SynResult'};
        % menu
        SavefigApp  % window for saving figures
        Options1_1App   % window for options
        Options1_2App
        Options2_1App
        Options2_2App
    end
    
    properties (Access = public)
        PanelNames  % names of panels
        CreatedPanels   % whether each panel is created
        PSOparams_default   % PSO parameters
        PSOparams   % PSO parameters
    end
    
    methods (Access = private)

        function update_menu(app,~,~)
            vars = evalin('base','whos');
            varnames = cell(size(vars));
            for i = 1:numel(varnames)
                varnames{i} = vars(i).name;
            end
            app.LFPDataDropDown.Items = ['__Select__';varnames(:)];
        end
        
        function create_panels(app)
            app.Panels = {app.UIAxes_1, ...
                [app.UIAxes2_1,app.UIAxes2_2,app.UIAxes2_3, ...
                app.UIAxes2_4,app.UIAxes2_5,app.UIAxes2_6], ...
                app.UIAxes3, ...
                app.UIAxes4_1, ...
                [app.UIAxes4_2,app.UIAxes4_3,app.UIAxes4_4], ...
                [app.UIAxes5,app.UIAxes6]};
            app.PanelNames = {'Fit PSD', 'Burst Statistics','Amplitude Optimization', ...
                'Synthetic PSD','Synthetic Burst Statistics','Detection Evaluation'};
            app.CreatedPanels = false(1,numel(app.Panels));
        end

        function panels_created(app,panels)
            app.CreatedPanels(panels) = true;
            app.SaveFiguresMenu.Enable = "on";
        end

        function clearallaxes(app,panels)
            for p = panels
                for ax = app.Panels{p}
                    cla(ax,'reset');
                end
                app.CreatedPanels(p) = false;
            end
            if ~any(app.CreatedPanels)
                app.SaveFiguresMenu.Enable = "off";
            end
        end
        
        function create_default_parameters(app)
            % PSO population size corresponding to AmpDistTypes
            nPop = cell2struct({12,12,8},app.AmpDistTypes,2);
            % Set default values
            app.default = struct( ...
                'tDB',1., ...           % threshold for fitting PSD
                'nfft',8192, ...        % window size estimating PSD (NFFT)
                'smooth_win',2, ...     % window size for smoothing PSD (Hz)
                'sample_density',50, ...% density of sample frequency points in natural logarithm per neper
                'FOOOF',false, ...      % whether use FOOOF algorithm
                'peak_width_limits1',2, ... % FOOOF peak width lowerer limit
                'peak_width_limits2',Inf, ...   % FOOOF peak width upper limit
                'max_n_peaks',6, ...    % FOOOF maximum number of peaks
                'aperiodic_mode','knee', ...    % FOOOF fitting approach for the aperiodic component
                'butter_order',6, ...   % butterworth filter order
                'ZS_threshold',2, ...   % amplitude Z-score threshold for burst detection
                'stop_perc',0.25, ...   % proportion of peak value at which burst duration stops
                'freq_resolution',0.25, ... % resolution for burst frequency
                'NBins',30, ...         % number of histogram bins
                'fs',1000, ...          % sampling rate of synthetic data
                'correlated',true, ...  % generate correlated attributes AP, CN, BF
                'width_edge',3, ...     % burst atom width, multiple of sigma of gaussian envelope
                'empr_CN',true, ...     % use emperical distribution of cycle number
                'empr_BF',true, ...     % use emperical distribution of burst frequency
                'power_match',false, ...% match exact burst power instead of estimated
                'SynlenOptim',1000, ... % synthetic length for optimization
                'div_type','KL', ...    % distribution divergence type used for cost function
                'Synlen',5000, ...      % synthetic length for generation
                'nPop',nPop, ...        % population size for each distribution type
                'LowerBound_sig',2^.5, ... % preselected lower bound
                'UpperBound_sig',2^.5, ... % preselected upper bound
                'NThresh',50 ...
                );
            app.specified = app.default;
        end

        function create_PSO_parameters(app)
            % PSO algorithm parameters
            app.PSOparams_default = struct( ...
                'nPop',12, ...          % Population Size (Swarm Size)
                'MinIt',15, ...         % Minimum Number of Iterations
                'MaxIt',30, ...         % Maximum Number of Iterations
                'MaxStall',5, ...       % Terminate if change in best cost is ...
                'FunTol',1e-5, ...      % less than FunTol over MaxStall iterations
                'w',1, ...              % Intertia Coefficient
                'wdamp',0.92, ...       % Damping Ratio of Inertia Coefficient
                'c1',2, ...             % Personal Acceleration Coefficient
                'c2',2, ...             % Social Acceleration Coefficient
                'withNeighbor',true, ...% Flag for using Neiborhood Operator
                'NeighborSize',[.3,1], ...  % Neighborhood Size (<=1 fraction, >1 number)
                'NeighborPeriod',3, ... % Number of iterations last for the same group of neighborhood
                'InitVel',true, ...     % Random Non-Zero Initial Velocity
                'randseed',0 ...        % Random seed
                );
            % PSO analysis parameters
            app.PSOparams_default.ShowIterInfo = false; % Flag for Showing Iteration Informatin
            app.PSOparams_default.Visualize = false;
            app.PSOparams = app.PSOparams_default;
        end

        function append_textbox(~,textbox,message)
            if iscell(message)
                textbox.Value = [textbox.Value;message(:)];
            elseif size(message,1)>1
                message = mat2cell(message,ones(1,size(message,1)));
                textbox.Value = [textbox.Value;message];
            else
                textbox.Value{end+1} = message;
            end
            try
                scroll(textbox,'bottom');
            catch
            end
            drawnow;
        end
        
        function reset_attr(app,step)
            attrs = app.cache_attr{step};
            for i = 1:numel(attrs)
                app.(attrs{i}) = [];
            end
        end
        
        function set_step1_stage(app,n)
            app.step1_stage = n;
            buttons = [app.FitPsdButton,app.FitBurstsButton, ...
                app.SaveCharacterizationDataButton,app.AlsoSaveBurstStatisticsCheckBox];
            button_step = [1,2,3,3];
            for i = 1:numel(buttons)
                if n<button_step(i)
                    buttons(i).Enable = 'off';
                else
                    buttons(i).Enable = 'on';
                end
            end
            if n==0
                app.reset_attr(1);
                app.LFPDataDropDown.Value = app.LFPDataDropDown.Items{1};
            end
            if app.step2_stage>0 || app.step3_stage>0
                app.set_step2_stage(0); % also sets step3_stage to 0
            end
        end
        
        function set_step2_stage(app,n)
            app.step2_stage = n;
            buttons = [app.FitAmpButton,app.SaveSyntheticButton, ...
                app.GenerateButton,app.AlsoSaveSyntheticLFPCheckBox];
            button_step = [1,2,2,3];
            for i = 1:numel(buttons)
                if n<button_step(i)
                    buttons(i).Enable = 'off';
                else
                    buttons(i).Enable = 'on';
                end
            end
            if n==0
                app.reset_attr(2);
                app.set_step3_stage(0);
            elseif app.step3_stage>1
                app.reset_attr(3);
                app.set_step3_stage(1);
            end
            if n<2
                app.AmpOptimDone(:) = false;
                app.DistributionTypeDropDown.Items = {};
                app.clearallaxes(3:5);
            end
            if n<3
                app.AlsoSaveSyntheticLFPCheckBox.Value = false;
            end
        end
        
        function set_step3_stage(app,n)
            app.step3_stage = n;
            dct_bttns = get(app.DetectionPerformanceandROCCurvePanel,'Children');
            excld = [app.ProbabilityGivenDetection,app.DetectButton, ...
                app.ButtonGroup_NThresholds,app.ButtonGroup_DetectOn];
            idx = arrayfun(@(x) ismember(x,excld),dct_bttns);
            dct_bttns = [dct_bttns(~idx);get(excld(1),'Children')]';
            buttons = [app.PreboundButton,app.SpecifyButton_LowerBound, ...
                app.EditField_LowerBound,app.EditField_LowerBound_sig, ...
                app.SpecifyButton_UpperBound,app.EditField_UpperBound, ...
                app.EditField_UpperBound_sig,app.DetectButton, ...
                dct_bttns];
            button_step = [2*ones(1,7),3,4*ones(1,numel(dct_bttns))];
            for i = 1:numel(buttons)
                if n<button_step(i)
                    buttons(i).Enable = 'off';
                else
                    buttons(i).Enable = 'on';
                end
            end
            if n<4
                app.Button_Normalize.Visible = 'off';
                app.Button_Normalize.Enable = 'off';
                if n<3
                    app.clearallaxes(6);
                    app.Button_Normalize.Value = false;
                end
            end
            if n==0
                app.reset_attr(3);
            end
        end

        function Generate_Synthetic_Data(app)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_2,msg);
            msgfcn('Generating synthetic LFP data...');
            SynParam = app.SynParam_h;
            SynParam.randseed = app.RngSeedEditField.Value;
            SynParam.syn_len = app.SynlenEditField.Value;
            SynParam.amp_dist_type = app.DistributionTypeDropDown.Value;
            try
                cd(app.working_dir{2});
                SynParam.setup;
            catch
                uialert(app.UIFigure,'Characterization data missing','File Error');
                cd(app.working_dir{1}); return;
            end
            cd(app.working_dir{1});
            app.SynData = gen_synthetic(SynParam);
            app.CompositeTrace = app.SynData.BackgroundTrace+app.SynData.SignalTrace;
            msgfcn(sprintf('Synthetic LFP is generated.\n'));
        end
        
        function SynP = PlotSynPSD(app)
            SynParam = app.SynParam_h;
            [~,~,Nfft,bg_range_i] = SynParam.psd_background;
            [pxx_syn,~] = pwelch(app.CompositeTrace,Nfft,[],Nfft,SynParam.fs);
            pxx_syn([1,end]) = pxx_syn([1,end])*2;
            [pxx_bg,f] = pwelch(app.SynData.BackgroundTrace,Nfft,[],Nfft,SynParam.fs);
            pxx_bg([1,end]) = pxx_bg([1,end])*2;
            
            ax = app.Panels{4}; hold(ax,'on');
            plot(ax,SynParam.rdat.f,pow2db(SynParam.rdat.PSD),'g');
            plot(ax,f,pow2db(pxx_syn),'b');
            plot(ax,f,pow2db(pxx_bg),'r');
            xlim(ax,SynParam.rdat.f(SynParam.rdat.i_fit_range));
            ylim(ax,'auto');
            legend(ax,{'Observed LFP','Synthetic LFP','Synthetic background'});
            xlabel(ax,'Frequency (Hz)');   ylabel(ax,'PSD (dB/Hz)');
            app.panels_created(4);
            drawnow;
            
            SynP = struct('fs',SynParam.fs,'tDB',SynParam.rdat.tDB,'sig_range_f',SynParam.rdat.sig_range_f, ...
                'T_length',SynParam.syn_len,'valid_seg_id',1,'f',f,'PSD_smoo',pxx_syn, ...
                'bg_range_i',bg_range_i,'fit_a',SynParam.rdat.fit_a,'fit_b',SynParam.rdat.fit_b);
            if isfield(SynParam.rdat,'aperiodic_params')
                SynP.aperiodic_params = SynParam.rdat.aperiodic_params;
            end
        end
        
        function [SynResults,SynStats] = PlotBurstStats(app,SynP)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_2,msg);
            SynParam = app.SynParam_h;
            butter_order = SynParam.rdat.butter_order;
            threshold = SynParam.rdat.threshold;
            stop_perc = SynParam.rdat.stop_perc;
            freq_resolution = SynParam.rdat.freq_resolution;
            unit = SynParam.rdat.unit;
            [SynResults,SynStats] = getAllBurstAttr(SynP,{app.CompositeTrace},butter_order, ...
                'threshold',threshold,'stop_perc',stop_perc,'freq_resolution',freq_resolution, ...
                'unit',unit,'display',1,'fit_amp',0,'msgfcn',msgfcn);
            SynResults = getBurstDistributions(SynResults,SynStats,'plot',1, ...
                'plot_logscale',0,'display',1,'axes',app.Panels{5},'msgfcn',msgfcn);
            app.panels_created(5);
            msgfcn('');
            drawnow;
        end
        
        function flag = LoadSynData(app)
            flag = true;
            if app.step3_stage>0
                msg = ['Data already loaded. Reloading will clear ', ...
                    'existing results. Do you want to continue?'];
                selection = uiconfirm(app.UIFigure,msg,'Confirm Selection');
                if isequal(selection,'Cancel'), return; end
                app.append_textbox(app.ResultsTextArea_3,'');
            end
            
            filepath = app.EditField_SelectSynDataname.Value;
            if ~isfile(filepath)
                uialert(app.UIFigure,'File not found','Path Error');    return;
            end
            dname = fileparts(filepath);
            app.working_dir = {pwd,dname};
            
            SynFile = load(filepath);
            if ~isfield(SynFile,'SynParam') || ~isa(SynFile.SynParam,'SynthParam')
                uialert(app.UIFigure,'Selected file does not contain "SynthParam" class', ...
                    'File Error');  return;
            end
            try
                cd(app.working_dir{2});
                SynFile.SynParam.setup;
            catch
                uialert(app.UIFigure,'Characterization data missing','File Error');
                cd(app.working_dir{1}); return;
            end
            cd(app.working_dir{1});
            
            app.set_step2_stage(0); % also sets step3_stage to 0
            app.SynParam_h = SynFile.SynParam;
            if isfield(SynFile,'SynData')
                app.SynData = SynFile.SynData;
            end
            flag = false;
        end
        
        function Thresh2FPR(app)
            FPR = interp1(app.Detection.thresholds,app.Detection.fpr, ...
                app.Slider_Threshold.Value,[],0);
            app.Slider_CFAR.Value = FPR/app.Detection.fpr(1)*100;
            if app.Detection.Detect_AP
                app.EditField_CFAR.Value = FPR;
                app.EditField_CFAR_slider.Value = app.Slider_CFAR.Value;
            else
                app.EditField_CFAR.Value = app.Slider_CFAR.Value;
            end
        end
        
        function FRP2Thresh(app)
            fpr = [app.Detection.fpr,0];
            thresholds = [app.Detection.thresholds,app.Detection.max_amp];
            FPR = app.Slider_CFAR.Value/100*fpr(1);
            idx = find(diff(fpr)==0)+1;
            fpr(idx) = [];  thresholds(idx) = [];
            Threshold = interp1(fpr,thresholds,FPR);
            app.Slider_Threshold.Value = Threshold;
            app.EditField_Threshold_slider.Value = Threshold;
            app.EditField_Threshold.Value = (Threshold ...
                -app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
        end
        
        function Threshold_Updated(app)
            Threshold = app.Slider_Threshold.Value;
            % Display threshold results
            [~,app.h_5] = ROC_plot(app.Detection,Threshold,'axes',app.Panels{6},'h',app.h_5);
            app.panels_created(6);
            drawnow;
            app.append_textbox(app.ResultsTextArea_3,num2str([Threshold, ...
                app.Slider_CFAR.Value],['Detection threshold: %.4g ', ...
                char(956),'V, False positive rate: %.4g %%']));
            % Conditional probability
           app.DropDown_ConditionValueChanged; 
        end
    
    end %private end
    
    methods (Access = public)
        
        function flag = save_fig(app,panels,imfmt,res,bgclr)
            flag = 0;
            dname = uigetdir(app.working_dir{end},'Select Figure Folder');
            if isequal(dname,0)
                return;
            end
            % check files
            flist = {dir(fullfile(dname,['*.',imfmt])).name};
            fnames = cell(size(panels));
            for i=1:numel(panels)
                p = panels(i);
                pname = strrep(app.PanelNames{p},' ','_');
                n = numel(app.Panels{p});
                fnames{i} = cell(n,1);
                for j=1:n
                    if n>1
                        id = num2str(j,'_%d.');
                    else
                        id = '.';
                    end
                    fnames{i}{j} = [pname,id,imfmt];
                end
            end
            fnames = vertcat(fnames{:});
            if ~isempty(flist)
                existing_files = intersect(fnames,flist);
                if ~isempty(existing_files)
                    msg = ['The figure files below already exist in the folder.'; ...
                        existing_files;
                        'Do you want to overwrite them?'];
                    selection = uiconfirm(app.SavefigApp.UIFigure,msg,'Confirm Save As');
                    if isequal(selection,'Cancel'), return; end
                end
            end
            % save figures
            UIaxes = app.Panels(panels);
            UIaxes = horzcat(UIaxes{:});
            flag = 1;
            failed = {};
            for i=1:numel(UIaxes)
                try
                    exportgraphics(UIaxes(i),fullfile(dname,fnames{i}), ...
                        'Resolution',res,'BackgroundColor',bgclr);
                catch
                    failed = [failed;fnames{i}];
                    flag = 0;
                end
            end
            if flag
                uialert(app.UIFigure,['Figures are saved in "',dname,'".'], ...
                        'Success','Icon','success');
            else
                msg = ['The following files may not be saved.';failed];
                uialert(app.UIFigure,msg,'Failure');
            end
        end
        
        function value = get_default_param(app,name)
            value = app.default.(name);
        end

        function value = get_specified_param(app,name)
            value = app.specified.(name);
        end

        function set_param(app,name,value)
            app.specified.(name) = value;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cache_attr = {{'LFP_seg','RealData','BurstStats'}, ...
                {'SynParam_h','SynData','CompositeTrace'}, ...
                {'SynData','SynStats','BoundStats','Detection'}};
            app.working_dir = {pwd};
            app.create_panels;
            app.create_default_parameters;
            app.create_PSO_parameters;
            
            app.Data_fs.Value = num2str(app.data_fs);
            app.fsEditField.Value = app.default.fs;
            app.tDBEditField.Value = app.default.tDB;
            app.NBinsEditField.Value = app.default.NBins;
            app.SynlenOptimEditField.Value = app.default.SynlenOptim;
            app.SynlenEditField.Value = app.default.Synlen;
            app.EditField_LowerBound_sig.Value = app.default.LowerBound_sig;
            app.EditField_UpperBound_sig.Value = app.default.UpperBound_sig;
            app.EditField_NThresh.Value = app.default.NThresh;

            app.DistributionTypesListBox.Items = app.AmpDistTypes;
            app.DistributionTypesListBox.Value = app.AmpDistTypes(1);
            app.PopulationDropDown.Items = app.AmpDistTypes(1);
            app.nPopEditField.Value = app.default.nPop.(app.AmpDistTypes{1});
            app.h_3 = struct('real',0,'syn',zeros(size(app.AmpDistTypes)));
            app.AmpOptimDone = false(size(app.AmpDistTypes));

            app.menu_timer = timer('ExecutionMode','fixedSpacing',...
                'Period',5,'TimerFcn',@app.update_menu);
            start(app.menu_timer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.menu_timer);
                delete(app.menu_timer);
            catch
            end
            delete(app.SavefigApp);
            delete(app.Options1_1App);
            delete(app.Options1_2App);
            delete(app.Options2_1App);
            delete(app.Options2_2App);
            delete(app);
        end

        % Value changed function: LFPDataDropDown
        function LFPDataDropDownValueChanged(app, event)
            try
                value = evalin('base',event.Value);
                flag = ~isempty(value);
            catch
                flag = false;
            end
            if flag
                if ~iscell(value)
                    value = {value};
                end
                for i = 1:numel(value)
                    if ~isvector(value{i}) || ~isnumeric(value{i})
                        uialert(app.UIFigure,['Expect input data to be a cell vector, ' ...
                            'each element in which is a numeric vector.'],'Value Error');
                        flag = false;   break;
                    end
                    if ~isreal(value{i})
                        uialert(app.UIFigure,'Expect input data to be real valued.'),'Value Error';
                        flag = false;   break;
                    end
                end
            end
            if flag
                if isequal(value,app.LFP_seg)
                    return;
                end
                if app.step1_stage>0
                    msg = {'Change of input data will clear existing results.', ...
                        'Do you want to continue with the new data?'};
                    selection = uiconfirm(app.UIFigure,msg,'Confirm Selection');
                    flag = isequal(selection,'OK');
                end
            end
            if flag
                app.LFP_seg = value;
                app.EditField_Dataname.Value = [event.Value,'_Charac'];
                app.set_step1_stage(1);
            else
                app.LFPDataDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: Data_fs
        function Data_fsValueChanged(app, event)
            try
                value = evalin('base',event.Value);
                flag = isnumeric(value) && isscalar(value) && isreal(value) && value>0;
            catch
                flag = false;
            end
            if flag
                app.data_fs = value;
            else
                app.Data_fs.Value = event.PreviousValue;
                uialert(app.UIFigure,'Sampling Rate should be positive value','Value Error');
            end
        end

        % Selection changed function: DBThresholdButtonGroup
        function DBThresholdButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_tDB.Value
                app.tDBEditField.Value = app.default.tDB;
            else
                app.tDBEditField.Value = app.specified.tDB;
            end
        end

        % Value changed function: tDBEditField
        function tDBEditFieldValueChanged(app, event)
            app.specified.tDB = app.tDBEditField.Value;
            app.SpecifyButton_tDB.Value = true;
        end

        % Button pushed function: FitPsdButton
        function FitPsdButtonPushed(app, event)
            % Setup
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_1,msg);
            nfft = app.specified.nfft;
            smooth_win = app.specified.smooth_win;
            freq_band = [app.FreqBandEditField1.Value,app.FreqBandEditField2.Value];
            db_threshold = app.tDBEditField.Value;
            sample_density = app.specified.sample_density;
            if freq_band(1)>freq_band(2)
                uialert(app.UIFigure,'Oscillation frequency range should be an interval.', ...
                    'Value Error'); return;
            end
            UsingFOOOF = app.specified.FOOOF;
            if app.AutofitButton.Value
                if UsingFOOOF
                    fit_range = [0,app.data_fs/2];
                    app.FitRangeEditField1.Value = fit_range(1);
                    app.FitRangeEditField2.Value = fit_range(2);
                else
                    autofit = true; fit_range = [0,0];
                end
            else
                autofit = false;
                fit_range = [app.FitRangeEditField1.Value,app.FitRangeEditField2.Value];
                if fit_range(1)>freq_band(1) || fit_range(2)<freq_band(2)
                    uialert(app.UIFigure,['Suggestion: the frequency range for ' ...
                        'fitting should contain the signal frequency band.'], ...
                        'Value Warning','Icon','warning');
                end
            end
            % Run
            msgfcn('Estimating PSD...');
            if isempty(app.RealData)
                [app.RealData,app.LFP_seg,flag] = getPSDestimation(app.LFP_seg,app.data_fs, ...
                    'nfft',nfft,'smooth_win',smooth_win,'display',1,'msgfcn',msgfcn);
            else
                flag = -1;
            end
            if flag>-2
                if UsingFOOOF
                    pk_wid = [app.specified.peak_width_limits1,app.specified.peak_width_limits2];
                    if pk_wid(1)>pk_wid(2)
                        uialert(app.UIFigure,'Peak width limits should be an interval.', ...
                            'Value Error'); return;
                    end
                    msgfcn(sprintf('\nFitting PSD with FOOOF...'));
                    settings = struct('peak_width_limits',pk_wid, ...
                        'max_n_peaks',app.specified.max_n_peaks, ...
                        'peak_threshold',0.0,'min_peak_height',db_threshold/10, ...
                        'aperiodic_mode',app.specified.aperiodic_mode,'verbose',false);
                    try
                        fooof_results = fooof(app.RealData.f,app.RealData.PSD,fit_range,settings,1);
                    catch exception
                        disp(getReport(exception));
                        uialert(app.UIFigure,'FOOOF fit may have failed.','Failure');
                        msgfcn(''); return;
                    end
                    app.clearallaxes(1);
                    [app.RealData,flag] = getPSDcomponents_fooof(app.RealData,fooof_results, ...
                        freq_band,fit_range,'tDB',db_threshold,'plot',1,'display',1, ...
                        'axes',app.Panels{1},'msgfcn',msgfcn);
                else
                    msgfcn(sprintf('\nFitting PSD...'));
                    app.clearallaxes(1);
                    [app.RealData,flag] = getPSDcomponents(app.RealData,freq_band,fit_range, ...
                        'tDB',db_threshold,'autofit',autofit,'sample_density',sample_density, ...
                        'plot',1,'display',1,'axes',app.Panels{1},'msgfcn',msgfcn);
                end
                app.panels_created(1);
                drawnow;
            end
            msgfcn('');
            % Result
            switch flag
                case -2
                    uialert(app.UIFigure,['The lengths of input LFP segments ' ...
                        'are too short for Fourier transform.'],'Input Error');
                    app.set_step1_stage(0);
                case -1
                    uialert(app.UIFigure,{'Frequency range for fitting is too narrow.', ...
                        'Select wider range.'},'Value Error');
                    app.set_step1_stage(1);
                case -3
                    uialert(app.UIFigure,{['Autofit failed: Signal occurs ' ...
                        'on the edge of auto fit range.'], ...
                        'Suggestion: Adjust the dB threshold.'}, ...
                        'Failure','Icon','warning');
                    app.set_step1_stage(1);
                case 0
                    uialert(app.UIFigure,{'Power above the dB threshold not found.', ...
                        'Suggestion: Lower the dB threshold.'}, ...
                        'Failure','Icon','warning');
                    app.set_step1_stage(1);
                case 1
                    uialert(app.UIFigure,'Fitting complete.', ...
                        'Success','Icon','success');
                    app.set_step1_stage(2);
            end
        end

        % Selection changed function: NBinsButtonGroup
        function NBinsButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_NBins.Value
                app.NBinsEditField.Value = app.default.NBins;
            else
                app.NBinsEditField.Value = app.specified.NBins;
            end
        end

        % Value changed function: NBinsEditField
        function NBinsEditFieldValueChanged(app, event)
            app.specified.NBins = app.NBinsEditField.Value;
            app.SpecifyButton_NBins.Value = true;
        end

        % Button pushed function: FitBurstsButton
        function FitBurstsButtonPushed(app, event)
            % Setup
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_1,msg);
            msgfcn('Characterizing bursts...');
            butter_order = app.specified.butter_order;
            ZS_threshold = app.specified.ZS_threshold;
            stop_perc = app.specified.stop_perc;
            freq_resolution = app.specified.freq_resolution;
            unit = app.UnitofAmplitudeEditField.Value;
            nbins = app.NBinsEditField.Value;
            % Run
            [app.RealData,app.BurstStats] = getAllBurstAttr(app.RealData,app.LFP_seg, ...
                butter_order,ZS_threshold,'stop_perc',stop_perc,'freq_resolution',freq_resolution, ...
                'unit',unit,'fit_amp_gamma',0,'display',1,'msgfcn',msgfcn);
            msgfcn(sprintf('\nFitting bursts distribution...'));
            app.clearallaxes(2);
            app.RealData = getBurstDistributions(app.RealData,app.BurstStats, ...
                'nbins',nbins,'plot',1,'plot_logscale',1,'display',1, ...
                'axes',app.Panels{2},'msgfcn',msgfcn);
            app.panels_created(2);
            drawnow;
            msgfcn(sprintf('Bursts characterization done.\n'));
            app.set_step1_stage(3);
        end

        % Selection change function: Tab_linear
        function Tab_linearSelectionChanged(app, event)
            selectedTab = app.Tab_linear.SelectedTab.Title;
            switch selectedTab
                case 'AP'
                    app.Tab_log.SelectedTab = app.APTab_2;
                case 'CN'
                    app.Tab_log.SelectedTab = app.CNTab_2;
                case 'BF'
                    app.Tab_log.SelectedTab = app.BFTab_2;
            end
        end

        % Selection change function: Tab_log
        function Tab_logSelectionChanged(app, event)
            selectedTab = app.Tab_log.SelectedTab.Title;
            switch selectedTab
                case 'AP'
                    app.Tab_linear.SelectedTab = app.APTab;
                case 'CN'
                    app.Tab_linear.SelectedTab = app.CNTab;
                case 'BF'
                    app.Tab_linear.SelectedTab = app.BFTab;
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            dname = uigetdir(app.working_dir{end},'Select Project Folder');
            if ~isequal(dname,0)
                app.SelectedDirectoryEditField.Value = dname;
                app.SelectedDirectoryEditFieldValueChanged;
            end
        end

        % Value changed function: SelectedDirectoryEditField
        function SelectedDirectoryEditFieldValueChanged(app, event)
            dname = app.SelectedDirectoryEditField.Value;
            if ~isfolder(dname)
                app.SelectedDirectoryEditField.Value = event.PreviousValue;
                uialert(app.UIFigure,'Directory not found','Path Error');
            else
                app.working_dir = {pwd,app.SelectedDirectoryEditField.Value};
            end
        end

        % Button pushed function: SaveCharacterizationDataButton
        function SaveCharacterizationDataButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_1,msg);
            if ~isfolder(app.working_dir{end})
                app.working_dir = {pwd};
            end
            dname = app.working_dir{end};
            [~,fname] = fileparts(app.EditField_Dataname.Value);
            if ~contains(fname,app.suffix{1})
                fname = [fname,app.suffix{1}];
            end
            filepath = fullfile(dname,fname);
            [fname,dname] = uiputfile('*.mat','Save Results',filepath);
            if isequal(fname,0)
                return;
            else
                filepath = fullfile(dname,fname);
            end
            % Save data
            RealData = app.RealData;
            try
                save(filepath,'-struct','RealData');
                flag = isfile(filepath);
            catch
                flag = false;
            end
            if flag
                app.working_dir{2} = dname;
                app.EditField_Dataname.Value = fname;
                app.SelectedDirectoryEditField.Value = dname;
                app.EditField_SelectRealDataname.Value = filepath;
                msgfcn(sprintf('Results saved in "%s".\n',filepath));
                uialert(app.UIFigure,'Result file saved successfully.', ...
                    'Success','Icon','success');
            else
                uialert(app.UIFigure,'Result file not saved.','Failure');
            end

            if app.AlsoSaveBurstStatisticsCheckBox.Value
                if contains(fname,app.suffix{1})
                    name = strrep(fname,app.suffix{1},app.suffix{4});
                else
                    name = [fname,app.suffix{4}];
                end
                filepath = fullfile(dname,name);
                [name,dname] = uiputfile('*.mat','Save Burst Statistics',filepath);
                if isequal(name,0)
                    return;
                else
                    filepath = fullfile(dname,name);
                end
                % Save data
                BurstStats = app.BurstStats;
                try
                    save(filepath,'-struct','BurstStats');
                    flag = isfile(filepath);
                catch
                    flag = false;
                end
                if flag
                    msgfcn(sprintf('Burst statistics saved in "%s".\n',filepath));
                    uialert(app.UIFigure,'Burst statistics file saved successfully.', ...
                        'Success','Icon','success');
                else
                    uialert(app.UIFigure,'Burst statistics file not saved.','Failure');
                end
            end
        end

        % Button pushed function: SelectRealDataButton
        function SelectRealDataButtonPushed(app, event)
            filepath = app.EditField_SelectRealDataname.Value;
            [fname,dname] = uigetfile('*.mat','Select Data File',filepath);
            if ~isequal(fname,0)
                filepath = fullfile(dname,fname);
                app.EditField_SelectRealDataname.Value = filepath;
                app.SelectedDirectoryEditField.Value = dname;
            end
        end

        % Value changed function: EditField_SelectRealDataname
        function EditField_SelectRealDatanameValueChanged(app, event)
            filepath = app.EditField_SelectRealDataname.Value;
            if isfile(filepath)
                app.SelectedDirectoryEditField.Value = fileparts(filepath);
            else
                app.EditField_SelectRealDataname.Value = event.PreviousValue;
                uialert(app.UIFigure,'File not found','Path Error');
            end
        end

        % Selection changed function: SamplingRateGroup
        function SamplingRateGroupSelectionChanged(app, event)
            if app.DefaultButton_fs.Value
                app.fsEditField.Value = app.default.fs;
            else
                app.fsEditField.Value = app.specified.fs;
            end
        end

        % Value changed function: fsEditField
        function fsEditFieldValueChanged(app, event)
            app.specified.fs = app.fsEditField.Value;
            app.SpecifyButton_fs.Value = true;
        end

        % Button pushed function: LoadRealDataButton
        function LoadRealDataButtonPushed(app, event)
            if app.step2_stage>0
                msg = ['Data already loaded. Reloading will clear ', ...
                    'existing results. Do you want to continue?'];
                selection = uiconfirm(app.UIFigure,msg,'Confirm Selection');
                if isequal(selection,'Cancel'), return; end
                app.set_step2_stage(0);
            end
            
            filepath = app.EditField_SelectRealDataname.Value;
            if ~isfile(filepath)
                uialert(app.UIFigure,'File not found','Path Error');    return;
            end
            [dname,name,ext] = fileparts(filepath);
            fname = [name,ext];
            app.working_dir = {pwd,dname};
            
            % Setup
            SynParam = SynthParam;
            SynParam.filepath = fname;
            try
                cd(app.working_dir{2});
                SynParam.loadrealdata;
            catch
                uialert(app.UIFigure,['Selected file does not contain ' ...
                    'characterization data'],'File Error');
                cd(app.working_dir{1}); return;
            end
            cd(app.working_dir{1});
            app.default.fs = SynParam.rdat.fs;
            if app.DefaultButton_fs.Value
                SynParam.fs = app.default.fs;
                app.fsEditField.Value = app.default.fs;
            else
                SynParam.fs = app.fsEditField.Value;
            end
            SynParam.randseed = app.RngSeedEditField.Value;
            SynParam.correlated = app.specified.correlated;
            SynParam.width_edge = app.specified.width_edge;
            SynParam.empr_CN = app.specified.empr_CN;
            SynParam.empr_BF = app.specified.empr_BF;
            SynParam.power_match = app.specified.power_match;
            [~,flag] = SynParam.setup;
            if flag
                app.SynParam_h = SynParam;
                app.append_textbox(app.ResultsTextArea_2,sprintf('Data loaded successfully.\n'));
                app.set_step2_stage(1);
            end
        end

        % Selection changed function: SyntheticLengthOptimButtonGroup
        function SyntheticLengthOptimButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_SynlenOptim.Value
                app.SynlenOptimEditField.Value = app.default.SynlenOptim;
            else
                app.SynlenOptimEditField.Value = app.specified.SynlenOptim;
            end
        end

        % Value changed function: SynlenOptimEditField
        function SynlenOptimEditFieldValueChanged(app, event)
            app.specified.SynlenOptim = app.SynlenOptimEditField.Value;
            app.SpecifyButton_SynlenOptim.Value = true;
        end

        % Value changed function: DistributionTypesListBox
        function DistributionTypesListBoxValueChanged(app, event)
            Value = event.Value;
            if isempty(Value)
                Value = event.PreviousValue;
                if isempty(Value)
                    Value = app.DistributionTypesListBox.items;
                end
                app.DistributionTypesListBox.Value = Value(1);
            end
            app.PopulationDropDown.Items = Value;
            if ~any(strcmp(Value,app.PopulationDropDown.Value))
                app.PopulationDropDown.Value = Value{1};
            end
            app.nPopEditField.Value = app.specified.nPop.(Value{1});
        end

        % Value changed function: PopulationDropDown
        function PopulationDropDownValueChanged(app, event)
            disttype = app.PopulationDropDown.Value;
            app.nPopEditField.Value = app.specified.nPop.(disttype);
        end

        % Value changed function: nPopEditField
        function nPopEditFieldValueChanged(app, event)
            disttype = app.PopulationDropDown.Value;
            app.specified.nPop.(disttype) = app.nPopEditField.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            disttype = app.PopulationDropDown.Value;
            nPop = app.default.nPop.(disttype);
            app.nPopEditField.Value = nPop;
            app.specified.nPop.(disttype) = nPop;
        end

        % Button pushed function: FitAmpButton
        function FitAmpButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_2,msg);
            % Setup
            msgfcn('Optimizing amplitude distribution parameters...');
            SynParam = app.SynParam_h;
            % SynParam.mem_presever = 1e9;
            SynParam.syn_len = app.SynlenOptimEditField.Value;
            try
                cd(app.working_dir{2});
                SynParam.setup(1);
            catch
                uialert(app.UIFigure,'Characterization data missing','File Error');
                cd(app.working_dir{1}); return;
            end
            cd(app.working_dir{1});
            SynParam.gen_background;
            
            % Cases
            fit_AP = app.Fit_AP.Value;
            disttypes = app.DistributionTypesListBox.Value;
            div_type = app.specified.div_type;
            app.PSOparams.randseed = SynParam.randseed+4;
            ax = app.Panels{3};
            for i = 1:numel(disttypes)
                disttype = disttypes{i};
                msgfcn(sprintf('\nOptimizing with %s distribution...',disttype));
                SynParam.amp_dist_type = disttype;
                app.PSOparams.nPop = app.specified.nPop.(disttype);
                out = runAmpDistOptim(SynParam,app.PSOparams,div_type,fit_AP);
                % Plot
                idx = find(strcmp(disttype,app.AmpDistTypes));
                app.AmpOptimDone(idx) = true;
                [~,app.h_3.real,app.h_3.syn(idx)] = SynParam.plot_eval_dist(disttype, ...
                    [],ax,app.h_3.real,app.h_3.syn(idx));
                legend(ax,[app.h_3.real,app.h_3.syn(app.AmpOptimDone)], ...
                    ['Observed',app.AmpDistTypes(app.AmpOptimDone)],'Location',"NorthEast");
                app.panels_created(3);
                drawnow;
                % Results
                msgfcn(num2str(out.FinalIteration,'Done after %d iterations.'));
                switch out.exitflag
                    case 1
                        msgfcn('Maximum number of stall iterations reached.');
                    case -1
                        msgfcn('Maximum number of iterations reached.');
                end
                msgfcn(num2str(SynParam.div_value.(disttype), ...
                    ['Population ',div_type,' divergence = %f']));
                msgfcn(num2str(SynParam.ks_test_pvalue.(disttype), ...
                    'Kolmogorov-Smirnov test p-value = %f'));
                msgfcn(['Optimized parameters: ', ...
                    num2str(SynParam.amp_param.(disttype),'%.2f ')]);
            end
            msgfcn('');
            app.DistributionTypeDropDown.Items = app.AmpDistTypes(app.AmpOptimDone);
            app.DistributionTypeDropDown.Value = SynParam.best_amp_dist_type;
            SynParam.amp_dist_type = app.DistributionTypeDropDown.Value;
            SynParam.syn_len = app.SynlenEditField.Value;
            
            uialert(app.UIFigure,['Parameter optimization for selected' ...
               ' distribution types all done.'],'Success','Icon','success');
            app.set_step2_stage(2);
        end

        % Selection changed function: SyntheticLengthButtonGroup
        function SyntheticLengthButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_Synlen.Value
                app.SynlenEditField.Value = app.default.Synlen;
            else
                app.SynlenEditField.Value = app.specified.Synlen;
            end
        end

        % Value changed function: SynlenEditField
        function SynlenEditFieldValueChanged(app, event)
            app.specified.Synlen = app.SynlenEditField.Value;
            app.SpecifyButton_Synlen.Value = true;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.Generate_Synthetic_Data;
            app.append_textbox(app.ResultsTextArea_2,sprintf('Characterizing synthetic LFP data...\n'));
            app.clearallaxes(4:5);
            SynP = app.PlotSynPSD;
            app.PlotBurstStats(SynP);   % return [SynResults,SynStats]
            
            uialert(app.UIFigure,'Generating and characterizing synthetic LFP complete.', ...
                'Success','Icon','success');
            app.set_step2_stage(3);
        end

        % Button pushed function: SaveSyntheticButton
        function SaveSyntheticButtonPushed(app, event)
            if ~isfolder(app.working_dir{end})
                app.working_dir = {pwd};
            end
            dname = app.working_dir{end};
            AlsoSaveSyntheticLFP = app.AlsoSaveSyntheticLFPCheckBox.Value;
            if AlsoSaveSyntheticLFP
                suf = app.suffix{3};
            else
                suf = app.suffix{2};
            end
            SynParam = app.SynParam_h;
            [~,name0] = fileparts(SynParam.filepath);
            if contains(name0,app.suffix{1})
                name = strrep(name0,app.suffix{1},suf);
                name0 = strrep(name0,app.suffix{1},'');
            else
                name = [name0,suf];
            end
            [~,casename,ext] = fileparts(strrep( ...
                app.EditField_SynParamDataname.Value,'Case name',''));
            for i = 1:3
                if contains(casename,app.suffix{i})
                    casename = strrep(casename,app.suffix{i},suf);
                    name = name0;
                end
            end
            if contains(casename,name0)
                casename = strrep(casename,name0,name);
            else
                if isempty(casename)
                    casename = name;
                else
                    casename = [name,'_',casename];
                end
            end
            filepath = fullfile(dname,[casename,ext]);
            [fname,dname] = uiputfile('*.mat','Save Results',filepath);
            if isequal(fname,0)
                return;
            else
                filepath = fullfile(dname,fname);
            end
            % Save data
            if AlsoSaveSyntheticLFP
                app.RngSeedEditField.Value = SynParam.randseed;
                app.SynlenEditField.Value = SynParam.syn_len;
                app.DefaultButton_Synlen.Value = SynParam.syn_len==app.default.Synlen;
                if ~app.DefaultButton_Synlen.Value
                    app.specified.Synlen = SynParam.syn_len;
                end
                app.DistributionTypeDropDown.Value = SynParam.amp_dist_type;
                Data = struct('SynParam',SynParam,'SynData',app.SynData);
            else
                SynParam.randseed = app.RngSeedEditField.Value;
                SynParam.syn_len = app.SynlenEditField.Value;
                SynParam.amp_dist_type = app.DistributionTypeDropDown.Value;
                Data = struct('SynParam',SynParam);
            end
            try
                save(filepath,'-struct','Data');
                flag = isfile(filepath);
            catch
                flag = false;
            end
            if flag
                [dname,name] = fileparts(filepath);
                app.EditField_SynParamDataname.Value = name;
                app.SelectedDirectoryEditField.Value = dname;
                app.EditField_SelectSynDataname.Value = filepath;
                app.append_textbox(app.ResultsTextArea_2, ...
                    sprintf('Results saved in "%s".\n',filepath));
                uialert(app.UIFigure,'Result file saved successfully.', ...
                    'Success','Icon','success');
            else
                uialert(app.UIFigure,'Result file not saved.','Failure');
            end
        end

        % Button pushed function: SelectSynDataButton
        function SelectSynDataButtonPushed(app, event)
            filepath = app.EditField_SelectSynDataname.Value;
            if isempty(filepath)
                filepath = app.working_dir{end};
            end
            [fname,dname] = uigetfile('*.mat','Select Data File',filepath);
            if ~isequal(fname,0)
                filepath = fullfile(dname,fname);
                app.EditField_SelectSynDataname.Value = filepath;
                app.SelectedDirectoryEditField.Value = dname;
            end
        end

        % Value changed function: EditField_SelectSynDataname
        function EditField_SelectSynDatanameValueChanged(app, event)
            filepath = app.EditField_SelectSynDataname.Value;
            if isfile(filepath)
                app.SelectedDirectoryEditField.Value = fileparts(filepath);
            else
                app.EditField_SelectSynDataname.Value = event.PreviousValue;
                uialert(app.UIFigure,'File not found','Path Error');
            end
        end

        % Button pushed function: ReviewSettingsButton
        function ReviewSettingsButtonPushed(app, event)
            if app.LoadSynData
                return;
            end
            app.TabGroup.SelectedTab = app.Tab_Step2;
            % Setup fields in step 2
            SynParam = app.SynParam_h;
            app.EditField_SelectRealDataname.Value = fullfile( ...
                app.working_dir{2},SynParam.filepath);
            app.RngSeedEditField.Value = SynParam.randseed;
            app.default.fs = SynParam.rdat.fs;
            app.fsEditField.Value = SynParam.fs;
            app.DefaultButton_fs.Value = SynParam.fs==app.default.fs;
            if app.SpecifyButton_fs.Value
                app.specified.fs = SynParam.fs;
            end
            app.specified.correlated = SynParam.correlated;
            app.specified.width_edge = SynParam.width_edge;
            app.specified.empr_CN = SynParam.empr_CN;
            app.specified.empr_BF = SynParam.empr_BF;
            app.specified.power_match = SynParam.power_match;
            app.set_step2_stage(1);
            drawnow;
            % Optimization parameters
            if isfield(SynParam.eval_param,'fit_AP')
                app.Fit_AP.Value = SynParam.eval_param.fit_AP;
            end
            for i = 1:numel(app.AmpDistTypes)
                app.AmpOptimDone(i) = ~isempty(SynParam.amp_param.(app.AmpDistTypes{i}));
            end
            if ~any(app.AmpOptimDone)
                uialert(app.UIFigure,'Amplitude distribution optimization has not been done yet', ...
                    'Data Error');  return;
            end
            TypesDone = app.AmpDistTypes(app.AmpOptimDone);
            app.DistributionTypesListBox.Value = TypesDone;
            app.PopulationDropDown.Items = TypesDone;
            app.PopulationDropDown.Value = TypesDone{1};
            app.nPopEditField.Value = app.specified.nPop.(TypesDone{1});
            ax = app.Panels{3};
            for i = find(app.AmpOptimDone)
                [~,app.h_3.real,app.h_3.syn(i)] = SynParam.plot_eval_dist( ...
                    app.AmpDistTypes{i},[],ax,app.h_3.real,app.h_3.syn(i));
            end
            legend(ax,[app.h_3.real,app.h_3.syn(app.AmpOptimDone)], ...
                ['Observed',app.AmpDistTypes(app.AmpOptimDone)],'Location',"NorthEast");
            app.panels_created(3);
            app.set_step2_stage(2);
            drawnow;
            % Generation parameters
            app.DistributionTypeDropDown.Items = TypesDone;
            app.DistributionTypeDropDown.Value = SynParam.amp_dist_type;
            app.SynlenEditField.Value = SynParam.syn_len;
            app.DefaultButton_Synlen.Value = SynParam.syn_len==app.default.Synlen;
            if ~app.DefaultButton_Synlen.Value
                app.specified.Synlen = SynParam.syn_len;
            end
            if ~isempty(app.SynData)
                app.CompositeTrace = app.SynData.BackgroundTrace+app.SynData.SignalTrace;
                app.set_step2_stage(3);
            end
            
            app.append_textbox(app.ResultsTextArea_2, ...
                sprintf('Data loaded successfully.\n'));
            app.set_step3_stage(1);
        end

        % Button pushed function: LoadSynDataButton
        function LoadSynDataButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_3,msg);
            % Load synthetic data
            if app.step3_stage==1
                SynParam = app.SynParam_h;
                msgfcn('Using synthesis setting in current step 2.');
            else
                if app.LoadSynData
                    return;
                end
                SynParam = app.SynParam_h;
                if all(cellfun(@isempty,struct2cell(SynParam.amp_param)))
                    uialert(app.UIFigure,'Amplitude distribution optimization has not been done yet', ...
                        'Data Error');  return;
                end
                msgfcn('Data loaded successfully.');
            end
            if isempty(app.SynData)
                msgfcn('Generating synthetic LFP data...');
                app.SynData = gen_synthetic(SynParam);
            end
            msgfcn(['Amplitude distribution type being used: ',SynParam.amp_dist_type]);
            msgfcn(num2str(SynParam.syn_len,'Synthetic LFP length: %.1f seconds'));
            msgfcn('');
            
            % Primary process
            [SynStats,~] = getSyntheticStats(SynParam,app.SynData);
            BoundStats = getPreselectedBounds(SynParam,SynStats);
            app.SynStats = SynStats;
            app.BoundStats = BoundStats;
            
            % Setup parameters
            app.EditField_Threshold.Value = 0;
            app.EditField_Threshold.Limits = ([0,SynStats.max_amp(3)] ...
                -SynStats.amp_mean(3))/SynStats.amp_sigma(3);
            app.Slider_Threshold.Limits = [0,SynStats.max_amp(3)];
            app.EditField_Threshold_slider.Limits = app.Slider_Threshold.Limits;
            app.Slider_Threshold.Value = SynStats.amp_mean(3)+ ...
                app.EditField_Threshold.Value*SynStats.amp_sigma(3);
            app.EditField_Threshold_slider.Value = app.Slider_Threshold.Value;
            app.default.LowerBound_sig = BoundStats.nsigma_bound(1);
            app.default.UpperBound_sig = BoundStats.auto_thetaU/SynStats.sigma_syn(1);
            app.specified.LowerBound_sig = app.default.LowerBound_sig;
            app.specified.UpperBound_sig = app.default.UpperBound_sig;
            app.DefaultButton_LowerBound.Value = true;
            app.DefaultButton_UpperBound.Value = true;
            app.EditField_LowerBound.Value = BoundStats.pre_bound(1);
            app.EditField_UpperBound.Value = BoundStats.auto_thetaU;
            app.EditField_LowerBound_sig.Value = app.default.LowerBound_sig;
            app.EditField_UpperBound_sig.Value = app.default.UpperBound_sig;
            
            % Set units
            unit = SynStats.unit;
            app.EditField_LowerBound.ValueDisplayFormat = ['%.4g ',unit];
            app.EditField_UpperBound.ValueDisplayFormat = ['%.4g ',unit];
            app.VLabel.Text = unit;
            app.set_step3_stage(2);
        end

        % Selection changed function: ButtonGroup_LowerBound
        function ButtonGroup_LowerBoundSelectionChanged(app, event)
            if app.DefaultButton_LowerBound.Value
                app.EditField_LowerBound_sig.Value = app.default.LowerBound_sig;
            else
                app.EditField_LowerBound_sig.Value = app.specified.LowerBound_sig;
            end
            app.EditField_LowerBound.Value = app.EditField_LowerBound_sig.Value ...
                *app.SynStats.sigma_syn(1);
        end

        % Value changed function: EditField_LowerBound
        function EditField_LowerBoundValueChanged(app, event)
            app.SpecifyButton_LowerBound.Value = true;
            app.specified.LowerBound_sig = app.EditField_LowerBound.Value ...
                /app.SynStats.sigma_syn(1);
            app.EditField_LowerBound_sig.Value = app.specified.LowerBound_sig;
        end

        % Value changed function: EditField_LowerBound_sig
        function EditField_LowerBound_sigValueChanged(app, event)
            app.SpecifyButton_LowerBound.Value = true;
            app.specified.LowerBound_sig = app.EditField_LowerBound_sig.Value;
            app.EditField_LowerBound.Value = app.specified.LowerBound_sig ...
                *app.SynStats.sigma_syn(1);
        end

        % Selection changed function: ButtonGroup_UpperBound
        function ButtonGroup_UpperBoundSelectionChanged(app, event)
            if app.DefaultButton_UpperBound.Value
                app.EditField_UpperBound_sig.Value = app.default.UpperBound_sig;
            else
                app.EditField_UpperBound_sig.Value = app.specified.UpperBound_sig;
            end
            app.EditField_UpperBound.Value = app.EditField_UpperBound_sig.Value ...
                *app.SynStats.sigma_syn(1);
        end

        % Value changed function: EditField_UpperBound
        function EditField_UpperBoundValueChanged(app, event)
            app.SpecifyButton_UpperBound.Value = true;
            app.specified.UpperBound_sig = app.EditField_UpperBound.Value ...
                /app.SynStats.sigma_syn(1);
            app.EditField_UpperBound_sig.Value = app.specified.UpperBound_sig;
        end

        % Value changed function: EditField_UpperBound_sig
        function EditField_UpperBound_sigValueChanged(app, event)
            app.SpecifyButton_UpperBound.Value = true;
            app.specified.UpperBound_sig = app.EditField_UpperBound_sig.Value;
            app.EditField_UpperBound.Value = app.specified.UpperBound_sig ...
                * app.SynStats.sigma_syn(1);
        end

        % Button pushed function: PreboundButton
        function PreboundButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_3,msg);
            SynStats = app.SynStats;
            BoundStats = getPreselectedBounds(app.SynParam_h,SynStats, ...
                app.EditField_LowerBound_sig.Value,app.EditField_UpperBound_sig.Value, ...
                app.BoundStats.auto_thetaU);
            if any(BoundStats.APrate_fit([1,3])==0)
                Case = {'false','true'};
                idx = find(BoundStats.APrate_fit([1,3])==0,1);
                uialert(app.UIFigure,['No ',Case{idx},' peak found with the selected bounds.'], ...
                    'Value Error'); return;
            end
            msgfcn('Preselected bounds are calculated.');
            if app.EditField_UpperBound_sig.Value~=BoundStats.nsigma_bound(2)
                msgfcn(num2str([app.EditField_UpperBound_sig.Value,BoundStats.nsigma_bound(2)], ...
                    'Upper bound is changed from %.3g σ to %.3g σ'));
                app.EditField_UpperBound_sig.Value = BoundStats.nsigma_bound(2);
                app.specified.UpperBound_sig = BoundStats.nsigma_bound(2);
                app.EditField_UpperBound.Value = BoundStats.pre_bound(2);
                uialert(app.UIFigure,{'Upper bound is smaller than the lower bound,', ...
                    'is forced to equal the lower bound.'},'Value Changed','Icon','info');
            end
            msgfcn('');
            app.EditField_TL.Value = BoundStats.dur_prop(1);
            app.EditField_TU.Value = BoundStats.dur_prop(3);
            app.FalsePeakRateEditField.Value = BoundStats.APrate_fit(1);
            app.TruePeakRateEditField.Value = BoundStats.APrate_fit(3);
            app.FalsepeakrateEditField_perc.Value = BoundStats.APrate_fit(1)...
                /SynStats.AP_rates(3)*100;
            app.TruepeakrateEditField_perc.Value = BoundStats.APrate_fit(3)...
                /SynStats.AP_rates(3)*100;
            
            app.BoundStats = BoundStats;
            app.set_step3_stage(3);
        end

        % Selection changed function: ButtonGroup_NThresholds
        function ButtonGroup_NThresholdsSelectionChanged(app, event)
            if app.DefaultButton_NThresh.Value
                app.EditField_NThresh.Value = app.default.NThresh;
            else
                app.EditField_NThresh.Value = app.specified.NThresh;
            end
        end

        % Value changed function: EditField_NThresh
        function EditField_NThreshValueChanged(app, event)
            app.specified.NThresh = app.EditField_NThresh.Value;
            app.SpecifyButton_NThresh.Value = true;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_3,msg);
            detect_AP = app.Detect_AP.Value;
            nthresh = app.EditField_NThresh.Value;
            app.Detection = getDetection(app.SynStats,app.BoundStats, ...
                'nthresh',nthresh,'Detect_AP',detect_AP);
            unit = app.Detection.unit;
            Youden = app.Detection.thresholds(app.Detection.Youden);
            Youden_ZS = (Youden-app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
            app.clearallaxes(6);
            [~,app.h_5] = ROC_plot(app.Detection,'axes',app.Panels{6},'h',app.h_5);
            app.panels_created(6);
            drawnow;
            
            if detect_AP
                Youden2 = app.Detection.thresholds(app.Detection.Youden2);
                Youden2_ZS = (Youden2-app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
                app.Button_Normalize.Visible = 'on';
                app.Button_Normalize.Enable = 'on';
                app.EditField_CFAR_slider.Visible = 'on';
                app.EditField_CFAR_slider.Enable = 'on';
                app.EditField_CFAR.ValueDisplayFormat = '%.3g Hz';
                app.EditField_CFAR.Limits = [0,app.Detection.fpr(1)];
                msgfcn('Detecting on amplitude peak.');
            else
                app.Button_Normalize.Visible = 'off';
                app.Button_Normalize.Enable = 'off';
                app.EditField_CFAR_slider.Visible = 'off';
                app.EditField_CFAR_slider.Enable = 'off';
                app.EditField_CFAR.ValueDisplayFormat = '%.3g %%';
                app.EditField_CFAR.Limits = [0,100];
                msgfcn('Detecting on amplitude.');
            end
            msgfcn('Optimal threshold:');
            msgfcn(num2str([Youden_ZS,Youden], ...
                ['Maximum Youden: %.3g Z-score, %.3g ',unit]));
            if detect_AP
                msgfcn(num2str([Youden2_ZS,Youden2], ...
                    ['Maximum Youden (in Hz): %.3g Z-score, %.3g ',unit]));
            end
            msgfcn('');
            app.Slider_Threshold.Value = Youden;
            app.Slider_ThresholdValueChanged;
            
            app.set_step3_stage(4);
        end

        % Value changed function: Button_Normalize
        function Button_NormalizeValueChanged(app, event)
            ax = app.UIAxes5;
            if app.Button_Normalize.Value
                % Normalized scale
                xl = 100*app.Detection.tpr(1)/app.Detection.fpr(1);
                xlim(ax,[0,xl]);
                xlabel(ax,'False positive rate (Hz)/True peak rate (Hz)');
                xt = 0:0.2:1;
                xtl = cellfun(@num2str,num2cell(xt'),'UniformOutput',false);
                xticks(ax,xt*xl);
                xticklabels(ax,xtl);
            else
                xlim(ax,[0,100]);
                xlabel(ax,'False positive rate (%)');
                xticks(ax,'auto');
                xticklabels(ax,'auto');
            end
        end

        % Value changed function: EditField_Threshold
        function EditField_ThresholdValueChanged(app, event)
            Threshold = app.SynStats.amp_mean(3)+ ...
                app.EditField_Threshold.Value*app.SynStats.amp_sigma(3);
            app.Slider_Threshold.Value = Threshold;
            app.EditField_Threshold_slider.Value = Threshold;
            app.Thresh2FPR;
            app.Threshold_Updated;
        end

        % Value changed function: Slider_Threshold
        function Slider_ThresholdValueChanged(app, event)
            Threshold = app.Slider_Threshold.Value;
            app.EditField_Threshold_slider.Value = Threshold;
            app.EditField_Threshold.Value = (Threshold ...
                -app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
            app.Thresh2FPR;
            app.Threshold_Updated;
        end

        % Value changed function: EditField_Threshold_slider
        function EditField_Threshold_sliderValueChanged(app, event)
            Threshold = app.EditField_Threshold_slider.Value;
            app.Slider_Threshold.Value = Threshold;
            app.EditField_Threshold.Value = (Threshold ...
                -app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
            app.Thresh2FPR;
            app.Threshold_Updated;
        end

        % Value changed function: EditField_CFAR
        function EditField_CFARValueChanged(app, event)
            FPR = app.EditField_CFAR.Value;
            if app.Detection.Detect_AP
                app.Slider_CFAR.Value = FPR/app.Detection.fpr(1)*100;
                app.EditField_CFAR_slider.Value = app.Slider_CFAR.Value;
            else
                app.Slider_CFAR.Value = FPR;
            end
            app.FRP2Thresh;
            app.Threshold_Updated;
        end

        % Value changed function: Slider_CFAR
        function Slider_CFARValueChanged(app, event)
            FPR = app.Slider_CFAR.Value;
            if app.Detection.Detect_AP
                app.EditField_CFAR.Value = FPR/100*app.Detection.fpr(1);
                app.EditField_CFAR_slider.Value = FPR;
            else
                app.EditField_CFAR.Value = FPR;
            end
            app.FRP2Thresh;
            app.Threshold_Updated;
        end

        % Value changed function: EditField_CFAR_slider
        function EditField_CFAR_sliderValueChanged(app, event)
            FPR = app.EditField_CFAR_slider.Value;
            app.Slider_CFAR.Value = FPR;
            app.EditField_CFAR.Value = FPR/100*app.Detection.fpr(1);
            app.FRP2Thresh;
            app.Threshold_Updated;
        end

        % Value changed function: DropDown_Condition
        function DropDown_ConditionValueChanged(app, event)
            thresholds = app.Detection.ctrs_thr0;
            if isequal(app.DropDown_Condition.Value,app.DropDown_Condition.Items{1})
                CProb = app.Detection.CProbGt;
            else
                CProb = app.Detection.CProbEq;
            end
            prob = 100*interp1(thresholds,CProb',app.Slider_Threshold.Value);
            app.EditField_PF.Value = prob(1);
            app.EditField_PI.Value = prob(2);
            app.EditField_PT.Value = prob(3);
        end

        % Menu selected function: SaveFiguresMenu
        function SaveFiguresMenuSelected(app, event)
            if any(app.CreatedPanels)
                app.SaveFiguresMenu.Enable = "off"; % Disable while dialog is open
                app.SavefigApp = gui_savefig(app);  % Call dialog box
            end
        end

        % Menu selected function: SaveOutputLogMenu
        function SaveOutputLogMenuSelected(app, event)
            dname = app.working_dir{end};
            fname = ['Output_Log_',char(datetime('now','Format','yyyyMMdd_HHmmss')),'.txt'];
            [fname,dname] = uiputfile('*.txt','Save Output Log',fullfile(dname,fname));
            if isequal(fname,0)
                return;
            else
                filepath = fullfile(dname,fname);
            end
            TxtAreas = [app.ResultsTextArea_1,app.ResultsTextArea_2,app.ResultsTextArea_3];
            f = fopen(filepath,'w');
            formatSpec = "%s\n";
            try
                for step=1:numel(TxtAreas)
                    texts = TxtAreas(step).Value;
                    fprintf(f,formatSpec,'================', ...
                        ['     Step ',num2str(step)],'================');
                    for i=1:numel(texts)
                        fprintf(f,formatSpec,texts{i});
                    end
                    fprintf(f,'\n\n');
                end
                fclose(f);
            catch
                uialert(app.UIFigure,'Output log may not be saved.','Failure');
                fclose(f);  return;
            end
            uialert(app.UIFigure,['Output log saved in "',dname,'".'],'Success','Icon','success');
        end

        % Menu selected function: ScreenshotMenu
        function ScreenshotMenuSelected(app, event)
            dname = app.working_dir{end};
            switch app.TabGroup.SelectedTab
                case app.Tab_Step1
                    step = 1;
                case app.Tab_Step2
                    step =  2;
                case app.Tab_Step3
                    step = 3;
            end
            fname = num2str(step,'step%d_screenshot_');
            fname = [fname,char(datetime('now','Format','yyyyMMdd_HHmmss'))];
            [fname,dname] = uiputfile({'*.png';'*.tif;*.tiff';'*.jpg;*.jpeg';'*.pdf'}, ...
                'Save Screenshot',fullfile(dname,fname));
            if isequal(fname,0)
                return;
            else
                filepath = fullfile(dname,fname);
            end
            try
                exportapp(app.UIFigure,filepath);
            catch
                uialert(app.UIFigure,'Screenshot may not be saved.','Failure');
                return;
            end
            uialert(app.UIFigure,['Screenshot saved in "',dname,'".'],'Success','Icon','success');
        end

        % Button pushed function: OptionsButton_PSD
        function OptionsButton_PSDPushed(app, event)
            app.OptionsButton_PSD.Enable = "off";   % Disable while dialog is open
            app.Options1_1App = gui_options1_1(app);    % Call dialog box
        end

        % Button pushed function: OptionsButton_Burst
        function OptionsButton_BurstPushed(app, event)
            app.OptionsButton_Burst.Enable = "off"; % Disable while dialog is open
            app.Options1_2App = gui_options1_2(app);    % Call dialog box
        end

        % Button pushed function: OptionsButton_SynSettings
        function OptionsButton_SynSettingsPushed(app, event)
            app.OptionsButton_SynSettings.Enable = "off";   % Disable while dialog is open
            app.Options2_1App = gui_options2_1(app);    % Call dialog box
        end

        % Button pushed function: OptionsButton_Optim
        function OptionsButton_OptimPushed(app, event)
            app.OptionsButton_Optim.Enable = "off"; % Disable while dialog is open
            app.Options2_2App = gui_options2_2(app);    % Call dialog box
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Oscillatory Burst Detector';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Text = 'Save';
            app.SaveFiguresMenu = uimenu(app.SaveMenu);
            app.SaveFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFiguresMenuSelected, true);
            app.SaveFiguresMenu.Enable = 'off';
            app.SaveFiguresMenu.Text = 'Save Figures';
            app.SaveOutputLogMenu = uimenu(app.SaveMenu);
            app.SaveOutputLogMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveOutputLogMenuSelected, true);
            app.SaveOutputLogMenu.Text = 'Save Output Log';
            app.ScreenshotMenu = uimenu(app.SaveMenu);
            app.ScreenshotMenu.MenuSelectedFcn = createCallbackFcn(app, @ScreenshotMenuSelected, true);
            app.ScreenshotMenu.Text = 'Screenshot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Step1 = uitab(app.TabGroup);
            app.Tab_Step1.Title = 'Step 1: Characterization';
            app.UIAxes_1 = uiaxes(app.Tab_Step1);
            title(app.UIAxes_1, 'Fit PSD')
            xlabel(app.UIAxes_1, 'Freguency (Hz)')
            ylabel(app.UIAxes_1, 'PSD (dB/Hz)')
            app.TabGroup1 = uitabgroup(app.Tab_Step1);
            app.linearscaleTab = uitab(app.TabGroup1);
            app.linearscaleTab.Title = 'linear scale';
            app.Tab_linear = uitabgroup(app.linearscaleTab);
            app.Tab_linear.SelectionChangedFcn = createCallbackFcn(app, @Tab_linearSelectionChanged, true);
            app.APTab = uitab(app.Tab_linear);
            app.APTab.Title = 'AP';
            app.UIAxes2_1 = uiaxes(app.APTab);
            title(app.UIAxes2_1, 'Burst Statistics')
            xlabel(app.UIAxes2_1, 'Amplitude peak (\muV)')
            ylabel(app.UIAxes2_1, 'Probability density')
            app.CNTab = uitab(app.Tab_linear);
            app.CNTab.Title = 'CN';
            app.UIAxes2_2 = uiaxes(app.CNTab);
            title(app.UIAxes2_2, 'Burst Statistics')
            xlabel(app.UIAxes2_2, 'Cycle number')
            ylabel(app.UIAxes2_2, 'Probability density')
            app.BFTab = uitab(app.Tab_linear);
            app.BFTab.Title = 'BF';
            app.UIAxes2_3 = uiaxes(app.BFTab);
            title(app.UIAxes2_3, 'Burst Statistics')
            xlabel(app.UIAxes2_3, 'Burst frequency (Hz)')
            ylabel(app.UIAxes2_3, 'Probability density')
            app.logscaleTab = uitab(app.TabGroup1);
            app.logscaleTab.Title = 'log scale';
            app.Tab_log = uitabgroup(app.logscaleTab);
            app.Tab_log.SelectionChangedFcn = createCallbackFcn(app, @Tab_logSelectionChanged, true);
            app.APTab_2 = uitab(app.Tab_log);
            app.APTab_2.Title = 'AP';
            app.UIAxes2_4 = uiaxes(app.APTab_2);
            title(app.UIAxes2_4, 'Burst Statistics')
            xlabel(app.UIAxes2_4, 'Amplitude peak (log scale)')
            ylabel(app.UIAxes2_4, 'Probability density')
            app.CNTab_2 = uitab(app.Tab_log);
            app.CNTab_2.Title = 'CN';
            app.UIAxes2_5 = uiaxes(app.CNTab_2);
            title(app.UIAxes2_5, 'Burst Statistics')
            xlabel(app.UIAxes2_5, 'Cycle number (log scale)')
            ylabel(app.UIAxes2_5, 'Probability density')
            app.BFTab_2 = uitab(app.Tab_log);
            app.BFTab_2.Title = 'BF';
            app.UIAxes2_6 = uiaxes(app.BFTab_2);
            title(app.UIAxes2_6, 'Burst Statistics')
            xlabel(app.UIAxes2_6, 'Burst frequency (log scale)')
            ylabel(app.UIAxes2_6, 'Probability density')
            app.ResultsTextArea_1 = uitextarea(app.Tab_Step1);
            app.ResultsTextArea_1.Editable = 'off';
            app.ResultsTextArea_1.Placeholder = '(Output Message)';
            app.SaveCharacterizationDataPanel = uipanel(app.Tab_Step1);
            app.SaveCharacterizationDataPanel.Title = 'Save Characterization Data';
            app.SaveCharacterizationDataButton = uibutton(app.SaveCharacterizationDataPanel, 'push');
            app.SaveCharacterizationDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCharacterizationDataButtonPushed, true);
            app.SaveCharacterizationDataButton.Enable = 'off';
            app.SaveCharacterizationDataButton.Text = 'Save';
            app.AlsoSaveBurstStatisticsCheckBox = uicheckbox(app.SaveCharacterizationDataPanel);
            app.AlsoSaveBurstStatisticsCheckBox.Enable = 'off';
            app.AlsoSaveBurstStatisticsCheckBox.Text = 'Also Save Burst Statistics';
            app.EditField_Dataname = uieditfield(app.SaveCharacterizationDataPanel, 'text');
            app.EditField_Dataname.Value = 'Dataname';
            app.CharacterizeBurstsandFitProbabilityDistributionPanel = uipanel(app.Tab_Step1);
            app.CharacterizeBurstsandFitProbabilityDistributionPanel.Title = 'Characterize Bursts and Fit Probability Distribution';
            app.FitBurstsButton = uibutton(app.CharacterizeBurstsandFitProbabilityDistributionPanel, 'push');
            app.FitBurstsButton.ButtonPushedFcn = createCallbackFcn(app, @FitBurstsButtonPushed, true);
            app.FitBurstsButton.Enable = 'off';
            app.FitBurstsButton.Text = 'Run';
            app.OptionsButton_Burst = uibutton(app.CharacterizeBurstsandFitProbabilityDistributionPanel, 'push');
            app.OptionsButton_Burst.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton_BurstPushed, true);
            app.OptionsButton_Burst.Text = 'Options';
            app.UnitofAmplitudeEditField = uieditfield(app.CharacterizeBurstsandFitProbabilityDistributionPanel, 'text');
            app.UnitofAmplitudeEditField.CharacterLimits = [1 Inf];
            app.UnitofAmplitudeEditField.Value = 'μV';
            app.NBinsButtonGroup = uibuttongroup(app.CharacterizeBurstsandFitProbabilityDistributionPanel);
            app.NBinsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NBinsButtonGroupSelectionChanged, true);
            app.NBinsButtonGroup.Title = 'Number of Histogram Bins';
            app.NBinsEditField = uieditfield(app.NBinsButtonGroup, 'numeric');
            app.NBinsEditField.Limits = [5 100];
            app.NBinsEditField.RoundFractionalValues = 'on';
            app.NBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NBinsEditFieldValueChanged, true);
            app.NBinsEditField.Value = 30;
            app.SpecifyButton_NBins = uiradiobutton(app.NBinsButtonGroup);
            app.SpecifyButton_NBins.Text = 'Specify:';
            app.DefaultButton_NBins = uiradiobutton(app.NBinsButtonGroup);
            app.DefaultButton_NBins.Text = 'Default';
            app.DefaultButton_NBins.Value = true;
            app.UnitofAmplitudeEditFieldLabel = uilabel(app.CharacterizeBurstsandFitProbabilityDistributionPanel);
            app.UnitofAmplitudeEditFieldLabel.Text = 'Unit of Amplitude';
            app.FitPSDPanel = uipanel(app.Tab_Step1);
            app.FitPSDPanel.Title = 'Fit PSD';
            app.SignalFrequencyBandLabel = uilabel(app.FitPSDPanel);
            app.SignalFrequencyBandLabel.Text = 'Signal Frequency Band';
            app.FitPsdButton = uibutton(app.FitPSDPanel, 'push');
            app.FitPsdButton.ButtonPushedFcn = createCallbackFcn(app, @FitPsdButtonPushed, true);
            app.FitPsdButton.Enable = 'off';
            app.FitPsdButton.Text = 'Fit';
            app.OptionsButton_PSD = uibutton(app.FitPSDPanel, 'push');
            app.OptionsButton_PSD.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton_PSDPushed, true);
            app.OptionsButton_PSD.Text = 'Options';
            app.Label = uilabel(app.FitPSDPanel);
            app.Label.Text = '-';
            app.DBThresholdButtonGroup = uibuttongroup(app.FitPSDPanel);
            app.DBThresholdButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DBThresholdButtonGroupSelectionChanged, true);
            app.DBThresholdButtonGroup.Title = 'Decibel Threshold';
            app.tDBLabel = uilabel(app.DBThresholdButtonGroup);
            app.tDBLabel.Text = 'dB';
            app.tDBEditField = uieditfield(app.DBThresholdButtonGroup, 'numeric');
            app.tDBEditField.Limits = [0 Inf];
            app.tDBEditField.ValueChangedFcn = createCallbackFcn(app, @tDBEditFieldValueChanged, true);
            app.tDBEditField.Value = 1;
            app.SpecifyButton_tDB = uiradiobutton(app.DBThresholdButtonGroup);
            app.SpecifyButton_tDB.Text = 'Specify:';
            app.DefaultButton_tDB = uiradiobutton(app.DBThresholdButtonGroup);
            app.DefaultButton_tDB.Text = 'Default';
            app.DefaultButton_tDB.Value = true;
            app.FitRangeButtonGroup = uibuttongroup(app.FitPSDPanel);
            app.FitRangeButtonGroup.Title = 'Frequency Range for Fitting';
            app.EditField_2Label = uilabel(app.FitRangeButtonGroup);
            app.EditField_2Label.Text = '-';
            app.HzLabel = uilabel(app.FitRangeButtonGroup);
            app.HzLabel.Text = 'Hz';
            app.FitRangeEditField2 = uieditfield(app.FitRangeButtonGroup, 'numeric');
            app.FitRangeEditField2.Limits = [0 Inf];
            app.FitRangeEditField2.Value = 200;
            app.FitRangeEditField1 = uieditfield(app.FitRangeButtonGroup, 'numeric');
            app.FitRangeEditField1.Limits = [0 Inf];
            app.FitRangeEditField1.Value = 10;
            app.SpecifyButton_FitRange = uiradiobutton(app.FitRangeButtonGroup);
            app.SpecifyButton_FitRange.Text = 'Specify:';
            app.AutofitButton = uiradiobutton(app.FitRangeButtonGroup);
            app.AutofitButton.Text = 'Autofit';
            app.AutofitButton.Value = true;
            app.HzLabel_2 = uilabel(app.FitPSDPanel);
            app.HzLabel_2.Text = 'Hz';
            app.FreqBandEditField2 = uieditfield(app.FitPSDPanel, 'numeric');
            app.FreqBandEditField2.Limits = [0 Inf];
            app.FreqBandEditField2.Value = 80;
            app.FreqBandEditField1 = uieditfield(app.FitPSDPanel, 'numeric');
            app.FreqBandEditField1.Limits = [0 Inf];
            app.FreqBandEditField1.Value = 30;
            app.SelectDataPanel = uipanel(app.Tab_Step1);
            app.SelectDataPanel.Title = 'Select Data';
            app.LFPDataDropDownLabel = uilabel(app.SelectDataPanel);
            app.LFPDataDropDownLabel.Text = 'LFP Data';
            app.SamplingRateLabel = uilabel(app.SelectDataPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.HzLabel_4 = uilabel(app.SelectDataPanel);
            app.HzLabel_4.Text = 'Hz';
            app.Data_fs = uieditfield(app.SelectDataPanel, 'text');
            app.Data_fs.ValueChangedFcn = createCallbackFcn(app, @Data_fsValueChanged, true);
            app.Data_fs.Value = '1000';
            app.LFPDataDropDown = uidropdown(app.SelectDataPanel);
            app.LFPDataDropDown.Items = {'__Select__'};
            app.LFPDataDropDown.Editable = 'on';
            app.LFPDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LFPDataDropDownValueChanged, true);
            app.LFPDataDropDown.Value = '__Select__';
            app.ProjectDirectoryPanel = uipanel(app.Tab_Step1);
            app.ProjectDirectoryPanel.Title = 'Project Directory';
            app.SelectedDirectoryEditFieldLabel = uilabel(app.ProjectDirectoryPanel);
            app.SelectedDirectoryEditFieldLabel.Text = 'Selected Directory:';
            app.SelectedDirectoryEditField = uieditfield(app.ProjectDirectoryPanel, 'text');
            app.SelectedDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedDirectoryEditFieldValueChanged, true);
            app.SelectFolderButton = uibutton(app.ProjectDirectoryPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ResultsTextAreaLabel = uilabel(app.Tab_Step1);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.Tab_Step2 = uitab(app.TabGroup);
            app.Tab_Step2.Title = 'Step 2: Synthesis';
            app.UIAxes3 = uiaxes(app.Tab_Step2);
            title(app.UIAxes3, 'Result of Optimization')
            xlabel(app.UIAxes3, 'Amplitude peak (\muV)')
            ylabel(app.UIAxes3, 'Rate density (Hz/\muV)')
            app.TabGroup2 = uitabgroup(app.Tab_Step2);
            app.PSDTab = uitab(app.TabGroup2);
            app.PSDTab.Title = 'Power Spectral Density';
            app.UIAxes4_1 = uiaxes(app.PSDTab);
            title(app.UIAxes4_1, 'Synthetic Data')
            xlabel(app.UIAxes4_1, 'Freguency (Hz)')
            ylabel(app.UIAxes4_1, 'PSD (dB/Hz)')
            app.BurstStatsTab = uitab(app.TabGroup2);
            app.BurstStatsTab.Title = 'Bursts Statistics';
            app.Tab_Bursts = uitabgroup(app.BurstStatsTab);
            app.APTab_3 = uitab(app.Tab_Bursts);
            app.APTab_3.Title = 'AP';
            app.UIAxes4_2 = uiaxes(app.APTab_3);
            title(app.UIAxes4_2, 'Synthetic Burst Statistics')
            xlabel(app.UIAxes4_2, 'Amplitude peak (\muV)')
            ylabel(app.UIAxes4_2, 'Probability density')
            app.CNTab_3 = uitab(app.Tab_Bursts);
            app.CNTab_3.Title = 'CN';
            app.UIAxes4_3 = uiaxes(app.CNTab_3);
            title(app.UIAxes4_3, 'Synthetic Burst Statistics')
            xlabel(app.UIAxes4_3, 'Cycle number')
            ylabel(app.UIAxes4_3, 'Probability density')
            app.BFTab_3 = uitab(app.Tab_Bursts);
            app.BFTab_3.Title = 'BF';
            app.UIAxes4_4 = uiaxes(app.BFTab_3);
            title(app.UIAxes4_4, 'Synthetic Burst Statistics')
            xlabel(app.UIAxes4_4, 'Burst frequency (Hz)')
            ylabel(app.UIAxes4_4, 'Probability density')
            app.ResultsTextArea_2 = uitextarea(app.Tab_Step2);
            app.ResultsTextArea_2.Editable = 'off';
            app.ResultsTextArea_2.Placeholder = '(Output Message)';
            app.SaveSynthesisParametersPanel = uipanel(app.Tab_Step2);
            app.SaveSynthesisParametersPanel.Title = 'Save Synthesis Parameters';
            app.SaveSyntheticButton = uibutton(app.SaveSynthesisParametersPanel, 'push');
            app.SaveSyntheticButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSyntheticButtonPushed, true);
            app.SaveSyntheticButton.Enable = 'off';
            app.SaveSyntheticButton.Text = 'Save';
            app.AlsoSaveSyntheticLFPCheckBox = uicheckbox(app.SaveSynthesisParametersPanel);
            app.AlsoSaveSyntheticLFPCheckBox.Enable = 'off';
            app.AlsoSaveSyntheticLFPCheckBox.Text = 'Also Save Synthetic LFP';
            app.EditField_SynParamDataname = uieditfield(app.SaveSynthesisParametersPanel, 'text');
            app.EditField_SynParamDataname.Value = 'Case name';
            app.GenerateSyntheticLFPPanel = uipanel(app.Tab_Step2);
            app.GenerateSyntheticLFPPanel.Title = 'Generate Synthetic LFP';
            app.GenerateButton = uibutton(app.GenerateSyntheticLFPPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Run';
            app.DistributionTypeDropDownLabel = uilabel(app.GenerateSyntheticLFPPanel);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.GenerateSyntheticLFPPanel);
            app.DistributionTypeDropDown.Items = {};
            app.DistributionTypeDropDown.Value = {};
            app.SyntheticLengthButtonGroup = uibuttongroup(app.GenerateSyntheticLFPPanel);
            app.SyntheticLengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SyntheticLengthButtonGroupSelectionChanged, true);
            app.SyntheticLengthButtonGroup.Title = 'Synthetic Data Length';
            app.secondsLabel2 = uilabel(app.SyntheticLengthButtonGroup);
            app.secondsLabel2.Text = 'seconds';
            app.SynlenEditField = uieditfield(app.SyntheticLengthButtonGroup, 'numeric');
            app.SynlenEditField.Limits = [60 Inf];
            app.SynlenEditField.ValueChangedFcn = createCallbackFcn(app, @SynlenEditFieldValueChanged, true);
            app.SynlenEditField.Value = 5000;
            app.SpecifyButton_Synlen = uiradiobutton(app.SyntheticLengthButtonGroup);
            app.SpecifyButton_Synlen.Text = 'Specify:';
            app.DefaultButton_Synlen = uiradiobutton(app.SyntheticLengthButtonGroup);
            app.DefaultButton_Synlen.Text = 'Default';
            app.DefaultButton_Synlen.Value = true;
            app.OptimizeAmplitudeDistributionParametersPanel = uipanel(app.Tab_Step2);
            app.OptimizeAmplitudeDistributionParametersPanel.Title = 'Optimize Amplitude Distribution Parameters';
            app.DistributionTypesListBoxLabel = uilabel(app.OptimizeAmplitudeDistributionParametersPanel);
            app.DistributionTypesListBoxLabel.Text = {'Distribution Types'; ''};
            app.PopulationDropDownLabel = uilabel(app.OptimizeAmplitudeDistributionParametersPanel);
            app.PopulationDropDownLabel.Text = 'Population';
            app.FitAmpButton = uibutton(app.OptimizeAmplitudeDistributionParametersPanel, 'push');
            app.FitAmpButton.ButtonPushedFcn = createCallbackFcn(app, @FitAmpButtonPushed, true);
            app.FitAmpButton.Enable = 'off';
            app.FitAmpButton.Text = 'Fit';
            app.OptionsButton_Optim = uibutton(app.OptimizeAmplitudeDistributionParametersPanel, 'push');
            app.OptionsButton_Optim.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton_OptimPushed, true);
            app.OptionsButton_Optim.Text = 'Options';
            app.ButtonGroup_FitTarget = uibuttongroup(app.OptimizeAmplitudeDistributionParametersPanel);
            app.Fit_Amp = uiradiobutton(app.ButtonGroup_FitTarget);
            app.Fit_Amp.Text = 'Amplitude';
            app.Fit_AP = uiradiobutton(app.ButtonGroup_FitTarget);
            app.Fit_AP.Text = 'Amplitude peak';
            app.Fit_AP.Value = true;
            app.FitTargetLabel = uilabel(app.ButtonGroup_FitTarget);
            app.FitTargetLabel.Text = 'Fit Target';
            app.nPopEditField = uieditfield(app.OptimizeAmplitudeDistributionParametersPanel, 'numeric');
            app.nPopEditField.Limits = [4 40];
            app.nPopEditField.RoundFractionalValues = 'on';
            app.nPopEditField.ValueChangedFcn = createCallbackFcn(app, @nPopEditFieldValueChanged, true);
            app.nPopEditField.Value = 8;
            app.ResetButton = uibutton(app.OptimizeAmplitudeDistributionParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PopulationDropDown = uidropdown(app.OptimizeAmplitudeDistributionParametersPanel);
            app.PopulationDropDown.Items = {};
            app.PopulationDropDown.ValueChangedFcn = createCallbackFcn(app, @PopulationDropDownValueChanged, true);
            app.PopulationDropDown.Value = {};
            app.DistributionTypesListBox = uilistbox(app.OptimizeAmplitudeDistributionParametersPanel);
            app.DistributionTypesListBox.Items = {};
            app.DistributionTypesListBox.Multiselect = 'on';
            app.DistributionTypesListBox.ValueChangedFcn = createCallbackFcn(app, @DistributionTypesListBoxValueChanged, true);
            app.DistributionTypesListBox.Tooltip = {'Hold ''Ctrl'' to select multiple'};
            app.DistributionTypesListBox.Value = {};
            app.SyntheticLengthOptimButtonGroup = uibuttongroup(app.OptimizeAmplitudeDistributionParametersPanel);
            app.SyntheticLengthOptimButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SyntheticLengthOptimButtonGroupSelectionChanged, true);
            app.SyntheticLengthOptimButtonGroup.Title = 'Synthetic Data Length';
            app.secondsLabel = uilabel(app.SyntheticLengthOptimButtonGroup);
            app.secondsLabel.Text = 'seconds';
            app.SynlenOptimEditField = uieditfield(app.SyntheticLengthOptimButtonGroup, 'numeric');
            app.SynlenOptimEditField.Limits = [0 Inf];
            app.SynlenOptimEditField.ValueChangedFcn = createCallbackFcn(app, @SynlenOptimEditFieldValueChanged, true);
            app.SynlenOptimEditField.Value = 1000;
            app.SpecifyButton_SynlenOptim = uiradiobutton(app.SyntheticLengthOptimButtonGroup);
            app.SpecifyButton_SynlenOptim.Text = 'Specify:';
            app.DefaultButton_SynlenOptim = uiradiobutton(app.SyntheticLengthOptimButtonGroup);
            app.DefaultButton_SynlenOptim.Text = 'Default';
            app.DefaultButton_SynlenOptim.Value = true;
            app.SynthesisSettingsPanel = uipanel(app.Tab_Step2);
            app.SynthesisSettingsPanel.Title = 'Synthesis Settings';
            app.RngSeedLabel = uilabel(app.SynthesisSettingsPanel);
            app.RngSeedLabel.Text = 'Rng Seed';
            app.LoadRealDataButton = uibutton(app.SynthesisSettingsPanel, 'push');
            app.LoadRealDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRealDataButtonPushed, true);
            app.LoadRealDataButton.Text = 'Load';
            app.OptionsButton_SynSettings = uibutton(app.SynthesisSettingsPanel, 'push');
            app.OptionsButton_SynSettings.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton_SynSettingsPushed, true);
            app.OptionsButton_SynSettings.Text = 'Options';
            app.RngSeedEditField = uieditfield(app.SynthesisSettingsPanel, 'numeric');
            app.RngSeedEditField.Limits = [0 4294967291];
            app.SamplingRateGroup = uibuttongroup(app.SynthesisSettingsPanel);
            app.SamplingRateGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplingRateGroupSelectionChanged, true);
            app.HzLabel_3 = uilabel(app.SamplingRateGroup);
            app.HzLabel_3.Text = 'Hz';
            app.fsEditField = uieditfield(app.SamplingRateGroup, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.ValueChangedFcn = createCallbackFcn(app, @fsEditFieldValueChanged, true);
            app.fsEditField.Value = 1000;
            app.SpecifyButton_fs = uiradiobutton(app.SamplingRateGroup);
            app.SpecifyButton_fs.Text = 'Specify:';
            app.DefaultButton_fs = uiradiobutton(app.SamplingRateGroup);
            app.DefaultButton_fs.Text = 'Same as LFP Data';
            app.DefaultButton_fs.Value = true;
            app.SyntheticSamplingRateLabel = uilabel(app.SamplingRateGroup);
            app.SyntheticSamplingRateLabel.Text = 'Sampling Rate';
            app.CharacterizationDataPanel = uipanel(app.Tab_Step2);
            app.CharacterizationDataPanel.Title = 'Characterization Data';
            app.EditField_SelectRealDataname = uieditfield(app.CharacterizationDataPanel, 'text');
            app.EditField_SelectRealDataname.ValueChangedFcn = createCallbackFcn(app, @EditField_SelectRealDatanameValueChanged, true);
            app.SelectRealDataButton = uibutton(app.CharacterizationDataPanel, 'push');
            app.SelectRealDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRealDataButtonPushed, true);
            app.SelectRealDataButton.Text = 'Select File';
            app.ResultsTextArea_2Label = uilabel(app.Tab_Step2);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.Tab_Step3 = uitab(app.TabGroup);
            app.Tab_Step3.Title = 'Step 3: Analysis';
            app.UIAxes6 = uiaxes(app.Tab_Step3);
            title(app.UIAxes6, 'Ground Truth Distribution')
            xlabel(app.UIAxes6, 'Amplitude peak (\muV)')
            ylabel(app.UIAxes6, 'Rate density (Hz/\muV)')
            app.UIAxes5 = uiaxes(app.Tab_Step3);
            title(app.UIAxes5, 'ROC')
            xlabel(app.UIAxes5, 'False positive rate (%)')
            ylabel(app.UIAxes5, 'True positive rate (%)')
            app.Button_Normalize = uibutton(app.Tab_Step3, 'state');
            app.Button_Normalize.ValueChangedFcn = createCallbackFcn(app, @Button_NormalizeValueChanged, true);
            app.Button_Normalize.Enable = 'off';
            app.Button_Normalize.Text = 'Normalize';
            app.ResultsTextArea_3 = uitextarea(app.Tab_Step3);
            app.ResultsTextArea_3.Editable = 'off';
            app.ResultsTextArea_3.Placeholder = '(Output Message)';
            app.DetectionPerformanceandROCCurvePanel = uipanel(app.Tab_Step3);
            app.DetectionPerformanceandROCCurvePanel.Title = 'Detection Performance and ROC Curve';
            app.ProbabilityGivenDetection = uipanel(app.DetectionPerformanceandROCCurvePanel);
            app.FalseFDRLabel = uilabel(app.ProbabilityGivenDetection);
            app.FalseFDRLabel.Enable = 'off';
            app.FalseFDRLabel.Text = 'False (FDR)';
            app.IntermediateLabel = uilabel(app.ProbabilityGivenDetection);
            app.IntermediateLabel.Enable = 'off';
            app.IntermediateLabel.Text = 'Intermediate';
            app.TruePPVLabel = uilabel(app.ProbabilityGivenDetection);
            app.TruePPVLabel.Enable = 'off';
            app.TruePPVLabel.Text = 'True (PPV)';
            app.EditField_PT = uieditfield(app.ProbabilityGivenDetection, 'numeric');
            app.EditField_PT.Limits = [0 100];
            app.EditField_PT.ValueDisplayFormat = '%.3g %%';
            app.EditField_PT.Editable = 'off';
            app.EditField_PT.Enable = 'off';
            app.EditField_PI = uieditfield(app.ProbabilityGivenDetection, 'numeric');
            app.EditField_PI.Limits = [0 100];
            app.EditField_PI.ValueDisplayFormat = '%.3g %%';
            app.EditField_PI.Editable = 'off';
            app.EditField_PI.Enable = 'off';
            app.EditField_PF = uieditfield(app.ProbabilityGivenDetection, 'numeric');
            app.EditField_PF.Limits = [0 100];
            app.EditField_PF.ValueDisplayFormat = '%.3g %%';
            app.EditField_PF.Editable = 'off';
            app.EditField_PF.Enable = 'off';
            app.DropDown_Condition = uidropdown(app.ProbabilityGivenDetection);
            app.DropDown_Condition.Items = {'> the Threshold', '= the Threshold'};
            app.DropDown_Condition.Editable = 'on';
            app.DropDown_Condition.ValueChangedFcn = createCallbackFcn(app, @DropDown_ConditionValueChanged, true);
            app.DropDown_Condition.Enable = 'off';
            app.DropDown_Condition.Value = '> the Threshold';
            app.ProbabilityGivenDetectionLabel = uilabel(app.ProbabilityGivenDetection);
            app.ProbabilityGivenDetectionLabel.Enable = 'off';
            app.ProbabilityGivenDetectionLabel.Text = {'Conditional Probability'; 'Given a Detection'};
            app.FPRLabel = uilabel(app.DetectionPerformanceandROCCurvePanel);
            app.FPRLabel.Enable = 'off';
            app.FPRLabel.Text = '%';
            app.EditField_CFAR_slider = uieditfield(app.DetectionPerformanceandROCCurvePanel, 'numeric');
            app.EditField_CFAR_slider.Limits = [0 100];
            app.EditField_CFAR_slider.RoundFractionalValues = 'on';
            app.EditField_CFAR_slider.ValueChangedFcn = createCallbackFcn(app, @EditField_CFAR_sliderValueChanged, true);
            app.EditField_CFAR_slider.Enable = 'off';
            app.Slider_CFAR = uislider(app.DetectionPerformanceandROCCurvePanel);
            app.Slider_CFAR.ValueChangedFcn = createCallbackFcn(app, @Slider_CFARValueChanged, true);
            app.Slider_CFAR.Enable = 'off';
            app.Slider_CFAR.Value = 100;
            app.FalsePositiveRateLabel = uilabel(app.DetectionPerformanceandROCCurvePanel);
            app.FalsePositiveRateLabel.Enable = 'off';
            app.FalsePositiveRateLabel.Text = 'False Positive Rate';
            app.EditField_CFAR = uieditfield(app.DetectionPerformanceandROCCurvePanel, 'numeric');
            app.EditField_CFAR.Limits = [0 Inf];
            app.EditField_CFAR.ValueDisplayFormat = '%.3g Hz';
            app.EditField_CFAR.ValueChangedFcn = createCallbackFcn(app, @EditField_CFARValueChanged, true);
            app.EditField_CFAR.Enable = 'off';
            app.VLabel = uilabel(app.DetectionPerformanceandROCCurvePanel);
            app.VLabel.Enable = 'off';
            app.VLabel.Text = 'μV';
            app.EditField_Threshold_slider = uieditfield(app.DetectionPerformanceandROCCurvePanel, 'numeric');
            app.EditField_Threshold_slider.Limits = [0 100];
            app.EditField_Threshold_slider.RoundFractionalValues = 'on';
            app.EditField_Threshold_slider.ValueChangedFcn = createCallbackFcn(app, @EditField_Threshold_sliderValueChanged, true);
            app.EditField_Threshold_slider.Enable = 'off';
            app.Slider_Threshold = uislider(app.DetectionPerformanceandROCCurvePanel);
            app.Slider_Threshold.ValueChangedFcn = createCallbackFcn(app, @Slider_ThresholdValueChanged, true);
            app.Slider_Threshold.Enable = 'off';
            app.Slider_Threshold.Value = 100;
            app.EditField_Threshold = uieditfield(app.DetectionPerformanceandROCCurvePanel, 'numeric');
            app.EditField_Threshold.ValueDisplayFormat = '%.3g z-score';
            app.EditField_Threshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ThresholdValueChanged, true);
            app.EditField_Threshold.Enable = 'off';
            app.DetectionThresholdLabel = uilabel(app.DetectionPerformanceandROCCurvePanel);
            app.DetectionThresholdLabel.Enable = 'off';
            app.DetectionThresholdLabel.Text = 'Detection Threshold';
            app.DetectButton = uibutton(app.DetectionPerformanceandROCCurvePanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Enable = 'off';
            app.DetectButton.Text = 'Evaluate';
            app.ButtonGroup_DetectOn = uibuttongroup(app.DetectionPerformanceandROCCurvePanel);
            app.Detect_Amp = uiradiobutton(app.ButtonGroup_DetectOn);
            app.Detect_Amp.Text = 'Amplitude';
            app.Detect_AP = uiradiobutton(app.ButtonGroup_DetectOn);
            app.Detect_AP.Text = 'Amplitude peak';
            app.Detect_AP.Value = true;
            app.DetectonLabel = uilabel(app.ButtonGroup_DetectOn);
            app.DetectonLabel.Text = 'Detect on';
            app.ButtonGroup_NThresholds = uibuttongroup(app.DetectionPerformanceandROCCurvePanel);
            app.ButtonGroup_NThresholds.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_NThresholdsSelectionChanged, true);
            app.EditField_NThresh = uieditfield(app.ButtonGroup_NThresholds, 'numeric');
            app.EditField_NThresh.Limits = [10 300];
            app.EditField_NThresh.RoundFractionalValues = 'on';
            app.EditField_NThresh.ValueChangedFcn = createCallbackFcn(app, @EditField_NThreshValueChanged, true);
            app.EditField_NThresh.Value = 50;
            app.SpecifyButton_NThresh = uiradiobutton(app.ButtonGroup_NThresholds);
            app.SpecifyButton_NThresh.Text = 'Specify:';
            app.DefaultButton_NThresh = uiradiobutton(app.ButtonGroup_NThresholds);
            app.DefaultButton_NThresh.Text = 'Default';
            app.DefaultButton_NThresh.Value = true;
            app.NumberofThresholdsLabel = uilabel(app.ButtonGroup_NThresholds);
            app.NumberofThresholdsLabel.Text = 'Number of Thresholds';
            app.PreselectedBoundsPanel = uipanel(app.Tab_Step3);
            app.PreselectedBoundsPanel.Title = 'Pre-selected Bounds';
            app.PreboundButton = uibutton(app.PreselectedBoundsPanel, 'push');
            app.PreboundButton.ButtonPushedFcn = createCallbackFcn(app, @PreboundButtonPushed, true);
            app.PreboundButton.Enable = 'off';
            app.PreboundButton.Text = 'Select Bounds';
            app.ButtonGroup_UpperBound_prop = uibuttongroup(app.PreselectedBoundsPanel);
            app.TimeULabel = uilabel(app.ButtonGroup_UpperBound_prop);
            app.TimeULabel.Text = 'Time (>θU)';
            app.TruepeakrateEditField_perc = uieditfield(app.ButtonGroup_UpperBound_prop, 'numeric');
            app.TruepeakrateEditField_perc.Limits = [0 100];
            app.TruepeakrateEditField_perc.ValueDisplayFormat = '%.3g %%';
            app.TruepeakrateEditField_perc.Editable = 'off';
            app.TruePeakRateEditField = uieditfield(app.ButtonGroup_UpperBound_prop, 'numeric');
            app.TruePeakRateEditField.Limits = [0 Inf];
            app.TruePeakRateEditField.ValueDisplayFormat = '%.4g Hz';
            app.TruePeakRateEditField.Editable = 'off';
            app.EditField_TU = uieditfield(app.ButtonGroup_UpperBound_prop, 'numeric');
            app.EditField_TU.Limits = [0 100];
            app.EditField_TU.ValueDisplayFormat = '%.3g %%';
            app.EditField_TU.Editable = 'off';
            app.TruePeakRateEditFieldLabel = uilabel(app.ButtonGroup_UpperBound_prop);
            app.TruePeakRateEditFieldLabel.Text = 'True Peak Rate';
            app.ButtonGroup_LowerBound_prop = uibuttongroup(app.PreselectedBoundsPanel);
            app.TimeLLabel = uilabel(app.ButtonGroup_LowerBound_prop);
            app.TimeLLabel.Text = 'Time (<θL)';
            app.FalsePeakRateEditFieldLabel = uilabel(app.ButtonGroup_LowerBound_prop);
            app.FalsePeakRateEditFieldLabel.Text = 'False Peak Rate';
            app.FalsepeakrateEditField_perc = uieditfield(app.ButtonGroup_LowerBound_prop, 'numeric');
            app.FalsepeakrateEditField_perc.Limits = [0 100];
            app.FalsepeakrateEditField_perc.ValueDisplayFormat = '%.3g %%';
            app.FalsepeakrateEditField_perc.Editable = 'off';
            app.FalsePeakRateEditField = uieditfield(app.ButtonGroup_LowerBound_prop, 'numeric');
            app.FalsePeakRateEditField.Limits = [0 Inf];
            app.FalsePeakRateEditField.ValueDisplayFormat = '%.4g Hz';
            app.FalsePeakRateEditField.Editable = 'off';
            app.EditField_TL = uieditfield(app.ButtonGroup_LowerBound_prop, 'numeric');
            app.EditField_TL.Limits = [0 100];
            app.EditField_TL.ValueDisplayFormat = '%.3g %%';
            app.EditField_TL.Editable = 'off';
            app.ButtonGroup_UpperBound = uibuttongroup(app.PreselectedBoundsPanel);
            app.ButtonGroup_UpperBound.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_UpperBoundSelectionChanged, true);
            app.EditField_UpperBound_sig = uieditfield(app.ButtonGroup_UpperBound, 'numeric');
            app.EditField_UpperBound_sig.LowerLimitInclusive = 'off';
            app.EditField_UpperBound_sig.UpperLimitInclusive = 'off';
            app.EditField_UpperBound_sig.Limits = [0 Inf];
            app.EditField_UpperBound_sig.ValueDisplayFormat = '%.3g σ';
            app.EditField_UpperBound_sig.ValueChangedFcn = createCallbackFcn(app, @EditField_UpperBound_sigValueChanged, true);
            app.EditField_UpperBound_sig.Enable = 'off';
            app.EditField_UpperBound_sig.Value = 1.4142135623731;
            app.EditField_UpperBound = uieditfield(app.ButtonGroup_UpperBound, 'numeric');
            app.EditField_UpperBound.LowerLimitInclusive = 'off';
            app.EditField_UpperBound.UpperLimitInclusive = 'off';
            app.EditField_UpperBound.Limits = [0 Inf];
            app.EditField_UpperBound.ValueDisplayFormat = '%.4g μV';
            app.EditField_UpperBound.ValueChangedFcn = createCallbackFcn(app, @EditField_UpperBoundValueChanged, true);
            app.EditField_UpperBound.Enable = 'off';
            app.EditField_UpperBound.Value = 1;
            app.SpecifyButton_UpperBound = uiradiobutton(app.ButtonGroup_UpperBound);
            app.SpecifyButton_UpperBound.Enable = 'off';
            app.SpecifyButton_UpperBound.Text = 'Specify:';
            app.DefaultButton_UpperBound = uiradiobutton(app.ButtonGroup_UpperBound);
            app.DefaultButton_UpperBound.Text = 'Default';
            app.DefaultButton_UpperBound.Value = true;
            app.UpperBoundULabel = uilabel(app.ButtonGroup_UpperBound);
            app.UpperBoundULabel.Text = {'Upper Bound θU'; ''};
            app.ButtonGroup_LowerBound = uibuttongroup(app.PreselectedBoundsPanel);
            app.ButtonGroup_LowerBound.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_LowerBoundSelectionChanged, true);
            app.EditField_LowerBound_sig = uieditfield(app.ButtonGroup_LowerBound, 'numeric');
            app.EditField_LowerBound_sig.LowerLimitInclusive = 'off';
            app.EditField_LowerBound_sig.UpperLimitInclusive = 'off';
            app.EditField_LowerBound_sig.Limits = [0 Inf];
            app.EditField_LowerBound_sig.ValueDisplayFormat = '%.3g σ';
            app.EditField_LowerBound_sig.ValueChangedFcn = createCallbackFcn(app, @EditField_LowerBound_sigValueChanged, true);
            app.EditField_LowerBound_sig.Enable = 'off';
            app.EditField_LowerBound_sig.Value = 1.4142135623731;
            app.EditField_LowerBound = uieditfield(app.ButtonGroup_LowerBound, 'numeric');
            app.EditField_LowerBound.LowerLimitInclusive = 'off';
            app.EditField_LowerBound.UpperLimitInclusive = 'off';
            app.EditField_LowerBound.Limits = [0 Inf];
            app.EditField_LowerBound.ValueDisplayFormat = '%.4g μV';
            app.EditField_LowerBound.ValueChangedFcn = createCallbackFcn(app, @EditField_LowerBoundValueChanged, true);
            app.EditField_LowerBound.Enable = 'off';
            app.EditField_LowerBound.Value = 1;
            app.SpecifyButton_LowerBound = uiradiobutton(app.ButtonGroup_LowerBound);
            app.SpecifyButton_LowerBound.Enable = 'off';
            app.SpecifyButton_LowerBound.Text = 'Specify:';
            app.DefaultButton_LowerBound = uiradiobutton(app.ButtonGroup_LowerBound);
            app.DefaultButton_LowerBound.Text = 'Default';
            app.DefaultButton_LowerBound.Value = true;
            app.LowerBoundLLabel = uilabel(app.ButtonGroup_LowerBound);
            app.LowerBoundLLabel.Text = 'Lower Bound θL';
            app.SyntheticDataPanel = uipanel(app.Tab_Step3);
            app.SyntheticDataPanel.Title = 'Synthetic Data';
            app.LoadSynDataButton = uibutton(app.SyntheticDataPanel, 'push');
            app.LoadSynDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSynDataButtonPushed, true);
            app.LoadSynDataButton.Text = 'Load';
            app.ReviewSettingsButton = uibutton(app.SyntheticDataPanel, 'push');
            app.ReviewSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewSettingsButtonPushed, true);
            app.ReviewSettingsButton.Text = 'Review Settings';
            app.EditField_SelectSynDataname = uieditfield(app.SyntheticDataPanel, 'text');
            app.EditField_SelectSynDataname.ValueChangedFcn = createCallbackFcn(app, @EditField_SelectSynDatanameValueChanged, true);
            app.SelectSynDataButton = uibutton(app.SyntheticDataPanel, 'push');
            app.SelectSynDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSynDataButtonPushed, true);
            app.SelectSynDataButton.Text = 'Select File';
            app.ResultsTextArea_3Label = uilabel(app.Tab_Step3);
            app.ResultsTextArea_3Label.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end