classdef gui_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Step1                       matlab.ui.container.Tab
        TabGroup1                       matlab.ui.container.TabGroup
        linearscaleTab                  matlab.ui.container.Tab
        Tab_linear                      matlab.ui.container.TabGroup
        APTab                           matlab.ui.container.Tab
        UIAxes2_1                       matlab.ui.control.UIAxes
        CNTab                           matlab.ui.container.Tab
        UIAxes2_2                       matlab.ui.control.UIAxes
        BFTab                           matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        logscaleTab                     matlab.ui.container.Tab
        Tab_log                         matlab.ui.container.TabGroup
        APTab_2                         matlab.ui.container.Tab
        UIAxes2_4                       matlab.ui.control.UIAxes
        CNTab_2                         matlab.ui.container.Tab
        UIAxes2_5                       matlab.ui.control.UIAxes
        BFTab_2                         matlab.ui.container.Tab
        UIAxes2_6                       matlab.ui.control.UIAxes
        ResultsTextAreaLabel            matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        SaveCharacterizationDataPanel   matlab.ui.container.Panel
        SaveCharacterizationDataButton  matlab.ui.control.Button
        EditField_Dataname              matlab.ui.control.EditField
        DatanameLabel                   matlab.ui.control.Label
        CharacterizeBurstsandFitProbabilityDistributionPanel  matlab.ui.container.Panel
        FitBurstsButton                 matlab.ui.control.Button
        OptionsButton_Burst             matlab.ui.control.Button
        NBinsButtonGroup                matlab.ui.container.ButtonGroup
        NBinsEditField                  matlab.ui.control.NumericEditField
        SpecifyButton_NBins             matlab.ui.control.RadioButton
        DefaultButton_NBins             matlab.ui.control.RadioButton
        FitPSDPanel                     matlab.ui.container.Panel
        HzLabel_2                       matlab.ui.control.Label
        FitPsdButton                    matlab.ui.control.Button
        OptionsButton_PSD               matlab.ui.control.Button
        FitRangeButtonGroup             matlab.ui.container.ButtonGroup
        HzLabel                         matlab.ui.control.Label
        EditField_2Label                matlab.ui.control.Label
        FitRangeEditField2              matlab.ui.control.NumericEditField
        FitRangeEditField1              matlab.ui.control.NumericEditField
        SpecifyButton_FitRange          matlab.ui.control.RadioButton
        AutofitButton                   matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        FreqRangeEditField2             matlab.ui.control.NumericEditField
        FreqRangeEditField1             matlab.ui.control.NumericEditField
        OscillationFrequencyRangeEditFieldLabel  matlab.ui.control.Label
        OutlierThresholdButtonGroup     matlab.ui.container.ButtonGroup
        nDBLabel                        matlab.ui.control.Label
        nDBEditField                    matlab.ui.control.NumericEditField
        SpecifyButton_nDB               matlab.ui.control.RadioButton
        DefaultButton_nDB               matlab.ui.control.RadioButton
        SelectDataPanel                 matlab.ui.container.Panel
        data_fs                         matlab.ui.control.EditField
        SamplingRateHzEditFieldLabel    matlab.ui.control.Label
        LFPDataDropDownLabel            matlab.ui.control.Label
        LFPDataDropDown                 matlab.ui.control.DropDown
        ProjectDirectoryPanel           matlab.ui.container.Panel
        SelectedDirectoryEditFieldLabel  matlab.ui.control.Label
        SelectedDirectoryEditField      matlab.ui.control.EditField
        SelectFolderButton              matlab.ui.control.Button
        UIAxes_1                        matlab.ui.control.UIAxes
        Tab_Step2                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PSDTab                          matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        BurstStatsTab                   matlab.ui.container.Tab
        Tab_Bursts                      matlab.ui.container.TabGroup
        APTab_3                         matlab.ui.container.Tab
        UIAxes4_2                       matlab.ui.control.UIAxes
        CNTab_3                         matlab.ui.container.Tab
        UIAxes4_3                       matlab.ui.control.UIAxes
        BFTab_3                         matlab.ui.container.Tab
        UIAxes4_4                       matlab.ui.control.UIAxes
        ResultsTextArea_2Label          matlab.ui.control.Label
        ResultsTextArea_2               matlab.ui.control.TextArea
        SaveSyntheticParameterPanel     matlab.ui.container.Panel
        AlsoSaveSyntheticLFPCheckBox    matlab.ui.control.CheckBox
        SaveSyntheticButton             matlab.ui.control.Button
        EditField_SynParamDataname      matlab.ui.control.EditField
        GenerateSyntheticLFPPanel       matlab.ui.container.Panel
        GenerateButton                  matlab.ui.control.Button
        DistributionTypeDropDown        matlab.ui.control.DropDown
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        SyntheticLengthButtonGroup      matlab.ui.container.ButtonGroup
        secondsLabel2                   matlab.ui.control.Label
        SynlenEditField                 matlab.ui.control.NumericEditField
        SpecifyButton_Synlen            matlab.ui.control.RadioButton
        DefaultButton_Synlen            matlab.ui.control.RadioButton
        OptimizeAmplitudeDistributionParametersPanel  matlab.ui.container.Panel
        ButtonGroup_FitTarget           matlab.ui.container.ButtonGroup
        FitTargetLabel                  matlab.ui.control.Label
        Fit_AS_Amp                      matlab.ui.control.RadioButton
        Fit_AS_AP                       matlab.ui.control.RadioButton
        resetButton                     matlab.ui.control.Button
        nPopEditField                   matlab.ui.control.NumericEditField
        PopulationDropDown              matlab.ui.control.DropDown
        PopulationDropDownLabel         matlab.ui.control.Label
        DistributionTypesListBox        matlab.ui.control.ListBox
        DistributionTypesListBoxLabel   matlab.ui.control.Label
        FitAmpButton                    matlab.ui.control.Button
        OptionsButton_Optim             matlab.ui.control.Button
        SyntheticLengthOptimButtonGroup  matlab.ui.container.ButtonGroup
        secondsLabel                    matlab.ui.control.Label
        SynlenOptimEditField            matlab.ui.control.NumericEditField
        SpecifyButton_SynlenOptim       matlab.ui.control.RadioButton
        DefaultButton_SynlenOptim       matlab.ui.control.RadioButton
        SynthesizingParametersPanel     matlab.ui.container.Panel
        OptionsButton_SynSettings       matlab.ui.control.Button
        LoadRealDataButton              matlab.ui.control.Button
        RngSeedEditField                matlab.ui.control.NumericEditField
        RngSeedLabel                    matlab.ui.control.Label
        SamplingRateGroup               matlab.ui.container.ButtonGroup
        HzLabel_3                       matlab.ui.control.Label
        fsEditField                     matlab.ui.control.NumericEditField
        SpecifyButton_fs                matlab.ui.control.RadioButton
        DefaultButton_fs                matlab.ui.control.RadioButton
        CharacterizationDataPanel       matlab.ui.container.Panel
        EditField_SelectRealDataname    matlab.ui.control.EditField
        SelectRealDataButton            matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        Tab_Step3                       matlab.ui.container.Tab
        Button_Normalize                matlab.ui.control.StateButton
        ResultsTextArea_3Label          matlab.ui.control.Label
        ResultsTextArea_3               matlab.ui.control.TextArea
        DetectionPerformanceandROCcurvePanel  matlab.ui.container.Panel
        ProbabilityGivenDetection       matlab.ui.container.Panel
        EditField_PT                    matlab.ui.control.NumericEditField
        TruePPVLabel                    matlab.ui.control.Label
        EditField_PI                    matlab.ui.control.NumericEditField
        IntermediateLabel               matlab.ui.control.Label
        EditField_PF                    matlab.ui.control.NumericEditField
        FalseFDRLabel                   matlab.ui.control.Label
        DropDown_Condition              matlab.ui.control.DropDown
        ProbabilityGivenDetectionLabel  matlab.ui.control.Label
        DetectButton                    matlab.ui.control.Button
        ButtonGroup_NThresholds         matlab.ui.container.ButtonGroup
        NumberofThresholdsLabel         matlab.ui.control.Label
        EditField_NThresh               matlab.ui.control.NumericEditField
        SpecifyButton_NThresh           matlab.ui.control.RadioButton
        DefaultButton_NThresh           matlab.ui.control.RadioButton
        ButtonGroup_DetectOn            matlab.ui.container.ButtonGroup
        DetectonLabel                   matlab.ui.control.Label
        Detect_AS_Amp                   matlab.ui.control.RadioButton
        Detect_AS_AP                    matlab.ui.control.RadioButton
        FPRLabel                        matlab.ui.control.Label
        Slider_CFAR                     matlab.ui.control.Slider
        EditField_CFAR                  matlab.ui.control.NumericEditField
        FalsePositiveRateLabel          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        EditField_Threshold             matlab.ui.control.NumericEditField
        DetectionThresholdLabel         matlab.ui.control.Label
        Slider_Threshold                matlab.ui.control.Slider
        PreselectedBoundsPanel          matlab.ui.container.Panel
        OptionsButton_Detection         matlab.ui.control.Button
        PreboundButton                  matlab.ui.control.Button
        ButtonGroup_UpperBound_prop     matlab.ui.container.ButtonGroup
        TruepeakrateEditField_perc      matlab.ui.control.NumericEditField
        TruePeakRateEditField           matlab.ui.control.NumericEditField
        TruePeakRateEditFieldLabel      matlab.ui.control.Label
        EditField_TU                    matlab.ui.control.NumericEditField
        TimeULabel                      matlab.ui.control.Label
        ButtonGroup_LowerBound_prop     matlab.ui.container.ButtonGroup
        FalsepeakrateEditField_perc     matlab.ui.control.NumericEditField
        FalsePeakRateEditField          matlab.ui.control.NumericEditField
        FalsePeakRateEditFieldLabel     matlab.ui.control.Label
        EditField_TL                    matlab.ui.control.NumericEditField
        TimeLLabel                      matlab.ui.control.Label
        ButtonGroup_UpperBound          matlab.ui.container.ButtonGroup
        EditField_UpperBound_sig        matlab.ui.control.NumericEditField
        EditField_UpperBound            matlab.ui.control.NumericEditField
        UpperBoundULabel                matlab.ui.control.Label
        SpecifyButton_UpperBound        matlab.ui.control.RadioButton
        DefaultButton_UpperBound        matlab.ui.control.RadioButton
        ButtonGroup_LowerBound          matlab.ui.container.ButtonGroup
        EditField_LowerBound_sig        matlab.ui.control.NumericEditField
        EditField_LowerBound            matlab.ui.control.NumericEditField
        LowerBoundLLabel                matlab.ui.control.Label
        SpecifyButton_LowerBound        matlab.ui.control.RadioButton
        DefaultButton_LowerBound        matlab.ui.control.RadioButton
        SyntheticDataPanel              matlab.ui.container.Panel
        ReviewSettingButton             matlab.ui.control.Button
        LoadSynDataButton               matlab.ui.control.Button
        EditField_SelectSynDataname     matlab.ui.control.EditField
        SelectSynDataButton             matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % gui items
        menu_timer  % for variable menu update
        step1_stage = 0 % 0:start, 1:data ready, 2:PSD fit, 3:burst fit
        step2_stage = 0 % 0:start, 1:setup done, 2:Amp fit, 3:generate
        step3_stage = 0 % 0:start, 1:reiew, 2:data ready, 3:pre-bounds, 4:detection
        h_3         % handles for plot 3
        h_5         % handles for plot 5
        AmpOptimDone
        % parameters
        default_attr = cell(1,3)    % default value of attributes
        default = cell(1,3)     % default parameter values
        specified = cell(1,3)   % specified parameter values
        fs = 1000   % data sampling rate
        defaultZSthreshold = 2  % Z-score threshold for burst detection
        defaultFilterOrder = 6  % Butterworth filter order
        PSOparams   % PSO parameters
        AmpDistTypes = {'gamma','exponential','lognormal'};
        % data
        LFP_seg
        RealData
        BurstStats
        SynParam_h
        SynData
        CompositeTrace
        SynStats
        BoundStats
        Detection
        % file
        working_dir
        suffix = {'_Charac','_SynParam','_SynData'};
    end
    
    methods (Access = private)
        function update_menu(app,~,~)
            vars = evalin('base','whos');
            varnames = cell(size(vars));
            for i = 1:numel(varnames)
                varnames{i} = vars(i).name;
            end
            app.LFPDataDropDown.Items = ['__Select__';varnames(:)];
        end
        
        function clearallaxes(app,panel)
            switch panel
                case 'FitPSD'
                    ax = app.UIAxes_1;
                case 'FitBursts'
                    ax = [app.UIAxes2_1,app.UIAxes2_2,app.UIAxes2_3, ...
                        app.UIAxes2_4,app.UIAxes2_5,app.UIAxes2_6];
                case 'FitAmp'
                    ax = app.UIAxes3;
                case 'SynData'
                    ax = [app.UIAxes4,app.UIAxes4_2,app.UIAxes4_3,app.UIAxes4_4];
                case 'Detection'
                    ax = [app.UIAxes5,app.UIAxes6];
            end
            for i = 1:numel(ax)
                cla(ax(i));
            end
        end
        
        function append_textbox(~,textbox,message)
            if iscell(message)
                textbox.Value = [textbox.Value;message(:)];
            elseif size(message,1)>1
                message = mat2cell(message,ones(1,size(message,1)));
                textbox.Value = [textbox.Value;message];
            else
                textbox.Value{end+1} = message;
            end
            try
                scroll(textbox,'bottom');
            catch
            end
            drawnow;
        end
        
        function reset_attr(app,step)
            attrs = app.default_attr{step};
            for i = 1:numel(attrs)
                app.(attrs{i}) = [];
            end
        end
        
        function set_step1_stage(app,n)
            app.step1_stage = n;
            buttons = [app.FitPsdButton,app.FitBurstsButton, ...
                app.SaveCharacterizationDataButton];
            button_step = [1,2,3];
            for i = 1:numel(buttons)
                if n<button_step(i)
                    buttons(i).Enable = 'off';
                else
                    buttons(i).Enable = 'on';
                end
            end
            if n==0
                app.reset_attr(1);
                app.LFPDataDropDown.Value = app.LFPDataDropDown.Items{1};
            end
            if app.step2_stage>0
                app.set_step2_stage(0);
            end
        end
        
        function set_step2_stage(app,n)
            app.step2_stage = n;
            buttons = [app.FitAmpButton,app.SaveSyntheticButton, ...
                app.GenerateButton,app.AlsoSaveSyntheticLFPCheckBox];
            button_step = [1,2,2,3];
            for i = 1:numel(buttons)
                if n<button_step(i)
                    buttons(i).Enable = 'off';
                else
                    buttons(i).Enable = 'on';
                    app.Button_Normalize.Value = false;
                end
            end
            if n==0
                app.reset_attr(2);
            end
            if app.step3_stage>1
                app.set_step3_stage(0);
            end
            if n<2
                app.AmpOptimDone(:) = false;
                app.DistributionTypeDropDown.Items = {};
                app.clearallaxes('FitAmp');
                app.clearallaxes('SynData');
            end
        end
        
        function set_step3_stage(app,n)
            app.step3_stage = n;
            dct_bttns = get(app.DetectionPerformanceandROCcurvePanel,'Children');
            excld = [app.ProbabilityGivenDetection,app.DetectButton, ...
                app.ButtonGroup_NThresholds,app.ButtonGroup_DetectOn];
            idx = arrayfun(@(x) ismember(x,excld),dct_bttns);
            dct_bttns = [dct_bttns(~idx);get(excld(1),'Children')]';
            buttons = [app.PreboundButton,app.SpecifyButton_LowerBound, ...
                app.EditField_LowerBound,app.EditField_LowerBound_sig, ...
                app.SpecifyButton_UpperBound,app.EditField_UpperBound, ...
                app.EditField_UpperBound_sig,app.DetectButton, ...
                dct_bttns];
            button_step = [2*ones(1,7),3,4*ones(1,numel(dct_bttns))];
            for i = 1:numel(buttons)
                if n<button_step(i)
                    buttons(i).Enable = 'off';
                else
                    buttons(i).Enable = 'on';
                end
            end
            if n<4
                app.Button_Normalize.Visible = 'off';
                app.Button_Normalize.Enable = 'off';
            end
            if n==0
                app.reset_attr(3);
                app.clearallaxes('Detection');
            end
        end
        
        function create_PSO_paramters(app)
            params.nPop = 12;           % Population Size (Swarm Size)
            params.MaxIt = 30;          % Maximum Number of Iterations
            params.MinIt = 15;          % Minimum Number of Iterations
            params.MaxStall = 5;        % Terminate if change in best cost is ...
            params.FunTol = 1e-5;       % less than FunTol over MaxStall iterations
            params.withNeighbor = true; % Flag for using Neiborhood Operator
            params.NeighborSize = [.3,1];	% Neighborhood Size (<=1 fraction, >1 number)
            params.NeighborPeriod = 3;	% Number of iterations last for the same group of neighborhood
            params.w = 1;               % Intertia Coefficient
            params.wdamp = 0.92;        % Damping Ratio of Inertia Coefficient
            params.c1 = 2;              % Personal Acceleration Coefficient
            params.c2 = 2;              % Social Acceleration Coefficient
            params.InitVel = true;      % Random Non-Zero Initial Velocity
            params.randseed = 0;        % Random seed
            % PSO analysis parameters
            params.ShowIterInfo = false;    % Flag for Showing Iteration Informatin
            params.Visualize = false;
            app.PSOparams = params;
        end
        
        function Generate_Synthetic_Data(app,textbox)
            msgfcn = @(msg) app.append_textbox(textbox,msg);
            msgfcn(sprintf('\nGenerating synthetic LFP data...'));
            SynParam = app.SynParam_h;
            SynParam.syn_len = app.SynlenEditField.Value;
            SynParam.amp_dist_type = app.DistributionTypeDropDown.Value;
            cd(app.working_dir{2});
            SynParam.setup;
            cd(app.working_dir{1});
            app.SynData = gen_synthetic(SynParam);
            app.CompositeTrace = app.SynData.BackgroundTrace+app.SynData.SignalTrace;
            msgfcn('Synthetic LFP is generated.');
        end
        
        function SynP = PlotSynPSD(app)
            SynParam = app.SynParam_h;
            [~,~,Nfft,fit_i] = SynParam.psd_background;
            [pxx_syn,f] = pwelch(app.CompositeTrace,Nfft,[],Nfft,SynParam.fs);
            pxx_syn([1,end]) = pxx_syn([1,end])*2;
            [pxx_bg,f] = pwelch(app.SynData.BackgroundTrace,Nfft,[],Nfft,SynParam.fs);
            pxx_bg([1,end]) = pxx_bg([1,end])*2;
            
            ax = app.UIAxes4;   hold(ax,'on');
            plot(ax,SynParam.rdat.f,pow2db(SynParam.rdat.PSD),'g');
            plot(ax,f,pow2db(pxx_syn),'b');
            plot(ax,f,pow2db(pxx_bg),'r');
            xlim(ax,[0,min(SynParam.rdat.bump_f(2)*2,f(end))]);
            % xlim(ax,SynParam.rdat.f(SynParam.rdat.i_fit_range));
            ylim(ax,'auto');
            legend(ax,{'in vivo LFP','synthetic LFP','synthetic background'});
            xlabel(ax,'Frequency (Hz)');   ylabel(ax,'Power Density (dB/Hz)');
            drawnow;
            
            SynP = struct('fs',SynParam.fs,'nDB',SynParam.rdat.nDB,'bump_f',SynParam.rdat.bump_f, ...
                'T_length',SynParam.syn_len,'valid_seg_id',1,'f',f,'PSD_smoo',pxx_syn, ...
                'fit_i',fit_i,'fit_a',SynParam.rdat.fit_a,'fit_b',SynParam.rdat.fit_b);
        end
        
        function [SynResults,SynStats] = PlotBurstStats(app,SynP)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_2,msg);
            SynParam = app.SynParam_h;
            butter_order = SynParam.rdat.butter_order;
            threshold = SynParam.rdat.ZS_threshold;
            stop_perc = SynParam.rdat.stop_perc;
            [SynResults,SynStats] = getAllBurstAttr(SynP,{app.CompositeTrace}, ...
                butter_order,'threshold',threshold,'stop_perc',stop_perc, ...
                'display',1,'fit_ASamp',0,'msgfcn',msgfcn);
            Axes = [app.UIAxes4_2,app.UIAxes4_3,app.UIAxes4_4];
            SynResults = getBurstDistributions(SynResults,SynStats, ...
                'plot',1,'display',1,'axes',Axes,'msgfcn',msgfcn);
            drawnow;
        end
        
        function flag = LoadSynData(app)
            flag = true;
            if app.step3_stage>0
                msg = ['Data already loaded. Reloading will clear ', ...
                    'existing results. Do you want to continue?'];
                selection = uiconfirm(app.UIFigure,msg,'Confirm Selection');
                if isequal(selection,'Cancel'), return; end
            end
            
            filepath = app.EditField_SelectSynDataname.Value;
            if ~isfile(filepath)
                uialert(app.UIFigure,'File not found','Path Error');    return;
            end
            dname = fileparts(filepath);
            app.working_dir = {pwd,dname};
            
            SynFile = load(filepath);
            if ~isfield(SynFile,'SynParam') || ~isa(SynFile.SynParam,'SynthParam')
                uialert(app.UIFigure,'Selected file does not contain "SynthParam" class', ...
                    'File Error');  return;
            end
            try
                cd(app.working_dir{2});
                SynFile.SynParam.setup;
                cd(app.working_dir{1});
            catch
                uialert(app.UIFigure,'Characterization data missing','File Error');
                return;
            end
            
            app.set_step3_stage(0);
            app.SynParam_h = SynFile.SynParam;
            if isfield(SynFile,'SynData')
                app.SynData = SynFile.SynData;
            end
            flag = false;
        end
        
        function Thresh2FPR(app)
            FPR = interp1(app.Detection.thresholds,app.Detection.fpr, ...
                app.Slider_Threshold.Value,[],0);
            app.Slider_CFAR.Value = FPR/app.Detection.fpr(1)*100;
            if app.Detection.Detect_ASAP
                app.EditField_CFAR.Value = FPR;
            else
                app.EditField_CFAR.Value = app.Slider_CFAR.Value;
            end
        end
        
        function FRP2Thresh(app)
            fpr = [app.Detection.fpr,0];
            thresholds = [app.Detection.thresholds,app.Detection.max_amp];
            FPR = app.Slider_CFAR.Value/100*fpr(1);
            idx = find(diff(fpr)==0)+1;
            fpr(idx) = [];  thresholds(idx) = [];
            Threshold = interp1(fpr,thresholds,FPR);
            app.Slider_Threshold.Value = Threshold;
            app.EditField_Threshold.Value = (Threshold ...
                -app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
        end
        
        function Threshold_Updated(app)
            Threshold = app.Slider_Threshold.Value;
            % Display threshold results
            ax = [app.UIAxes5,app.UIAxes6];
            [~,app.h_5] = ROC_plot(app.Detection,Threshold,'axes',ax,'h',app.h_5);
            drawnow;
            app.append_textbox(app.ResultsTextArea_3,num2str([Threshold, ...
                app.Slider_CFAR.Value],['Detection threshold: %.4g ', ...
                char(956),'V, False positive rate: %.4g %%']));
            % Conditional probability
           app.CondProb_Update; 
        end
        
        function CondProb_Update(app)
            thresholds = app.Detection.ctrs_thr0;
            if isequal(app.DropDown_Condition.Value,app.DropDown_Condition.Items{1})
                CProb = app.Detection.CProbGt;
            else
                CProb = app.Detection.CProbEq;
            end
            prob = 100*interp1(thresholds,CProb',app.Slider_Threshold.Value);
            app.EditField_PF.Value = prob(1);
            app.EditField_PI.Value = prob(2);
            app.EditField_PT.Value = prob(3);
        end
    end %private end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.default_attr = {{'LFP_seg','RealData','BurstStats'}, ...
                {'SynParam_h','SynData','CompositeTrace'}, ...
                {'SynData','SynStats','BoundStats','Detection'}};
            
            app.data_fs.Value = num2str(app.fs);
            app.fsEditField.Value = app.fs;
            
            app.DistributionTypesListBox.Items = app.AmpDistTypes;
            app.DistributionTypesListBox.Value = app.AmpDistTypes(1);
            app.PopulationDropDown.Items = app.AmpDistTypes(1);
            nPops = {12,8,12};  % PSO population corresponding to AmpDistTypes
            app.nPopEditField.Value = nPops{1};
            app.h_3 = struct('real',0,'syn',zeros(size(app.AmpDistTypes)));
            app.AmpOptimDone = false(size(app.AmpDistTypes));
            
            app.default = struct( ...
                'nDB',1., ...           % threshold for fitting PSD
                'NBins',30, ...         % number of histogram bins
                'fs',1000, ...          % sampling rate of synthetic data
                'SynlenOptim',1000, ... % synthetic length for optimization
                'Synlen',5000, ...      % synthetic length for generation
                ... % default population for distribution types
                'nPop',cell2struct(nPops,app.AmpDistTypes,2), ...
                'LowerBound_sig',2^.5, ... % preselected lower bound
                'UpperBound_sig',2^.5, ... % preselected upper bound
                'NThresh',50 ...
                );
            app.specified = app.default;
            app.nDBEditField.Value = app.default.nDB;
            app.NBinsEditField.Value = app.default.NBins;
            app.fsEditField.Value = app.default.fs;
            app.SynlenOptimEditField.Value = app.default.SynlenOptim;
            app.SynlenEditField.Value = app.default.Synlen;
            app.EditField_LowerBound_sig.Value = app.default.LowerBound_sig;
            app.EditField_UpperBound_sig.Value = app.default.UpperBound_sig;
            app.EditField_NThresh.Value = app.default.NThresh;
            
            app.create_PSO_paramters;
            
            app.menu_timer = timer('ExecutionMode','fixedSpacing',...
                'Period',5,'TimerFcn',@app.update_menu);
            start(app.menu_timer);
        end

        % Value changed function: LFPDataDropDown
        function LFPDataDropDownValueChanged(app, event)
            try
                value = evalin('base',event.Value);
                flag = ~isempty(value);
            catch
                flag = false;
            end
            if flag
                if ~iscell(value)
                    value = {value};
                end
                for i = 1:numel(value)
                    if ~isvector(value{i}) || ~isnumeric(value{i})
                        uialert(app.UIFigure,['Expect input data to be a cell vector, ' ...
                            'each element in which is a numeric vector.'],'Value Error');
                        flag = false;   break;
                    end
                    if ~isreal(value{i})
                        uialert(app.UIFigure,'Expect input data to be real valued.'),'Value Error';
                        flag = false;   break;
                    end
                end
            end
            if flag
                if isequal(value,app.LFP_seg)
                    return;
                end
                if app.step1_stage>0
                    msg = {'Change of input data will clear existing results.', ...
                        'Do you want to continue with the new data?'};
                    selection = uiconfirm(app.UIFigure,msg,'Confirm Selection');
                    flag = isequal(selection,'OK');
                end
            end
            if flag
                app.LFP_seg = value;
                app.EditField_Dataname.Value = [event.Value,'_Charac'];
                app.set_step1_stage(1);
            else
                app.LFPDataDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: data_fs
        function data_fsValueChanged(app, event)
            try
                value = evalin('base',event.Value);
                flag = isnumeric(value)&&isscalar(value)&&isreal(value)&&value>0;
            catch
                flag = false;
            end
            if flag
                app.fs = value;
            else
                app.data_fs.Value = event.PreviousValue;
                uialert(app.UIFigure,'Sampling Rate should be positive value','Value Error');
            end
        end

        % Selection changed function: OutlierThresholdButtonGroup
        function OutlierThresholdButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_nDB.Value
                app.nDBEditField.Value = app.default.nDB;
            else
                app.nDBEditField.Value = app.specified.nDB;
            end
        end

        % Value changed function: nDBEditField
        function nDBEditFieldValueChanged(app, event)
            app.specified.nDB = app.nDBEditField.Value;
            app.SpecifyButton_nDB.Value = true;
        end

        % Button pushed function: FitPsdButton
        function FitPsdButtonPushed(app, event)
            % Setup
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea,msg);
            msgfcn(sprintf('\nRunning fit PSD...'));
            app.clearallaxes('FitPSD');
            freq_range = [app.FreqRangeEditField1.Value,app.FreqRangeEditField2.Value];
            outlier_threshold = app.nDBEditField.Value;
            if freq_range(1)>freq_range(2)
                uialert(app.UIFigure,'Oscillation frequency range should be an interval.', ...
                    'Value Error'); return;
            end
            if app.AutofitButton.Value
                autofit = true; fit_range = [0,0];
            else
                autofit = false;
                fit_range = [app.FitRangeEditField1.Value,app.FitRangeEditField2.Value];
                if fit_range(1)>freq_range(1) || fit_range(2)<freq_range(2)
                    uialert(app.UIFigure,['It is suggested that the frequency range for ' ...
                        'fitting contains the oscillation frequency range.'], ...
                        'Value Warning','Icon','warning');
                end
            end
            % Run
            [app.RealData,app.LFP_seg,flag] = getPSDcomponents(app.LFP_seg, ...
                app.fs,freq_range,fit_range,'nDB',outlier_threshold, ...
                'autofit',autofit,'plot',1,'display',1,'axes',app.UIAxes_1,'msgfcn',msgfcn);
            % Result
            switch flag
                case -2
                    uialert(app.UIFigure,{'Input LFP data is too short.' ...
                        'Segment durations are expected to be longer.'},'Input Error');
                    app.set_step1_stage(0);
                case -1
                    uialert(app.UIFigure,{'Frequency range for fitting is too narrow.', ...
                        'Select wider range.'},'Value Error');
                    app.set_step1_stage(1);
                case -3
                    uialert(app.UIFigure,{'Signal occurs on the edge of fit range.', ...
                        'Suggestion: Adjust the outlier threshold.'}, ...
                        'Failure','Icon','warning');
                    app.set_step1_stage(1);
                case 0
                    uialert(app.UIFigure,{['No significant signal found within ' ...
                        'the frequency range of interest.'], ...
                        'Suggestion: Adjust the outlier threshold.'}, ...
                        'Failure','Icon','warning');
                    app.set_step1_stage(1);
                case 1
                    uialert(app.UIFigure,'Fitting complete.', ...
                        'Success','Icon','success');
                    app.set_step1_stage(2);
            end
        end

        % Selection changed function: NBinsButtonGroup
        function NBinsButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_NBins.Value
                app.NBinsEditField.Value = app.default.NBins;
            else
                app.NBinsEditField.Value = app.specified.NBins;
            end
        end

        % Value changed function: NBinsEditField
        function NBinsEditFieldValueChanged(app, event)
            app.specified.NBins = app.NBinsEditField.Value;
            app.SpecifyButton_NBins.Value = true;
        end

        % Button pushed function: FitBurstsButton
        function FitBurstsButtonPushed(app, event)
            % Setup
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea,msg);
            msgfcn(sprintf('\nCharacterizing bursts...'));
            app.clearallaxes('FitBursts');
            filt_order = app.defaultFilterOrder;
            ZS_threshold = app.defaultZSthreshold;
            nbins = app.NBinsEditField.Value;
            Axes = [app.UIAxes2_1,app.UIAxes2_2,app.UIAxes2_3, ...
                app.UIAxes2_4,app.UIAxes2_5,app.UIAxes2_6];
            % Run
            [app.RealData,app.BurstStats] = getAllBurstAttr(app.RealData, ...
                app.LFP_seg,filt_order,ZS_threshold,'fit_ASamp_gamma',false, ...
                'display',1,'msgfcn',msgfcn);
            msgfcn(sprintf('Fitting bursts distribution...'));
            app.RealData = getBurstDistributions(app.RealData,app.BurstStats, ...
                'nbins',nbins,'plot',1,'plot_logscale',1,'display',1, ...
                'axes',Axes,'msgfcn',msgfcn);
            msgfcn('Bursts characterization done.');
            app.set_step1_stage(3);
        end

        % Selection change function: Tab_linear
        function Tab_linearSelectionChanged(app, event)
            selectedTab = app.Tab_linear.SelectedTab.Title;
            switch selectedTab
                case 'AP'
                    app.Tab_log.SelectedTab = app.APTab_2;
                case 'CN'
                    app.Tab_log.SelectedTab = app.CNTab_2;
                case 'BF'
                    app.Tab_log.SelectedTab = app.BFTab_2;
            end
        end

        % Selection change function: Tab_log
        function Tab_logSelectionChanged(app, event)
            selectedTab = app.Tab_log.SelectedTab.Title;
            switch selectedTab
                case 'AP'
                    app.Tab_linear.SelectedTab = app.APTab;
                case 'CN'
                    app.Tab_linear.SelectedTab = app.CNTab;
                case 'BF'
                    app.Tab_linear.SelectedTab = app.BFTab;
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            dname = uigetdir(pwd,'Select Project Folder');
            if ~isequal(dname,0)
                app.SelectedDirectoryEditField.Value = dname;
            end
        end

        % Value changed function: SelectedDirectoryEditField
        function SelectedDirectoryEditFieldValueChanged(app, event)
            dname = app.SelectedDirectoryEditField.Value;
            if ~isfolder(dname)
                app.SelectedDirectoryEditField.Value = event.PreviousValue;
                uialert(app.UIFigure,'Directory not found','Path Error');
            end
        end

        % Button pushed function: SaveCharacterizationDataButton
        function SaveCharacterizationDataButtonPushed(app, event)
            dname = app.SelectedDirectoryEditField.Value;
            if ~isfolder(dname)
                dname = pwd;
            end
            [~,fname] = fileparts(app.EditField_Dataname.Value);
            filepath = fullfile(dname,fname);
            [fname,dname] = uiputfile('*.mat','Save Results',filepath);
            if isequal(fname,0)
                return;
            else
                filepath = fullfile(dname,fname);
            end
            % Save data
            RealData = app.RealData;
            try
                save(filepath,'-struct','RealData');
                flag = isfile(filepath);
            catch
                flag = false;
            end
            if flag
                app.EditField_Dataname.Value = fname;
                app.SelectedDirectoryEditField.Value = dname;
                app.EditField_SelectRealDataname.Value = filepath;
                app.append_textbox(app.ResultsTextArea, ...
                    ['Results saved in "',filepath,'".']);
                uialert(app.UIFigure,'Result file saved successfully.', ...
                    'Success','Icon','success');
            else
                uialert(app.UIFigure,'Result file not saved.','Failure');
            end
        end

        % Button pushed function: SelectRealDataButton
        function SelectRealDataButtonPushed(app, event)
            filepath = app.EditField_SelectRealDataname.Value;
            [fname,dname] = uigetfile('*.mat','Select Data File',filepath);
            if ~isequal(fname,0)
                filepath = fullfile(dname,fname);
                app.EditField_SelectRealDataname.Value = filepath;
                app.SelectedDirectoryEditField.Value = dname;
            end
        end

        % Value changed function: EditField_SelectRealDataname
        function EditField_SelectRealDatanameValueChanged(app, event)
            filepath = app.EditField_SelectRealDataname.Value;
            if isfile(filepath)
                app.SelectedDirectoryEditField.Value = fileparts(filepath);
            else
                app.EditField_SelectRealDataname.Value = event.PreviousValue;
                uialert(app.UIFigure,'File not found','Path Error');
            end
        end

        % Selection changed function: SamplingRateGroup
        function SamplingRateGroupSelectionChanged(app, event)
            if app.DefaultButton_fs.Value
                app.fsEditField.Value = app.default.fs;
            else
                app.fsEditField.Value = app.specified.fs;
            end
        end

        % Value changed function: fsEditField
        function fsEditFieldValueChanged(app, event)
            app.specified.fs = app.fsEditField.Value;
            app.SpecifyButton_fs.Value = true;
        end

        % Button pushed function: LoadRealDataButton
        function LoadRealDataButtonPushed(app, event)
            if app.step2_stage>0
                msg = ['Data already loaded. Reloading will clear ', ...
                    'existing results. Do you want to continue?'];
                selection = uiconfirm(app.UIFigure,msg,'Confirm Selection');
                if isequal(selection,'Cancel'), return; end
                app.set_step2_stage(0);
            end
            
            filepath = app.EditField_SelectRealDataname.Value;
            if ~isfile(filepath)
                uialert(app.UIFigure,'File not found','Path Error');    return;
            end
            [dname,name,ext] = fileparts(filepath);
            fname = [name,ext];
            app.working_dir = {pwd,dname};
            
            % Setup
            SynParam = SynthParam;
            SynParam.filepath = fname;
            SynParam.randseed = app.RngSeedEditField.Value;
            cd(app.working_dir{2});
            try
                SynParam.setup;
            catch
                uialert(app.UIFigure,'Selected file does not contain required data','File Error');
                cd(app.working_dir{1}); return;
            end
            cd(app.working_dir{1});
            app.default.fs = SynParam.rdat.fs;
            if app.DefaultButton_fs.Value
                SynParam.fs = app.default.fs;
                app.fsEditField.Value = app.default.fs;
            else
                SynParam.fs = app.fsEditField.Value;
            end
            [~,flag] = SynParam.setup;
            if flag
                app.SynParam_h = SynParam;
                app.append_textbox(app.ResultsTextArea_2,'Data loaded successfully.');
                app.set_step2_stage(1);
            end
        end

        % Selection changed function: 
        % SyntheticLengthOptimButtonGroup
        function SyntheticLengthOptimButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_SynlenOptim.Value
                app.SynlenOptimEditField.Value = app.default.SynlenOptim;
            else
                app.SynlenOptimEditField.Value = app.specified.SynlenOptim;
            end
        end

        % Value changed function: SynlenOptimEditField
        function SynlenOptimEditFieldValueChanged(app, event)
            app.specified.SynlenOptim = app.SynlenOptimEditField.Value;
            app.SpecifyButton_SynlenOptim.Value = true;
        end

        % Value changed function: DistributionTypesListBox
        function DistributionTypesListBoxValueChanged(app, event)
            Value = event.Value;
            if isempty(Value)
                Value = event.PreviousValue;
                if isempty(Value)
                    Value = app.DistributionTypesListBox.items;
                end
                app.DistributionTypesListBox.Value = Value(1);
            end
            app.PopulationDropDown.Items = Value;
            app.PopulationDropDown.Value = Value{1};
            app.nPopEditField.Value = app.specified.nPop.(Value{1});
        end

        % Value changed function: PopulationDropDown
        function PopulationDropDownValueChanged(app, event)
            disttype = app.PopulationDropDown.Value;
            app.nPopEditField.Value = app.specified.nPop.(disttype);
        end

        % Value changed function: nPopEditField
        function nPopEditFieldValueChanged(app, event)
            disttype = app.PopulationDropDown.Value;
            app.specified.nPop.(disttype) = app.nPopEditField.Value;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            disttype = app.PopulationDropDown.Value;
            nPop = app.default.nPop.(disttype);
            app.nPopEditField.Value = nPop;
            app.specified.nPop.(disttype) = nPop;
        end

        % Button pushed function: FitAmpButton
        function FitAmpButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_2,msg);
            % Setup
            msgfcn(sprintf('\nOptimizing amplitude distribution...'));
            SynParam = app.SynParam_h;
            % SynParam.mem_presever = 1e9;
            SynParam.syn_len = app.SynlenOptimEditField.Value;
            SynParam.empr_CN = true;
            SynParam.empr_BF = true;
            cd(app.working_dir{2});
            SynParam.setup;
            cd(app.working_dir{1});
            SynParam.gen_background;
            
            % Cases
            fit_ASAP = app.Fit_AS_AP.Value;
            disttypes = app.DistributionTypesListBox.Value;
            div_type = 'KL';
            for i = 1:numel(disttypes)
                disttype = disttypes{i};
                msgfcn(sprintf(['\nOptimizing with ',disttype,' distribution...']));
                SynParam.amp_dist_type = disttype;
                app.PSOparams.nPop = app.specified.nPop.(disttype);
                out = runAmpDistOptim(SynParam,app.PSOparams,div_type,fit_ASAP);
                % Plot
                idx = find(strcmp(disttype,app.AmpDistTypes));
                app.AmpOptimDone(idx) = true;
                [~,app.h_3.real,app.h_3.syn(idx)] = SynParam.plot_eval_dist(disttype, ...
                    [],app.UIAxes3,app.h_3.real,app.h_3.syn(idx));
                legend(app.UIAxes3,[app.h_3.real,app.h_3.syn(app.AmpOptimDone)], ...
                    ['Real',app.AmpDistTypes(app.AmpOptimDone)],'Location',"northeast");
                drawnow;
                % Results
                msgfcn(num2str(out.FinalIteration,'Done after %d iterations.'));
                switch out.exitflag
                    case 1
                        msgfcn('Maximum number of stall iterations reached.');
                    case -1
                        msgfcn('Maximum number of iterations reached.');
                end
                msgfcn(num2str(SynParam.div_value.(disttype), ...
                    ['Population ',div_type,' divergence = %f.']));
                msgfcn(['Optimized parameters: ', ...
                    num2str(SynParam.amp_param.(disttype),'%.2f '),'.']);
            end
            app.DistributionTypeDropDown.Items = app.AmpDistTypes(app.AmpOptimDone);
            app.DistributionTypeDropDown.Value = SynParam.best_amp_dist_type;
            SynParam.amp_dist_type = app.DistributionTypeDropDown.Value;
            SynParam.syn_len = app.SynlenEditField.Value;
            
            uialert(app.UIFigure,['Parameter optimization for selected' ...
               ' distribution types all done.'],'Success','Icon','success');
            app.set_step2_stage(2);
        end

        % Selection changed function: SyntheticLengthButtonGroup
        function SyntheticLengthButtonGroupSelectionChanged(app, event)
            if app.DefaultButton_Synlen.Value
                app.SynlenEditField.Value = app.default.Synlen;
            else
                app.SynlenEditField.Value = app.specified.Synlen;
            end
        end

        % Value changed function: SynlenEditField
        function SynlenEditFieldValueChanged(app, event)
            app.specified.Synlen = app.SynlenEditField.Value;
            app.SpecifyButton_Synlen.Value = true;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_2,msg);
            app.set_step2_stage(2);
            app.Generate_Synthetic_Data(app.ResultsTextArea_2);
            msgfcn('Characterizing synthetic LFP data...');
            app.clearallaxes('SynData');
            SynP = app.PlotSynPSD;
            app.PlotBurstStats(SynP);   % => [SynResults,SynStats]
            
            uialert(app.UIFigure,'Generating and characterizing synthetic LFP complete.', ...
                'Success','Icon','success');
            app.set_step2_stage(3);
        end

        % Button pushed function: SaveSyntheticButton
        function SaveSyntheticButtonPushed(app, event)
            dname = app.working_dir{2};
            if ~isfolder(dname)
                dname = pwd;
            end
            if app.AlsoSaveSyntheticLFPCheckBox.Value
                suf = app.suffix{3};
            else
                suf = app.suffix{2};
            end
            SynParam = app.SynParam_h;
            [~,name0] = fileparts(SynParam.filepath);
            if contains(name0,app.suffix{1})
                name = strrep(name0,app.suffix{1},suf);
                name0 = strrep(name0,app.suffix{1},'');
            else
                name = [name0,suf];
            end
            [~,casename,ext] = fileparts(strrep( ...
                app.EditField_SynParamDataname.Value,'Case name',''));
            for i = 1:3
                if contains(casename,app.suffix{i})
                    casename = strrep(casename,app.suffix{i},suf);
                    name = name0;
                end
            end
            if contains(casename,name0)
                casename = strrep(casename,name0,name);
            else
                casename = [name,'_',casename];
            end
            filepath = fullfile(dname,[casename,ext]);
            [fname,dname] = uiputfile('*.mat','Save Results',filepath);
            if isequal(fname,0)
                return;
            else
                filepath = fullfile(dname,fname);
            end
            % Save data
            Data = struct('SynParam',SynParam);
            if app.AlsoSaveSyntheticLFPCheckBox.Value
                Data.SynData = app.SynData;
            end
            try
                save(filepath,'-struct','Data');
                flag = isfile(filepath);
            catch
                flag = false;
            end
            if flag
                [dname,name] = fileparts(filepath);
                app.EditField_SynParamDataname.Value = name;
                app.SelectedDirectoryEditField.Value = dname;
                app.EditField_SelectSynDataname.Value = filepath;
                app.append_textbox(app.ResultsTextArea_2, ...
                    sprintf(['\nResults saved in "',filepath,'".']));
                uialert(app.UIFigure,'Result file saved successfully.', ...
                    'Success','Icon','success');
            else
                uialert(app.UIFigure,'Result file not saved.','Failure');
            end
        end

        % Button pushed function: SelectSynDataButton
        function SelectSynDataButtonPushed(app, event)
            filepath = app.EditField_SelectSynDataname.Value;
            [fname,dname] = uigetfile('*.mat','Select Data File',filepath);
            if ~isequal(fname,0)
                filepath = fullfile(dname,fname);
                app.EditField_SelectSynDataname.Value = filepath;
                app.SelectedDirectoryEditField.Value = dname;
            end
        end

        % Value changed function: EditField_SelectSynDataname
        function EditField_SelectSynDatanameValueChanged(app, event)
            filepath = app.EditField_SelectSynDataname.Value;
            if isfile(filepath)
                app.SelectedDirectoryEditField.Value = fileparts(filepath);
            else
                app.EditField_SelectSynDataname.Value = event.PreviousValue;
                uialert(app.UIFigure,'File not found','Path Error');
            end
        end

        % Button pushed function: ReviewSettingButton
        function ReviewSettingButtonPushed(app, event)
            if app.LoadSynData, return; end
            app.TabGroup.SelectedTab = app.Tab_Step2;
            % Setup
            SynParam = app.SynParam_h;
            app.EditField_SelectRealDataname.Value = fullfile( ...
                app.working_dir{2},SynParam.filepath);
            app.RngSeedEditField.Value = SynParam.randseed;
            app.default.fs = SynParam.rdat.fs;
            app.fsEditField.Value = SynParam.fs;
            app.DefaultButton_fs.Value = SynParam.fs==app.default.fs;
            if app.SpecifyButton_fs.Value
                app.specified.fs = SynParam.fs;
            end
            app.set_step2_stage(1);
            % Optimization parameters
            if isfield(SynParam.eval_param,'fit_ASAP')
                app.Fit_AS_AP.Value = SynParam.eval_param.fit_ASAP;
            end
            for i = 1:numel(app.AmpDistTypes)
                app.AmpOptimDone(i) = ~isempty(SynParam.amp_param.(app.AmpDistTypes{i}));
            end
            if ~any(app.AmpOptimDone)
                uialert(app.UIFigure,'Amplitude distribution optimization has not been done yet', ...
                    'Data Error');  return;
            end
            TypesDone = app.AmpDistTypes(app.AmpOptimDone);
            app.DistributionTypesListBox.Value = TypesDone;
            app.PopulationDropDown.Items = TypesDone;
            app.PopulationDropDown.Value = TypesDone{1};
            app.nPopEditField.Value = app.specified.nPop.(TypesDone{1});
            for i = find(app.AmpOptimDone)
                [~,app.h_3.real,app.h_3.syn(i)] = SynParam.plot_eval_dist( ...
                    app.AmpDistTypes{i},[],app.UIAxes3,app.h_3.real,app.h_3.syn(i));
            end
            legend(app.UIAxes3,[app.h_3.real,app.h_3.syn(app.AmpOptimDone)], ...
                ['Real',app.AmpDistTypes(app.AmpOptimDone)],'Location',"northeast");
            drawnow;
            app.set_step2_stage(2);
            % Generation parameters
            app.DistributionTypeDropDown.Items = TypesDone;
            app.DistributionTypeDropDown.Value = SynParam.amp_dist_type;
            app.SynlenEditField.Value = SynParam.syn_len;
            app.DefaultButton_Synlen.Value = SynParam.syn_len==app.default.Synlen;
            if ~app.DefaultButton_Synlen.Value
                app.specified.Synlen = SynParam.syn_len;
            end
            if ~isempty(app.SynData)
                app.CompositeTrace = app.SynData.BackgroundTrace+app.SynData.SignalTrace;
                app.set_step2_stage(3);
            end
            
            app.append_textbox(app.ResultsTextArea_2, ...
                sprintf('\nData loaded successfully.'));
            app.set_step3_stage(1);
        end

        % Button pushed function: LoadSynDataButton
        function LoadSynDataButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_3,msg);
            % Load synthetic data
            if app.step3_stage~=1
                if app.LoadSynData
                    return;
                end
                SynParam = app.SynParam_h;
                if all(cellfun(@isempty,struct2cell(SynParam.amp_param)))
                    uialert(app.UIFigure,'Amplitude distribution optimization has not been done yet', ...
                        'Data Error');  return;
                end
                msgfcn(sprintf('\nData loaded successfully.'));
            else
                SynParam = app.SynParam_h;
            end
            if isempty(app.SynData)
                msgfcn(sprintf('\nGenerating synthetic LFP data...'));
                app.SynData = gen_synthetic(SynParam);
            end
            msgfcn(['Amplitude distribution type being used: ',SynParam.amp_dist_type]);
            msgfcn(num2str(SynParam.syn_len,'Synthetic LFP length: %.1f seconds'));
            msgfcn('Synthetic LFP is generated.');
            
            % Primary process
            SynStats = getSyntheticStats(SynParam,app.SynData);
            BoundStats = getPreselectedBounds(SynParam,SynStats);
            app.SynStats = SynStats;
            app.BoundStats = BoundStats;
            
            % Setup parameters
            app.Slider_Threshold.Limits = [0,SynStats.max_amp(3)];
            app.EditField_Threshold.Limits = ([0,SynStats.max_amp(3)] ...
                -SynStats.amp_mean(3))/SynStats.amp_sigma(3);
            app.Slider_Threshold.Value = SynStats.amp_mean(3)+ ...
                app.EditField_Threshold.Value*SynStats.amp_sigma(3);
            app.default.LowerBound_sig = BoundStats.nsigma_bound(1);
            app.default.UpperBound_sig = BoundStats.auto_thetaU/SynStats.sigma_syn(1);
            app.specified.LowerBound_sig = app.default.LowerBound_sig;
            app.specified.UpperBound_sig = app.default.UpperBound_sig;
            app.DefaultButton_LowerBound.Value = true;
            app.DefaultButton_UpperBound.Value = true;
            app.EditField_LowerBound.Value = BoundStats.pre_bound(1);
            app.EditField_UpperBound.Value = BoundStats.auto_thetaU;
            app.EditField_LowerBound_sig.Value = app.default.LowerBound_sig;
            app.EditField_UpperBound_sig.Value = app.default.UpperBound_sig;
            
            app.set_step3_stage(2);
        end

        % Selection changed function: ButtonGroup_LowerBound
        function ButtonGroup_LowerBoundSelectionChanged(app, event)
            if app.DefaultButton_LowerBound.Value
                app.EditField_LowerBound_sig.Value = app.default.LowerBound_sig;
            else
                app.EditField_LowerBound_sig.Value = app.specified.LowerBound_sig;
            end
            app.EditField_LowerBound.Value = app.EditField_LowerBound_sig.Value ...
                *app.SynStats.sigma_syn(1);
        end

        % Value changed function: EditField_LowerBound
        function EditField_LowerBoundValueChanged(app, event)
            app.SpecifyButton_LowerBound.Value = true;
            app.specified.LowerBound_sig = app.EditField_LowerBound.Value ...
                /app.SynStats.sigma_syn(1);
            app.EditField_LowerBound_sig.Value = app.specified.LowerBound_sig;
        end

        % Value changed function: EditField_LowerBound_sig
        function EditField_LowerBound_sigValueChanged(app, event)
            app.SpecifyButton_LowerBound.Value = true;
            app.specified.LowerBound_sig = app.EditField_LowerBound_sig.Value;
            app.EditField_LowerBound.Value = app.specified.LowerBound_sig ...
                *app.SynStats.sigma_syn(1);
        end

        % Selection changed function: ButtonGroup_UpperBound
        function ButtonGroup_UpperBoundSelectionChanged(app, event)
            if app.DefaultButton_UpperBound.Value
                app.EditField_UpperBound_sig.Value = app.default.UpperBound_sig;
            else
                app.EditField_UpperBound_sig.Value = app.specified.UpperBound_sig;
            end
            app.EditField_UpperBound.Value = app.EditField_UpperBound_sig.Value ...
                *app.SynStats.sigma_syn(1);
        end

        % Value changed function: EditField_UpperBound
        function EditField_UpperBoundValueChanged(app, event)
            app.SpecifyButton_UpperBound.Value = true;
            app.specified.UpperBound_sig = app.EditField_UpperBound.Value ...
                /app.SynStats.sigma_syn(1);
            app.EditField_UpperBound_sig.Value = app.specified.UpperBound_sig;
        end

        % Value changed function: EditField_UpperBound_sig
        function EditField_UpperBound_sigValueChanged(app, event)
            app.SpecifyButton_UpperBound.Value = true;
            app.specified.UpperBound_sig = app.EditField_UpperBound_sig.Value;
            app.EditField_UpperBound.Value = app.specified.UpperBound_sig ...
                * app.SynStats.sigma_syn(1);
        end

        % Button pushed function: PreboundButton
        function PreboundButtonPushed(app, event)
            msgfcn = @(msg) app.append_textbox(app.ResultsTextArea_3,msg);
            SynStats = app.SynStats;
            BoundStats = getPreselectedBounds(app.SynParam_h,SynStats, ...
                app.EditField_LowerBound_sig.Value,app.EditField_UpperBound_sig.Value, ...
                app.BoundStats.auto_thetaU);
            if any(BoundStats.ASAPrate_fit([1,3])==0)
                Case = {'false','true'};
                idx = find(BoundStats.ASAPrate_fit([1,3])==0,1);
                uialert(app.UIFigure,['No ',Case{idx},' peak found with the selected bounds.'], ...
                    'Value Error'); return;
            end
            msgfcn(sprintf('\nPreselected bounds are calculated.'));
            if app.EditField_UpperBound_sig.Value~=BoundStats.nsigma_bound(2)
                msgfcn(num2str([app.EditField_UpperBound_sig.Value, ...
                    BoundStats.nsigma_bound(2)],['Upper bound is changed', ...
                    'from %.3g ',char(952),' to %.3g ',char(952)]));
                app.EditField_UpperBound_sig.Value = BoundStats.nsigma_bound(2);
                app.specified.UpperBound_sig = BoundStats.nsigma_bound(2);
                app.EditField_UpperBound.Value = BoundStats.pre_bound(2);
                uialert(app.UIFigure,{'Upper bound is smaller than the lower bound,', ...
                    'is forced to equal the lower bound.'},'Value Changed','Icon','info');
            end
            app.EditField_TL.Value = BoundStats.dur_prop(1);
            app.EditField_TU.Value = BoundStats.dur_prop(3);
            app.FalsePeakRateEditField.Value = BoundStats.ASAPrate_fit(1);
            app.TruePeakRateEditField.Value = BoundStats.ASAPrate_fit(3);
            app.FalsepeakrateEditField_perc.Value = BoundStats.ASAPrate_fit(1)...
                /SynStats.pkrate(3)*100;
            app.TruepeakrateEditField_perc.Value = BoundStats.ASAPrate_fit(3)...
                /SynStats.pkrate(3)*100;
            
            app.BoundStats = BoundStats;
            app.set_step3_stage(3);
        end

        % Selection changed function: ButtonGroup_NThresholds
        function ButtonGroup_NThresholdsSelectionChanged(app, event)
            if app.DefaultButton_NThresh.Value
                app.EditField_NThresh.Value = app.default.NThresh;
            else
                app.EditField_NThresh.Value = app.specified.NThresh;
            end
        end

        % Value changed function: EditField_NThresh
        function EditField_NThreshValueChanged(app, event)
            app.specified.NThresh = app.EditField_NThresh.Value;
            app.SpecifyButton_NThresh.Value = true;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            Detect_AS_AP = app.Detect_AS_AP.Value;
            nthresh = app.EditField_NThresh.Value;
            app.Detection = getDetection(app.SynStats,app.BoundStats, ...
                'nthresh',nthresh,'Detect_ASAP',Detect_AS_AP);
            ax = [app.UIAxes5,app.UIAxes6];
            [~,app.h_5] = ROC_plot(app.Detection,'axes',ax,'h',app.h_5);
            
            if Detect_AS_AP
                app.Button_Normalize.Visible = 'on';
                app.Button_Normalize.Enable = 'on';
                app.EditField_CFAR.ValueDisplayFormat = '%.3g Hz';
                app.EditField_CFAR.Limits = [0,app.Detection.fpr(1)];
                msg = sprintf('\nDetecting on analytic signal (AS) amplitude peaks.');
            else
                app.Button_Normalize.Visible = 'off';
                app.Button_Normalize.Enable = 'off';
                app.EditField_CFAR.ValueDisplayFormat = '%.3g %%';
                app.EditField_CFAR.Limits = [0,100];
                msg = sprintf('\nDetecting on analytic signal (AS) amplitudes.');
            end
            app.Slider_Threshold.Value = app.Slider_Threshold.Limits(2);
            app.EditField_Threshold.Value = app.EditField_Threshold.Limits(2);
            app.Thresh2FPR;
            app.CondProb_Update;
            
            app.append_textbox(app.ResultsTextArea_3,msg);
            app.set_step3_stage(4);
        end

        % Value changed function: Button_Normalize
        function Button_NormalizeValueChanged(app, event)
            ax = app.UIAxes5;
            if app.Button_Normalize.Value
                % Normalized scale
                xl = 100*app.Detection.tpr(1)/app.Detection.fpr(1);
                xlim(ax,[0,xl]);
                xlabel(ax,'FP rate(Hz)/True peak rate(Hz)');
                xt = 0:0.2:1;
                xtl = cellfun(@num2str,num2cell(xt'),'UniformOutput',false);
                xticks(ax,xt*xl);
                xticklabels(ax,xtl);
            else
                xlim(ax,[0,100]);
                xlabel(ax,'False positive rate (%)');
                xticks(ax,'auto');
                xticklabels(ax,'auto');
            end
        end

        % Value changed function: EditField_Threshold
        function EditField_ThresholdValueChanged(app, event)
            app.Slider_Threshold.Value = app.SynStats.amp_mean(3)+ ...
                app.EditField_Threshold.Value*app.SynStats.amp_sigma(3);
            app.Thresh2FPR;
            app.Threshold_Updated;
        end

        % Value changed function: Slider_Threshold
        function Slider_ThresholdValueChanged(app, event)
            app.EditField_Threshold.Value = (app.Slider_Threshold.Value ...
                -app.SynStats.amp_mean(3))/app.SynStats.amp_sigma(3);
            app.Thresh2FPR;
            app.Threshold_Updated;
        end

        % Value changed function: EditField_CFAR
        function EditField_CFARValueChanged(app, event)
            FPR = app.EditField_CFAR.Value;
            if app.Detection.Detect_ASAP
                app.Slider_CFAR.Value = FPR/app.Detection.fpr(1)*100;
            else
                app.Slider_CFAR.Value = FPR;
            end
            app.FRP2Thresh;
            app.Threshold_Updated;
        end

        % Value changed function: Slider_CFAR
        function Slider_CFARValueChanged(app, event)
            FPR = app.Slider_CFAR.Value;
            if app.Detection.Detect_ASAP
                app.EditField_CFAR.Value = FPR/100*app.Detection.fpr(1);
            else
                app.EditField_CFAR.Value = FPR;
            end
            app.FRP2Thresh;
            app.Threshold_Updated;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                stop(app.menu_timer);
                delete(app.menu_timer);
                delete(app);
            catch
            end
        end

        % Value changed function: DropDown_Condition
        function DropDown_ConditionValueChanged(app, event)
            app.CondProb_Update;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Step1 = uitab(app.TabGroup);
            app.Tab_Step1.Title = 'Step 1: Characterization';
            app.UIAxes_1 = uiaxes(app.Tab_Step1);
            title(app.UIAxes_1, 'Fit PSD')
            xlabel(app.UIAxes_1, 'Freguency (Hz)')
            ylabel(app.UIAxes_1, 'PSD (dB/Hz)')
            app.ProjectDirectoryPanel = uipanel(app.Tab_Step1);
            app.ProjectDirectoryPanel.Title = 'Project Directory';
            app.SelectFolderButton = uibutton(app.ProjectDirectoryPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelectedDirectoryEditField = uieditfield(app.ProjectDirectoryPanel, 'text');
            app.SelectedDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @SelectedDirectoryEditFieldValueChanged, true);
            app.SelectedDirectoryEditFieldLabel = uilabel(app.ProjectDirectoryPanel);
            app.SelectedDirectoryEditFieldLabel.Text = 'Selected Directory:';
            app.SelectDataPanel = uipanel(app.Tab_Step1);
            app.SelectDataPanel.Title = 'Select Data';
            app.LFPDataDropDown = uidropdown(app.SelectDataPanel);
            app.LFPDataDropDown.Items = {'__Select__'};
            app.LFPDataDropDown.Editable = 'on';
            app.LFPDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LFPDataDropDownValueChanged, true);
            app.LFPDataDropDown.Value = '__Select__';
            app.LFPDataDropDownLabel = uilabel(app.SelectDataPanel);
            app.LFPDataDropDownLabel.Text = 'LFP Data';
            app.SamplingRateHzEditFieldLabel = uilabel(app.SelectDataPanel);
            app.SamplingRateHzEditFieldLabel.Text = {'Sampling Rate (Hz)'; ''};
            app.data_fs = uieditfield(app.SelectDataPanel, 'text');
            app.data_fs.ValueChangedFcn = createCallbackFcn(app, @data_fsValueChanged, true);
            app.data_fs.Value = '1000';
            app.FitPSDPanel = uipanel(app.Tab_Step1);
            app.FitPSDPanel.Title = 'Fit the PSD';
            app.OutlierThresholdButtonGroup = uibuttongroup(app.FitPSDPanel);
            app.OutlierThresholdButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OutlierThresholdButtonGroupSelectionChanged, true);
            app.OutlierThresholdButtonGroup.Title = 'Outlier Threshold (Decibel)';
            app.DefaultButton_nDB = uiradiobutton(app.OutlierThresholdButtonGroup);
            app.DefaultButton_nDB.Text = 'Default';
            app.DefaultButton_nDB.Value = true;
            app.SpecifyButton_nDB = uiradiobutton(app.OutlierThresholdButtonGroup);
            app.SpecifyButton_nDB.Text = 'Specify:';
            app.nDBEditField = uieditfield(app.OutlierThresholdButtonGroup, 'numeric');
            app.nDBEditField.Limits = [0 Inf];
            app.nDBEditField.ValueChangedFcn = createCallbackFcn(app, @nDBEditFieldValueChanged, true);
            app.nDBEditField.Value = 1;
            app.nDBLabel = uilabel(app.OutlierThresholdButtonGroup);
            app.nDBLabel.Text = 'dB';
            app.OscillationFrequencyRangeEditFieldLabel = uilabel(app.FitPSDPanel);
            app.OscillationFrequencyRangeEditFieldLabel.Text = 'Oscillation Frequency Range';
            app.FreqRangeEditField1 = uieditfield(app.FitPSDPanel, 'numeric');
            app.FreqRangeEditField1.Limits = [0 Inf];
            app.FreqRangeEditField1.Value = 30;
            app.FreqRangeEditField2 = uieditfield(app.FitPSDPanel, 'numeric');
            app.FreqRangeEditField2.Limits = [0 Inf];
            app.FreqRangeEditField2.Value = 80;
            app.Label = uilabel(app.FitPSDPanel);
            app.Label.Text = '-';
            app.FitRangeButtonGroup = uibuttongroup(app.FitPSDPanel);
            app.FitRangeButtonGroup.Title = 'Frequency Range for Fitting';
            app.AutofitButton = uiradiobutton(app.FitRangeButtonGroup);
            app.AutofitButton.Text = 'Autofit';
            app.AutofitButton.Value = true;
            app.SpecifyButton_FitRange = uiradiobutton(app.FitRangeButtonGroup);
            app.SpecifyButton_FitRange.Text = 'Specify:';
            app.FitRangeEditField1 = uieditfield(app.FitRangeButtonGroup, 'numeric');
            app.FitRangeEditField1.Limits = [0 Inf];
            app.FitRangeEditField1.Value = 10;
            app.FitRangeEditField2 = uieditfield(app.FitRangeButtonGroup, 'numeric');
            app.FitRangeEditField2.Limits = [0 Inf];
            app.FitRangeEditField2.Value = 200;
            app.EditField_2Label = uilabel(app.FitRangeButtonGroup);
            app.EditField_2Label.Text = '-';
            app.HzLabel = uilabel(app.FitRangeButtonGroup);
            app.HzLabel.Text = 'Hz';
            app.OptionsButton_PSD = uibutton(app.FitPSDPanel, 'push');
            app.OptionsButton_PSD.Enable = 'off';
            app.OptionsButton_PSD.Text = 'Options';
            app.FitPsdButton = uibutton(app.FitPSDPanel, 'push');
            app.FitPsdButton.ButtonPushedFcn = createCallbackFcn(app, @FitPsdButtonPushed, true);
            app.FitPsdButton.Enable = 'off';
            app.FitPsdButton.Text = 'Fit';
            app.HzLabel_2 = uilabel(app.FitPSDPanel);
            app.HzLabel_2.Text = 'Hz';
            app.CharacterizeBurstsandFitProbabilityDistributionPanel = uipanel(app.Tab_Step1);
            app.CharacterizeBurstsandFitProbabilityDistributionPanel.Title = 'Characterize Bursts and Fit Probability Distribution';
            app.NBinsButtonGroup = uibuttongroup(app.CharacterizeBurstsandFitProbabilityDistributionPanel);
            app.NBinsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NBinsButtonGroupSelectionChanged, true);
            app.NBinsButtonGroup.Title = 'Number of Histogram Bins';
            app.DefaultButton_NBins = uiradiobutton(app.NBinsButtonGroup);
            app.DefaultButton_NBins.Text = 'Default';
            app.DefaultButton_NBins.Value = true;
            app.SpecifyButton_NBins = uiradiobutton(app.NBinsButtonGroup);
            app.SpecifyButton_NBins.Text = 'Specify:';
            app.NBinsEditField = uieditfield(app.NBinsButtonGroup, 'numeric');
            app.NBinsEditField.Limits = [5 100];
            app.NBinsEditField.RoundFractionalValues = 'on';
            app.NBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NBinsEditFieldValueChanged, true);
            app.NBinsEditField.Value = 30;
            app.OptionsButton_Burst = uibutton(app.CharacterizeBurstsandFitProbabilityDistributionPanel, 'push');
            app.OptionsButton_Burst.Enable = 'off';
            app.OptionsButton_Burst.Text = 'Options';
            app.FitBurstsButton = uibutton(app.CharacterizeBurstsandFitProbabilityDistributionPanel, 'push');
            app.FitBurstsButton.ButtonPushedFcn = createCallbackFcn(app, @FitBurstsButtonPushed, true);
            app.FitBurstsButton.Enable = 'off';
            app.FitBurstsButton.Text = 'Run';
            app.SaveCharacterizationDataPanel = uipanel(app.Tab_Step1);
            app.SaveCharacterizationDataPanel.Title = 'Save Characterization Data';
            app.DatanameLabel = uilabel(app.SaveCharacterizationDataPanel);
            app.DatanameLabel.Text = 'Data name';
            app.EditField_Dataname = uieditfield(app.SaveCharacterizationDataPanel, 'text');
            app.EditField_Dataname.Value = 'Dataname';
            app.SaveCharacterizationDataButton = uibutton(app.SaveCharacterizationDataPanel, 'push');
            app.SaveCharacterizationDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCharacterizationDataButtonPushed, true);
            app.SaveCharacterizationDataButton.Enable = 'off';
            app.SaveCharacterizationDataButton.Text = 'Save';
            app.ResultsTextArea = uitextarea(app.Tab_Step1);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextAreaLabel = uilabel(app.Tab_Step1);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.TabGroup1 = uitabgroup(app.Tab_Step1);
            app.linearscaleTab = uitab(app.TabGroup1);
            app.linearscaleTab.Title = 'linear scale';
            app.Tab_linear = uitabgroup(app.linearscaleTab);
            app.Tab_linear.SelectionChangedFcn = createCallbackFcn(app, @Tab_linearSelectionChanged, true);
            app.APTab = uitab(app.Tab_linear);
            app.APTab.Title = 'AP';
            app.UIAxes2_1 = uiaxes(app.APTab);
            title(app.UIAxes2_1, 'Fit Bursts')
            xlabel(app.UIAxes2_1, 'AS-AP amplitude (\muV)')
            ylabel(app.UIAxes2_1, 'Probability density')
            app.CNTab = uitab(app.Tab_linear);
            app.CNTab.Title = 'CN';
            app.UIAxes2_2 = uiaxes(app.CNTab);
            title(app.UIAxes2_2, 'Fit Bursts')
            xlabel(app.UIAxes2_2, 'Cycle number')
            ylabel(app.UIAxes2_2, 'Probability density')
            app.BFTab = uitab(app.Tab_linear);
            app.BFTab.Title = 'BF';
            app.UIAxes2_3 = uiaxes(app.BFTab);
            title(app.UIAxes2_3, 'Fit Bursts')
            xlabel(app.UIAxes2_3, 'Burst frequency (Hz)')
            ylabel(app.UIAxes2_3, 'Probability density')
            app.logscaleTab = uitab(app.TabGroup1);
            app.logscaleTab.Title = 'log scale';
            app.Tab_log = uitabgroup(app.logscaleTab);
            app.Tab_log.SelectionChangedFcn = createCallbackFcn(app, @Tab_logSelectionChanged, true);
            app.APTab_2 = uitab(app.Tab_log);
            app.APTab_2.Title = 'AP';
            app.UIAxes2_4 = uiaxes(app.APTab_2);
            title(app.UIAxes2_4, 'Fit Bursts')
            xlabel(app.UIAxes2_4, 'AS-AP amplitude (log scale)')
            ylabel(app.UIAxes2_4, 'Probability density')
            app.CNTab_2 = uitab(app.Tab_log);
            app.CNTab_2.Title = 'CN';
            app.UIAxes2_5 = uiaxes(app.CNTab_2);
            title(app.UIAxes2_5, 'Fit Bursts')
            xlabel(app.UIAxes2_5, 'Cycle number (log scale)')
            ylabel(app.UIAxes2_5, 'Probability density')
            app.BFTab_2 = uitab(app.Tab_log);
            app.BFTab_2.Title = 'BF';
            app.UIAxes2_6 = uiaxes(app.BFTab_2);
            title(app.UIAxes2_6, 'Fit Bursts')
            xlabel(app.UIAxes2_6, 'Burst frequency (log scale)')
            ylabel(app.UIAxes2_6, 'Probability density')
            app.Tab_Step2 = uitab(app.TabGroup);
            app.Tab_Step2.Title = 'Step 2: Synthesis';
            app.UIAxes3 = uiaxes(app.Tab_Step2);
            title(app.UIAxes3, 'Result of Optimization')
            xlabel(app.UIAxes3, 'AS-AP amplitude (\muV)')
            ylabel(app.UIAxes3, 'Rate density (Hz/\muV)')
            app.CharacterizationDataPanel = uipanel(app.Tab_Step2);
            app.CharacterizationDataPanel.Title = 'Characterization Data';
            app.SelectRealDataButton = uibutton(app.CharacterizationDataPanel, 'push');
            app.SelectRealDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRealDataButtonPushed, true);
            app.SelectRealDataButton.Text = 'Select File';
            app.EditField_SelectRealDataname = uieditfield(app.CharacterizationDataPanel, 'text');
            app.EditField_SelectRealDataname.ValueChangedFcn = createCallbackFcn(app, @EditField_SelectRealDatanameValueChanged, true);
            app.SynthesizingParametersPanel = uipanel(app.Tab_Step2);
            app.SynthesizingParametersPanel.Title = 'Synthesizing Parameters';
            app.SamplingRateGroup = uibuttongroup(app.SynthesizingParametersPanel);
            app.SamplingRateGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplingRateGroupSelectionChanged, true);
            app.SamplingRateGroup.Title = 'Synthetic Sampling Rate';
            app.DefaultButton_fs = uiradiobutton(app.SamplingRateGroup);
            app.DefaultButton_fs.Text = 'Same as LFP Data';
            app.DefaultButton_fs.Value = true;
            app.SpecifyButton_fs = uiradiobutton(app.SamplingRateGroup);
            app.SpecifyButton_fs.Text = 'Specify:';
            app.fsEditField = uieditfield(app.SamplingRateGroup, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.ValueChangedFcn = createCallbackFcn(app, @fsEditFieldValueChanged, true);
            app.fsEditField.Value = 1000;
            app.HzLabel_3 = uilabel(app.SamplingRateGroup);
            app.HzLabel_3.Text = 'Hz';
            app.RngSeedLabel = uilabel(app.SynthesizingParametersPanel);
            app.RngSeedLabel.Text = 'Rng Seed';
            app.RngSeedEditField = uieditfield(app.SynthesizingParametersPanel, 'numeric');
            app.RngSeedEditField.Limits = [0 4294967291];
            app.LoadRealDataButton = uibutton(app.SynthesizingParametersPanel, 'push');
            app.LoadRealDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRealDataButtonPushed, true);
            app.LoadRealDataButton.Text = 'Load';
            app.OptionsButton_SynSettings = uibutton(app.SynthesizingParametersPanel, 'push');
            app.OptionsButton_SynSettings.Enable = 'off';
            app.OptionsButton_SynSettings.Text = 'Options';
            app.OptimizeAmplitudeDistributionParametersPanel = uipanel(app.Tab_Step2);
            app.OptimizeAmplitudeDistributionParametersPanel.Title = 'Optimize Amplitude Distribution Parameters';
            app.SyntheticLengthOptimButtonGroup = uibuttongroup(app.OptimizeAmplitudeDistributionParametersPanel);
            app.SyntheticLengthOptimButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SyntheticLengthOptimButtonGroupSelectionChanged, true);
            app.SyntheticLengthOptimButtonGroup.Title = 'Synthetic Data Length';
            app.DefaultButton_SynlenOptim = uiradiobutton(app.SyntheticLengthOptimButtonGroup);
            app.DefaultButton_SynlenOptim.Text = 'Default';
            app.DefaultButton_SynlenOptim.Value = true;
            app.SpecifyButton_SynlenOptim = uiradiobutton(app.SyntheticLengthOptimButtonGroup);
            app.SpecifyButton_SynlenOptim.Text = 'Specify:';
            app.SynlenOptimEditField = uieditfield(app.SyntheticLengthOptimButtonGroup, 'numeric');
            app.SynlenOptimEditField.Limits = [0 Inf];
            app.SynlenOptimEditField.ValueChangedFcn = createCallbackFcn(app, @SynlenOptimEditFieldValueChanged, true);
            app.SynlenOptimEditField.Value = 1000;
            app.secondsLabel = uilabel(app.SyntheticLengthOptimButtonGroup);
            app.secondsLabel.Text = 'seconds';
            app.OptionsButton_Optim = uibutton(app.OptimizeAmplitudeDistributionParametersPanel, 'push');
            app.OptionsButton_Optim.Enable = 'off';
            app.OptionsButton_Optim.Text = 'Options';
            app.FitAmpButton = uibutton(app.OptimizeAmplitudeDistributionParametersPanel, 'push');
            app.FitAmpButton.ButtonPushedFcn = createCallbackFcn(app, @FitAmpButtonPushed, true);
            app.FitAmpButton.Enable = 'off';
            app.FitAmpButton.Text = 'Fit';
            app.DistributionTypesListBoxLabel = uilabel(app.OptimizeAmplitudeDistributionParametersPanel);
            app.DistributionTypesListBoxLabel.Text = {'Distribution Types'; ''};
            app.DistributionTypesListBox = uilistbox(app.OptimizeAmplitudeDistributionParametersPanel);
            app.DistributionTypesListBox.Items = {};
            app.DistributionTypesListBox.Multiselect = 'on';
            app.DistributionTypesListBox.ValueChangedFcn = createCallbackFcn(app, @DistributionTypesListBoxValueChanged, true);
            app.DistributionTypesListBox.Value = {};
            app.PopulationDropDownLabel = uilabel(app.OptimizeAmplitudeDistributionParametersPanel);
            app.PopulationDropDownLabel.Text = 'Population';
            app.PopulationDropDown = uidropdown(app.OptimizeAmplitudeDistributionParametersPanel);
            app.PopulationDropDown.Items = {};
            app.PopulationDropDown.ValueChangedFcn = createCallbackFcn(app, @PopulationDropDownValueChanged, true);
            app.PopulationDropDown.Value = {};
            app.nPopEditField = uieditfield(app.OptimizeAmplitudeDistributionParametersPanel, 'numeric');
            app.nPopEditField.Limits = [4 40];
            app.nPopEditField.RoundFractionalValues = 'on';
            app.nPopEditField.ValueChangedFcn = createCallbackFcn(app, @nPopEditFieldValueChanged, true);
            app.nPopEditField.Value = 8;
            app.resetButton = uibutton(app.OptimizeAmplitudeDistributionParametersPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.ButtonGroup_FitTarget = uibuttongroup(app.OptimizeAmplitudeDistributionParametersPanel);
            app.Fit_AS_AP = uiradiobutton(app.ButtonGroup_FitTarget);
            app.Fit_AS_AP.Text = 'AS-AP';
            app.Fit_AS_AP.Value = true;
            app.Fit_AS_Amp = uiradiobutton(app.ButtonGroup_FitTarget);
            app.Fit_AS_Amp.Text = 'AS Amplitude';
            app.FitTargetLabel = uilabel(app.ButtonGroup_FitTarget);
            app.FitTargetLabel.Text = 'Fit Target:';
            app.GenerateSyntheticLFPPanel = uipanel(app.Tab_Step2);
            app.GenerateSyntheticLFPPanel.Title = 'Generate synthetic LFP';
            app.SyntheticLengthButtonGroup = uibuttongroup(app.GenerateSyntheticLFPPanel);
            app.SyntheticLengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SyntheticLengthButtonGroupSelectionChanged, true);
            app.SyntheticLengthButtonGroup.Title = 'Synthetic Data Length';
            app.DefaultButton_Synlen = uiradiobutton(app.SyntheticLengthButtonGroup);
            app.DefaultButton_Synlen.Text = 'Default';
            app.DefaultButton_Synlen.Value = true;
            app.SpecifyButton_Synlen = uiradiobutton(app.SyntheticLengthButtonGroup);
            app.SpecifyButton_Synlen.Text = 'Specify:';
            app.SynlenEditField = uieditfield(app.SyntheticLengthButtonGroup, 'numeric');
            app.SynlenEditField.Limits = [60 Inf];
            app.SynlenEditField.ValueChangedFcn = createCallbackFcn(app, @SynlenEditFieldValueChanged, true);
            app.SynlenEditField.Value = 5000;
            app.secondsLabel2 = uilabel(app.SyntheticLengthButtonGroup);
            app.secondsLabel2.Text = 'seconds';
            app.DistributionTypeDropDownLabel = uilabel(app.GenerateSyntheticLFPPanel);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.GenerateSyntheticLFPPanel);
            app.DistributionTypeDropDown.Items = {};
            app.DistributionTypeDropDown.Value = {};
            app.GenerateButton = uibutton(app.GenerateSyntheticLFPPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Run';
            app.SaveSyntheticParameterPanel = uipanel(app.Tab_Step2);
            app.SaveSyntheticParameterPanel.Title = 'Save Synthetic Parameter';
            app.EditField_SynParamDataname = uieditfield(app.SaveSyntheticParameterPanel, 'text');
            app.EditField_SynParamDataname.Value = 'Case name';
            app.SaveSyntheticButton = uibutton(app.SaveSyntheticParameterPanel, 'push');
            app.SaveSyntheticButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSyntheticButtonPushed, true);
            app.SaveSyntheticButton.Enable = 'off';
            app.SaveSyntheticButton.Text = 'Save';
            app.AlsoSaveSyntheticLFPCheckBox = uicheckbox(app.SaveSyntheticParameterPanel);
            app.AlsoSaveSyntheticLFPCheckBox.Enable = 'off';
            app.AlsoSaveSyntheticLFPCheckBox.Text = 'Also Save Synthetic LFP';
            app.ResultsTextArea_2 = uitextarea(app.Tab_Step2);
            app.ResultsTextArea_2.Editable = 'off';
            app.ResultsTextArea_2Label = uilabel(app.Tab_Step2);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.TabGroup2 = uitabgroup(app.Tab_Step2);
            app.PSDTab = uitab(app.TabGroup2);
            app.PSDTab.Title = 'Power Spectral Density';
            app.UIAxes4 = uiaxes(app.PSDTab);
            title(app.UIAxes4, 'Synthetic Data')
            xlabel(app.UIAxes4, 'Freguency (Hz)')
            ylabel(app.UIAxes4, 'PSD (dB/Hz)')
            app.BurstStatsTab = uitab(app.TabGroup2);
            app.BurstStatsTab.Title = 'Bursts Statistics';
            app.Tab_Bursts = uitabgroup(app.BurstStatsTab);
            app.APTab_3 = uitab(app.Tab_Bursts);
            app.APTab_3.Title = 'AP';
            app.UIAxes4_2 = uiaxes(app.APTab_3);
            title(app.UIAxes4_2, 'Synthetic Bursts')
            xlabel(app.UIAxes4_2, 'AS-AP amplitude (\muV)')
            ylabel(app.UIAxes4_2, 'Probability density')
            app.CNTab_3 = uitab(app.Tab_Bursts);
            app.CNTab_3.Title = 'CN';
            app.UIAxes4_3 = uiaxes(app.CNTab_3);
            title(app.UIAxes4_3, 'Synthetic Bursts')
            xlabel(app.UIAxes4_3, 'Cycle number')
            ylabel(app.UIAxes4_3, 'Probability density')
            app.BFTab_3 = uitab(app.Tab_Bursts);
            app.BFTab_3.Title = 'BF';
            app.UIAxes4_4 = uiaxes(app.BFTab_3);
            title(app.UIAxes4_4, 'Synthetic Bursts')
            xlabel(app.UIAxes4_4, 'Burst frequency (Hz)')
            ylabel(app.UIAxes4_4, 'Probability density')
            app.Tab_Step3 = uitab(app.TabGroup);
            app.Tab_Step3.Title = 'Step 3: Analysis';
            app.UIAxes5 = uiaxes(app.Tab_Step3);
            title(app.UIAxes5, 'ROC')
            xlabel(app.UIAxes5, 'False positive rate (%)')
            ylabel(app.UIAxes5, 'True positive rate (%)')
            app.UIAxes6 = uiaxes(app.Tab_Step3);
            title(app.UIAxes6, 'Ground Truth Distribution')
            xlabel(app.UIAxes6, 'AS-AP Amplitude (\muV)')
            ylabel(app.UIAxes6, 'Rate density (Hz/\muV)')
            app.SyntheticDataPanel = uipanel(app.Tab_Step3);
            app.SyntheticDataPanel.Title = 'Synthetic Data';
            app.SelectSynDataButton = uibutton(app.SyntheticDataPanel, 'push');
            app.SelectSynDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSynDataButtonPushed, true);
            app.SelectSynDataButton.Text = 'Select File';
            app.EditField_SelectSynDataname = uieditfield(app.SyntheticDataPanel, 'text');
            app.EditField_SelectSynDataname.ValueChangedFcn = createCallbackFcn(app, @EditField_SelectSynDatanameValueChanged, true);
            app.LoadSynDataButton = uibutton(app.SyntheticDataPanel, 'push');
            app.LoadSynDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSynDataButtonPushed, true);
            app.LoadSynDataButton.Text = 'Load';
            app.ReviewSettingButton = uibutton(app.SyntheticDataPanel, 'push');
            app.ReviewSettingButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewSettingButtonPushed, true);
            app.ReviewSettingButton.Text = 'Review Setting';
            app.PreselectedBoundsPanel = uipanel(app.Tab_Step3);
            app.PreselectedBoundsPanel.Title = 'Pre-selected Bounds';
            app.ButtonGroup_LowerBound = uibuttongroup(app.PreselectedBoundsPanel);
            app.ButtonGroup_LowerBound.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_LowerBoundSelectionChanged, true);
            app.DefaultButton_LowerBound = uiradiobutton(app.ButtonGroup_LowerBound);
            app.DefaultButton_LowerBound.Text = 'Default';
            app.DefaultButton_LowerBound.Value = true;
            app.SpecifyButton_LowerBound = uiradiobutton(app.ButtonGroup_LowerBound);
            app.SpecifyButton_LowerBound.Enable = 'off';
            app.SpecifyButton_LowerBound.Text = 'Specify:';
            app.LowerBoundLLabel = uilabel(app.ButtonGroup_LowerBound);
            app.LowerBoundLLabel.Text = 'Lower Bound θL';
            app.EditField_LowerBound = uieditfield(app.ButtonGroup_LowerBound, 'numeric');
            app.EditField_LowerBound.LowerLimitInclusive = 'off';
            app.EditField_LowerBound.UpperLimitInclusive = 'off';
            app.EditField_LowerBound.Limits = [0 Inf];
            app.EditField_LowerBound.ValueDisplayFormat = '%.4g μV';
            app.EditField_LowerBound.ValueChangedFcn = createCallbackFcn(app, @EditField_LowerBoundValueChanged, true);
            app.EditField_LowerBound.Enable = 'off';
            app.EditField_LowerBound.Value = 1;
            app.EditField_LowerBound_sig = uieditfield(app.ButtonGroup_LowerBound, 'numeric');
            app.EditField_LowerBound_sig.LowerLimitInclusive = 'off';
            app.EditField_LowerBound_sig.UpperLimitInclusive = 'off';
            app.EditField_LowerBound_sig.Limits = [0 Inf];
            app.EditField_LowerBound_sig.ValueDisplayFormat = '%.3g σ';
            app.EditField_LowerBound_sig.ValueChangedFcn = createCallbackFcn(app, @EditField_LowerBound_sigValueChanged, true);
            app.EditField_LowerBound_sig.Enable = 'off';
            app.EditField_LowerBound_sig.Value = 1.4142135623731;
            app.ButtonGroup_UpperBound = uibuttongroup(app.PreselectedBoundsPanel);
            app.ButtonGroup_UpperBound.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_UpperBoundSelectionChanged, true);
            app.DefaultButton_UpperBound = uiradiobutton(app.ButtonGroup_UpperBound);
            app.DefaultButton_UpperBound.Text = 'Default';
            app.DefaultButton_UpperBound.Value = true;
            app.SpecifyButton_UpperBound = uiradiobutton(app.ButtonGroup_UpperBound);
            app.SpecifyButton_UpperBound.Enable = 'off';
            app.SpecifyButton_UpperBound.Text = 'Specify:';
            app.UpperBoundULabel = uilabel(app.ButtonGroup_UpperBound);
            app.UpperBoundULabel.Text = {'Upper Bound θU'; ''};
            app.EditField_UpperBound = uieditfield(app.ButtonGroup_UpperBound, 'numeric');
            app.EditField_UpperBound.LowerLimitInclusive = 'off';
            app.EditField_UpperBound.UpperLimitInclusive = 'off';
            app.EditField_UpperBound.Limits = [0 Inf];
            app.EditField_UpperBound.ValueDisplayFormat = '%.4g μV';
            app.EditField_UpperBound.ValueChangedFcn = createCallbackFcn(app, @EditField_UpperBoundValueChanged, true);
            app.EditField_UpperBound.Enable = 'off';
            app.EditField_UpperBound.Value = 1;
            app.EditField_UpperBound_sig = uieditfield(app.ButtonGroup_UpperBound, 'numeric');
            app.EditField_UpperBound_sig.LowerLimitInclusive = 'off';
            app.EditField_UpperBound_sig.UpperLimitInclusive = 'off';
            app.EditField_UpperBound_sig.Limits = [0 Inf];
            app.EditField_UpperBound_sig.ValueDisplayFormat = '%.3g σ';
            app.EditField_UpperBound_sig.ValueChangedFcn = createCallbackFcn(app, @EditField_UpperBound_sigValueChanged, true);
            app.EditField_UpperBound_sig.Enable = 'off';
            app.EditField_UpperBound_sig.Value = 1.4142135623731;
            app.ButtonGroup_LowerBound_prop = uibuttongroup(app.PreselectedBoundsPanel);
            app.TimeLLabel = uilabel(app.ButtonGroup_LowerBound_prop);
            app.TimeLLabel.Text = 'Time (<θL)';
            app.EditField_TL = uieditfield(app.ButtonGroup_LowerBound_prop, 'numeric');
            app.EditField_TL.Limits = [0 100];
            app.EditField_TL.ValueDisplayFormat = '%.3g %%';
            app.EditField_TL.Editable = 'off';
            app.FalsePeakRateEditFieldLabel = uilabel(app.ButtonGroup_LowerBound_prop);
            app.FalsePeakRateEditFieldLabel.Text = 'False Peak Rate';
            app.FalsePeakRateEditField = uieditfield(app.ButtonGroup_LowerBound_prop, 'numeric');
            app.FalsePeakRateEditField.Limits = [0 Inf];
            app.FalsePeakRateEditField.ValueDisplayFormat = '%.4g Hz';
            app.FalsePeakRateEditField.Editable = 'off';
            app.FalsepeakrateEditField_perc = uieditfield(app.ButtonGroup_LowerBound_prop, 'numeric');
            app.FalsepeakrateEditField_perc.Limits = [0 100];
            app.FalsepeakrateEditField_perc.ValueDisplayFormat = '%.3g %%';
            app.FalsepeakrateEditField_perc.Editable = 'off';
            app.ButtonGroup_UpperBound_prop = uibuttongroup(app.PreselectedBoundsPanel);
            app.TimeULabel = uilabel(app.ButtonGroup_UpperBound_prop);
            app.TimeULabel.Text = 'Time (>θU)';
            app.EditField_TU = uieditfield(app.ButtonGroup_UpperBound_prop, 'numeric');
            app.EditField_TU.Limits = [0 100];
            app.EditField_TU.ValueDisplayFormat = '%.3g %%';
            app.EditField_TU.Editable = 'off';
            app.TruePeakRateEditFieldLabel = uilabel(app.ButtonGroup_UpperBound_prop);
            app.TruePeakRateEditFieldLabel.Text = 'True Peak Rate';
            app.TruePeakRateEditField = uieditfield(app.ButtonGroup_UpperBound_prop, 'numeric');
            app.TruePeakRateEditField.Limits = [0 Inf];
            app.TruePeakRateEditField.ValueDisplayFormat = '%.4g Hz';
            app.TruePeakRateEditField.Editable = 'off';
            app.TruepeakrateEditField_perc = uieditfield(app.ButtonGroup_UpperBound_prop, 'numeric');
            app.TruepeakrateEditField_perc.Limits = [0 100];
            app.TruepeakrateEditField_perc.ValueDisplayFormat = '%.3g %%';
            app.TruepeakrateEditField_perc.Editable = 'off';
            app.PreboundButton = uibutton(app.PreselectedBoundsPanel, 'push');
            app.PreboundButton.ButtonPushedFcn = createCallbackFcn(app, @PreboundButtonPushed, true);
            app.PreboundButton.Enable = 'off';
            app.PreboundButton.Text = 'Select Bounds';
            app.OptionsButton_Detection = uibutton(app.PreselectedBoundsPanel, 'push');
            app.OptionsButton_Detection.Enable = 'off';
            app.OptionsButton_Detection.Text = 'Options';
            app.DetectionPerformanceandROCcurvePanel = uipanel(app.Tab_Step3);
            app.DetectionPerformanceandROCcurvePanel.Title = 'Detection Performance and ROC curve';
            app.Slider_Threshold = uislider(app.DetectionPerformanceandROCcurvePanel);
            app.Slider_Threshold.ValueChangedFcn = createCallbackFcn(app, @Slider_ThresholdValueChanged, true);
            app.Slider_Threshold.Enable = 'off';
            app.Slider_Threshold.Value = 100;
            app.DetectionThresholdLabel = uilabel(app.DetectionPerformanceandROCcurvePanel);
            app.DetectionThresholdLabel.Enable = 'off';
            app.DetectionThresholdLabel.Text = 'Detection Threshold';
            app.EditField_Threshold = uieditfield(app.DetectionPerformanceandROCcurvePanel, 'numeric');
            app.EditField_Threshold.ValueDisplayFormat = '%.3g z-score';
            app.EditField_Threshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ThresholdValueChanged, true);
            app.EditField_Threshold.Enable = 'off';
            app.EditField_Threshold.Value = 1;
            app.VLabel = uilabel(app.DetectionPerformanceandROCcurvePanel);
            app.VLabel.Enable = 'off';
            app.VLabel.Text = 'μV';
            app.FalsePositiveRateLabel = uilabel(app.DetectionPerformanceandROCcurvePanel);
            app.FalsePositiveRateLabel.Enable = 'off';
            app.FalsePositiveRateLabel.Text = 'False Positive Rate';
            app.EditField_CFAR = uieditfield(app.DetectionPerformanceandROCcurvePanel, 'numeric');
            app.EditField_CFAR.Limits = [0 Inf];
            app.EditField_CFAR.ValueDisplayFormat = '%.3g Hz';
            app.EditField_CFAR.ValueChangedFcn = createCallbackFcn(app, @EditField_CFARValueChanged, true);
            app.EditField_CFAR.Enable = 'off';
            app.Slider_CFAR = uislider(app.DetectionPerformanceandROCcurvePanel);
            app.Slider_CFAR.ValueChangedFcn = createCallbackFcn(app, @Slider_CFARValueChanged, true);
            app.Slider_CFAR.Enable = 'off';
            app.Slider_CFAR.Value = 100;
            app.FPRLabel = uilabel(app.DetectionPerformanceandROCcurvePanel);
            app.FPRLabel.Enable = 'off';
            app.FPRLabel.Text = '%';
            app.ButtonGroup_DetectOn = uibuttongroup(app.DetectionPerformanceandROCcurvePanel);
            app.Detect_AS_AP = uiradiobutton(app.ButtonGroup_DetectOn);
            app.Detect_AS_AP.Text = 'AS-AP';
            app.Detect_AS_AP.Value = true;
            app.Detect_AS_Amp = uiradiobutton(app.ButtonGroup_DetectOn);
            app.Detect_AS_Amp.Text = 'AS Amplitude';
            app.DetectonLabel = uilabel(app.ButtonGroup_DetectOn);
            app.DetectonLabel.Text = 'Detect on';
            app.ButtonGroup_NThresholds = uibuttongroup(app.DetectionPerformanceandROCcurvePanel);
            app.ButtonGroup_NThresholds.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_NThresholdsSelectionChanged, true);
            app.DefaultButton_NThresh = uiradiobutton(app.ButtonGroup_NThresholds);
            app.DefaultButton_NThresh.Text = 'Default';
            app.DefaultButton_NThresh.Value = true;
            app.SpecifyButton_NThresh = uiradiobutton(app.ButtonGroup_NThresholds);
            app.SpecifyButton_NThresh.Text = 'Specify:';
            app.EditField_NThresh = uieditfield(app.ButtonGroup_NThresholds, 'numeric');
            app.EditField_NThresh.Limits = [10 300];
            app.EditField_NThresh.RoundFractionalValues = 'on';
            app.EditField_NThresh.ValueChangedFcn = createCallbackFcn(app, @EditField_NThreshValueChanged, true);
            app.EditField_NThresh.Value = 50;
            app.NumberofThresholdsLabel = uilabel(app.ButtonGroup_NThresholds);
            app.NumberofThresholdsLabel.Text = 'Number of Thresholds';
            app.DetectButton = uibutton(app.DetectionPerformanceandROCcurvePanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Enable = 'off';
            app.DetectButton.Text = 'Evaluate';
            app.ProbabilityGivenDetection = uipanel(app.DetectionPerformanceandROCcurvePanel);
            app.ProbabilityGivenDetectionLabel = uilabel(app.ProbabilityGivenDetection);
            app.ProbabilityGivenDetectionLabel.Enable = 'off';
            app.ProbabilityGivenDetectionLabel.Text = {'Conditional Probability'; 'Given a Detection'};
            app.DropDown_Condition = uidropdown(app.ProbabilityGivenDetection);
            app.DropDown_Condition.Items = {'> the Threshold', '= the Threshold'};
            app.DropDown_Condition.Editable = 'on';
            app.DropDown_Condition.ValueChangedFcn = createCallbackFcn(app, @DropDown_ConditionValueChanged, true);
            app.DropDown_Condition.Enable = 'off';
            app.DropDown_Condition.Value = '> the Threshold';
            app.FalseFDRLabel = uilabel(app.ProbabilityGivenDetection);
            app.FalseFDRLabel.Enable = 'off';
            app.FalseFDRLabel.Text = 'False (FDR)';
            app.EditField_PF = uieditfield(app.ProbabilityGivenDetection, 'numeric');
            app.EditField_PF.Limits = [0 100];
            app.EditField_PF.ValueDisplayFormat = '%.3g %%';
            app.EditField_PF.Editable = 'off';
            app.EditField_PF.Enable = 'off';
            app.IntermediateLabel = uilabel(app.ProbabilityGivenDetection);
            app.IntermediateLabel.Enable = 'off';
            app.IntermediateLabel.Text = 'Intermediate';
            app.EditField_PI = uieditfield(app.ProbabilityGivenDetection, 'numeric');
            app.EditField_PI.Limits = [0 100];
            app.EditField_PI.ValueDisplayFormat = '%.3g %%';
            app.EditField_PI.Editable = 'off';
            app.EditField_PI.Enable = 'off';
            app.TruePPVLabel = uilabel(app.ProbabilityGivenDetection);
            app.TruePPVLabel.Enable = 'off';
            app.TruePPVLabel.Text = 'True (PPV)';
            app.EditField_PT = uieditfield(app.ProbabilityGivenDetection, 'numeric');
            app.EditField_PT.Limits = [0 100];
            app.EditField_PT.ValueDisplayFormat = '%.3g %%';
            app.EditField_PT.Editable = 'off';
            app.EditField_PT.Enable = 'off';
            app.ResultsTextArea_3 = uitextarea(app.Tab_Step3);
            app.ResultsTextArea_3.Editable = 'off';
            app.ResultsTextArea_3Label = uilabel(app.Tab_Step3);
            app.ResultsTextArea_3Label.Text = 'Results';
            app.Button_Normalize = uibutton(app.Tab_Step3, 'state');
            app.Button_Normalize.ValueChangedFcn = createCallbackFcn(app, @Button_NormalizeValueChanged, true);
            app.Button_Normalize.Enable = 'off';
            app.Button_Normalize.Text = 'Normalize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end