classdef gui_options1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NFFTEditField                matlab.ui.control.NumericEditField
        SmoothingWindowEditField     matlab.ui.control.NumericEditField
        HzLabel                      matlab.ui.control.Label
        SampleDensityEditField       matlab.ui.control.NumericEditField
        SampleDensityEditFieldLabel  matlab.ui.control.Label
        SmoothingWindowEditField_2Label  matlab.ui.control.Label
        NFFTEditFieldLabel           matlab.ui.control.Label
        perNpLabel                   matlab.ui.control.Label
        UseFOOOFCheckBox             matlab.ui.control.CheckBox
        RestoreDefaultButton         matlab.ui.control.Button
        FOOOFPanel                   matlab.ui.container.Panel
        PeakWIdthLimitEditField1     matlab.ui.control.NumericEditField
        PeakWIdthLimitEditField2     matlab.ui.control.NumericEditField
        HzLabel_2                    matlab.ui.control.Label
        MaxNPeaksEditField           matlab.ui.control.NumericEditField
        AperiodicModeButtonGroup     matlab.ui.container.ButtonGroup
        AperiodicModeLabel           matlab.ui.control.Label
        fixedButton                  matlab.ui.control.RadioButton
        kneeButton                   matlab.ui.control.RadioButton
        MaxNPeaksEditFieldLabel      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        PeakWIdthLimitLabel          matlab.ui.control.Label
        RestoreDefaultButton_2       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp  % Main app
        params
        components
        fooof_params
        fooof_components
        aperiodic_mode = struct()
    end
    
    methods (Access = private)
        
        function set_aperiodic_mode(app,mode)
            app.AperiodicModeButtonGroup.SelectedObject = app.aperiodic_mode.(mode);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;    % main app object
            % parameter names and corresponding UI components
            app.params = {'nfft','smooth_win','sample_density','FOOOF'};
            app.fooof_params = {'peak_width_limits1','peak_width_limits2','max_n_peaks'};
            app.components = {app.NFFTEditField,app.SmoothingWindowEditField, ...
                app.SampleDensityEditField,app.UseFOOOFCheckBox};
            app.fooof_components = {app.PeakWIdthLimitEditField1, ...
                app.PeakWIdthLimitEditField2,app.MaxNPeaksEditField};
            for button=[app.kneeButton,app.fixedButton]
                app.aperiodic_mode.(button.Text) = button;
            end
            % Initialize parameters
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.CallingApp.get_specified_param(name);
                app.components{i}.Value = value;
            end
            for i=1:numel(app.fooof_params)
                name = app.fooof_params{i};
                value = app.CallingApp.get_specified_param(name);
                app.fooof_components{i}.Value = value;
            end
            app.set_aperiodic_mode(app.CallingApp.get_specified_param('aperiodic_mode'));
            app.UseFOOOFCheckBoxValueChanged;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton_PSD.Enable = "on"; % Enable the button in main app
            delete(app)
        end

        % Value changed function: NFFTEditField
        function NFFTEditFieldValueChanged(app, event)
            value = app.NFFTEditField.Value;
            app.CallingApp.set_param(app.params{1},value);
        end

        % Value changed function: SmoothingWindowEditField
        function SmoothingWindowEditFieldValueChanged(app, event)
            value = app.SmoothingWindowEditField.Value;
            app.CallingApp.set_param(app.params{2},value);
        end

        % Value changed function: SampleDensityEditField
        function SampleDensityEditFieldValueChanged(app, event)
            value = app.SampleDensityEditField.Value;
            app.CallingApp.set_param(app.params{3},value);
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.CallingApp.get_default_param(name);
                app.components{i}.Value = value;
                app.CallingApp.set_param(name,value);
            end
            app.UseFOOOFCheckBoxValueChanged;
        end

        % Value changed function: UseFOOOFCheckBox
        function UseFOOOFCheckBoxValueChanged(app, event)
            value = app.UseFOOOFCheckBox.Value;
            if value
                app.FOOOFPanel.Enable = 'on';
                app.SampleDensityEditField.Enable = 'off';
            else
                app.FOOOFPanel.Enable = 'off';
                app.SampleDensityEditField.Enable = 'on';
            end
            app.CallingApp.set_param(app.params{4},value);
        end

        % Value changed function: PeakWIdthLimitEditField1
        function PeakWIdthLimitEditField1ValueChanged(app, event)
            value = app.PeakWIdthLimitEditField1.Value;
            app.CallingApp.set_param(app.fooof_params{1},value);
        end

        % Value changed function: PeakWIdthLimitEditField2
        function PeakWIdthLimitEditField2ValueChanged(app, event)
            value = app.PeakWIdthLimitEditField2.Value;
            app.CallingApp.set_param(app.fooof_params{2},value);
        end

        % Value changed function: MaxNPeaksEditField
        function MaxNPeaksEditFieldValueChanged(app, event)
            value = app.MaxNPeaksEditField.Value;
            app.CallingApp.set_param(app.fooof_params{3},value);
        end

        % Selection changed function: AperiodicModeButtonGroup
        function AperiodicModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AperiodicModeButtonGroup.SelectedObject;
            app.CallingApp.set_param('aperiodic_mode',selectedButton.Text);
        end

        % Button pushed function: RestoreDefaultButton_2
        function RestoreDefaultButton_2Pushed(app, event)
            for i=1:numel(app.fooof_params)
                name = app.fooof_params{i};
                value = app.CallingApp.get_default_param(name);
                app.fooof_components{i}.Value = value;
                app.CallingApp.set_param(name,value);
            end
            app.set_aperiodic_mode(app.CallingApp.get_default_param('aperiodic_mode'));
            app.AperiodicModeButtonGroupSelectionChanged
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options: Fit PSD';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FOOOFPanel = uipanel(app.UIFigure);
            app.FOOOFPanel.Enable = 'off';
            app.RestoreDefaultButton_2 = uibutton(app.FOOOFPanel, 'push');
            app.RestoreDefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButton_2Pushed, true);
            app.RestoreDefaultButton_2.Text = 'Restore Default';
            app.PeakWIdthLimitLabel = uilabel(app.FOOOFPanel);
            app.PeakWIdthLimitLabel.Text = 'Peak WIdth Limit';
            app.Label = uilabel(app.FOOOFPanel);
            app.Label.Text = ' - ';
            app.MaxNPeaksEditFieldLabel = uilabel(app.FOOOFPanel);
            app.MaxNPeaksEditFieldLabel.Text = 'Max N Peaks';
            app.AperiodicModeButtonGroup = uibuttongroup(app.FOOOFPanel);
            app.AperiodicModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AperiodicModeButtonGroupSelectionChanged, true);
            app.kneeButton = uiradiobutton(app.AperiodicModeButtonGroup);
            app.kneeButton.Text = 'knee';
            app.kneeButton.Value = true;
            app.fixedButton = uiradiobutton(app.AperiodicModeButtonGroup);
            app.fixedButton.Text = 'fixed';
            app.AperiodicModeLabel = uilabel(app.AperiodicModeButtonGroup);
            app.AperiodicModeLabel.Text = 'Aperiodic Mode';
            app.MaxNPeaksEditField = uieditfield(app.FOOOFPanel, 'numeric');
            app.MaxNPeaksEditField.Limits = [0 Inf];
            app.MaxNPeaksEditField.RoundFractionalValues = 'on';
            app.MaxNPeaksEditField.ValueDisplayFormat = '%d';
            app.MaxNPeaksEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNPeaksEditFieldValueChanged, true);
            app.MaxNPeaksEditField.Value = 6;
            app.HzLabel_2 = uilabel(app.FOOOFPanel);
            app.HzLabel_2.Text = 'Hz';
            app.PeakWIdthLimitEditField2 = uieditfield(app.FOOOFPanel, 'numeric');
            app.PeakWIdthLimitEditField2.Limits = [0 Inf];
            app.PeakWIdthLimitEditField2.ValueChangedFcn = createCallbackFcn(app, @PeakWIdthLimitEditField2ValueChanged, true);
            app.PeakWIdthLimitEditField2.Value = Inf;
            app.PeakWIdthLimitEditField1 = uieditfield(app.FOOOFPanel, 'numeric');
            app.PeakWIdthLimitEditField1.Limits = [0 Inf];
            app.PeakWIdthLimitEditField1.ValueChangedFcn = createCallbackFcn(app, @PeakWIdthLimitEditField1ValueChanged, true);
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.UseFOOOFCheckBox = uicheckbox(app.UIFigure);
            app.UseFOOOFCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFOOOFCheckBoxValueChanged, true);
            app.UseFOOOFCheckBox.Text = 'Use FOOOF';
            app.perNpLabel = uilabel(app.UIFigure);
            app.perNpLabel.Text = 'per Np';
            app.NFFTEditFieldLabel = uilabel(app.UIFigure);
            app.NFFTEditFieldLabel.Text = 'NFFT';
            app.SmoothingWindowEditField_2Label = uilabel(app.UIFigure);
            app.SmoothingWindowEditField_2Label.Text = 'Smoothing Window';
            app.SampleDensityEditFieldLabel = uilabel(app.UIFigure);
            app.SampleDensityEditFieldLabel.Text = 'Sample Density';
            app.SampleDensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleDensityEditField.Limits = [0 Inf];
            app.SampleDensityEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDensityEditFieldValueChanged, true);
            app.SampleDensityEditField.Value = 50;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.SmoothingWindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothingWindowEditField.Limits = [0 Inf];
            app.SmoothingWindowEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingWindowEditFieldValueChanged, true);
            app.SmoothingWindowEditField.Value = 2;
            app.NFFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.NFFTEditField.Limits = [32 Inf];
            app.NFFTEditField.RoundFractionalValues = 'on';
            app.NFFTEditField.ValueDisplayFormat = '%d';
            app.NFFTEditField.ValueChangedFcn = createCallbackFcn(app, @NFFTEditFieldValueChanged, true);
            app.NFFTEditField.Value = 8192;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_options1_1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end