classdef gui_options1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FilterOrderEditField           matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        ZscoreThresholdEditField       matlab.ui.control.NumericEditField
        ZscoreThresholdEditFieldLabel  matlab.ui.control.Label
        DurationStopProportionEditField  matlab.ui.control.NumericEditField
        DurationStopProportionEditFieldLabel  matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        BurstFrequencyResolutionEditField  matlab.ui.control.NumericEditField
        BurstFrequencyResolutionEditFieldLabel  matlab.ui.control.Label
        RestoreDefaultButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp  % Main app
        params
        components
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;    % main app object
            % parameter names and corresponding UI components
            app.params = {'butter_order','ZS_threshold','stop_perc','freq_resolution'};
            app.components = {app.FilterOrderEditField,app.ZscoreThresholdEditField, ...
                app.DurationStopProportionEditField,app.BurstFrequencyResolutionEditField};
            % Initialize parameters
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.CallingApp.get_specified_param(name);
                app.components{i}.Value = value;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton_Burst.Enable = "on";   % Enable the button in main app
            delete(app)
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            app.CallingApp.set_param(app.params{1},value);
        end

        % Value changed function: ZscoreThresholdEditField
        function ZscoreThresholdEditFieldValueChanged(app, event)
            value = app.ZscoreThresholdEditField.Value;
            app.CallingApp.set_param(app.params{2},value);
        end

        % Value changed function: DurationStopProportionEditField
        function DurationStopProportionEditFieldValueChanged(app, event)
            value = app.DurationStopProportionEditField.Value;
            app.CallingApp.set_param(app.params{3},value);
        end

        % Value changed function: BurstFrequencyResolutionEditField
        function BurstFrequencyResolutionEditFieldValueChanged(app, event)
            value = app.BurstFrequencyResolutionEditField.Value;
            app.CallingApp.set_param(app.params{4},value);
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.CallingApp.get_default_param(name);
                app.components{i}.Value = value;
                app.CallingApp.set_param(name,value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options: Characterize Bursts';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.BurstFrequencyResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.BurstFrequencyResolutionEditFieldLabel.Text = 'Burst Frequency Resolution';
            app.BurstFrequencyResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.BurstFrequencyResolutionEditField.Limits = [0 Inf];
            app.BurstFrequencyResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @BurstFrequencyResolutionEditFieldValueChanged, true);
            app.BurstFrequencyResolutionEditField.Value = 0.25;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.DurationStopProportionEditFieldLabel = uilabel(app.UIFigure);
            app.DurationStopProportionEditFieldLabel.Text = 'Duration Stop Proportion';
            app.DurationStopProportionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationStopProportionEditField.Limits = [0 1];
            app.DurationStopProportionEditField.ValueDisplayFormat = '%.4g';
            app.DurationStopProportionEditField.ValueChangedFcn = createCallbackFcn(app, @DurationStopProportionEditFieldValueChanged, true);
            app.DurationStopProportionEditField.Value = 0.25;
            app.ZscoreThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ZscoreThresholdEditFieldLabel.Text = 'Z-score Threshold';
            app.ZscoreThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZscoreThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ZscoreThresholdEditFieldValueChanged, true);
            app.ZscoreThresholdEditField.Value = 2;
            app.FilterOrderEditFieldLabel = uilabel(app.UIFigure);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterOrderEditField.Limits = [1 Inf];
            app.FilterOrderEditField.RoundFractionalValues = 'on';
            app.FilterOrderEditField.ValueDisplayFormat = '%d';
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.FilterOrderEditField.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_options1_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end