classdef gui_options2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CorrelatedAttributesLabel     matlab.ui.control.Label
        CorrelatedAttributesCheckBox  matlab.ui.control.CheckBox
        BurstAtomWidthEditField       matlab.ui.control.NumericEditField
        SigmaLabel                    matlab.ui.control.Label
        CNButtonGroup                 matlab.ui.container.ButtonGroup
        CNDistributionLabel           matlab.ui.control.Label
        CNEmpiricalButton             matlab.ui.control.RadioButton
        CNParametricButton            matlab.ui.control.RadioButton
        BFButtonGroup                 matlab.ui.container.ButtonGroup
        BFDistributionLabel           matlab.ui.control.Label
        BFEmpiricalButton             matlab.ui.control.RadioButton
        BFParametricButton            matlab.ui.control.RadioButton
        MatchPowerButtonGroup         matlab.ui.container.ButtonGroup
        MatchPowerLabel               matlab.ui.control.Label
        EstimatedButton               matlab.ui.control.RadioButton
        ExactButton                   matlab.ui.control.RadioButton
        BurstAtomWidthEditFieldLabel  matlab.ui.control.Label
        RestoreDefaultButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp  % Main app
        params
        components
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;    % main app object
            % parameter names and corresponding UI components
            app.params = {'correlated','width_edge','empr_CN','empr_BF','power_match'};
            app.components = {app.CorrelatedAttributesCheckBox,app.BurstAtomWidthEditField, ...
                app.CNEmpiricalButton,app.BFEmpiricalButton,app.ExactButton};
            % Initialize parameters
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.CallingApp.get_specified_param(name);
                app.components{i}.Value = value;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton_SynSettings.Enable = "on"; % Enable the button in main app
            delete(app)
        end

        % Value changed function: CorrelatedAttributesCheckBox
        function CorrelatedAttributesCheckBoxValueChanged(app, event)
            value = app.CorrelatedAttributesCheckBox.Value;
            app.CallingApp.set_param(app.params{1},value);
        end

        % Value changed function: BurstAtomWidthEditField
        function BurstAtomWidthEditFieldValueChanged(app, event)
            value = app.BurstAtomWidthEditField.Value;
            app.CallingApp.set_param(app.params{2},value);
        end

        % Selection changed function: CNButtonGroup
        function CNButtonGroupSelectionChanged(app, event)
            value = app.CNEmpiricalButton.Value;
            app.CallingApp.set_param(app.params{3},value);
        end

        % Selection changed function: BFButtonGroup
        function BFButtonGroupSelectionChanged(app, event)
            value = app.BFEmpiricalButton.Value;
            app.CallingApp.set_param(app.params{4},value);
        end

        % Selection changed function: MatchPowerButtonGroup
        function MatchPowerButtonGroupSelectionChanged(app, event)
            value = app.ExactButton.Value;
            app.CallingApp.set_param(app.params{5},value);
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.CallingApp.get_default_param(name);
                app.components{i}.Value = value;
                app.CallingApp.set_param(name,value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options: Synthesize Bursts';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.BurstAtomWidthEditFieldLabel = uilabel(app.UIFigure);
            app.BurstAtomWidthEditFieldLabel.Text = 'Burst Atom Width';
            app.MatchPowerButtonGroup = uibuttongroup(app.UIFigure);
            app.MatchPowerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MatchPowerButtonGroupSelectionChanged, true);
            app.ExactButton = uiradiobutton(app.MatchPowerButtonGroup);
            app.ExactButton.Text = 'Exact';
            app.EstimatedButton = uiradiobutton(app.MatchPowerButtonGroup);
            app.EstimatedButton.Text = 'Estimated';
            app.EstimatedButton.Value = true;
            app.MatchPowerLabel = uilabel(app.MatchPowerButtonGroup);
            app.MatchPowerLabel.Text = 'Match Power';
            app.BFButtonGroup = uibuttongroup(app.UIFigure);
            app.BFButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BFButtonGroupSelectionChanged, true);
            app.BFParametricButton = uiradiobutton(app.BFButtonGroup);
            app.BFParametricButton.Text = 'Parametric';
            app.BFEmpiricalButton = uiradiobutton(app.BFButtonGroup);
            app.BFEmpiricalButton.Text = 'Empirical';
            app.BFEmpiricalButton.Value = true;
            app.BFDistributionLabel = uilabel(app.BFButtonGroup);
            app.BFDistributionLabel.Text = 'BF Distribution';
            app.CNButtonGroup = uibuttongroup(app.UIFigure);
            app.CNButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CNButtonGroupSelectionChanged, true);
            app.CNParametricButton = uiradiobutton(app.CNButtonGroup);
            app.CNParametricButton.Text = 'Parametric';
            app.CNEmpiricalButton = uiradiobutton(app.CNButtonGroup);
            app.CNEmpiricalButton.Text = 'Empirical';
            app.CNEmpiricalButton.Value = true;
            app.CNDistributionLabel = uilabel(app.CNButtonGroup);
            app.CNDistributionLabel.Text = 'CN Distribution';
            app.SigmaLabel = uilabel(app.UIFigure);
            app.SigmaLabel.Text = 'σ';
            app.BurstAtomWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BurstAtomWidthEditField.Limits = [0 Inf];
            app.BurstAtomWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BurstAtomWidthEditFieldValueChanged, true);
            app.BurstAtomWidthEditField.Value = 3;
            app.CorrelatedAttributesCheckBox = uicheckbox(app.UIFigure);
            app.CorrelatedAttributesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrelatedAttributesCheckBoxValueChanged, true);
            app.CorrelatedAttributesCheckBox.Text = '';
            app.CorrelatedAttributesCheckBox.Value = true;
            app.CorrelatedAttributesLabel = uilabel(app.UIFigure);
            app.CorrelatedAttributesLabel.Text = 'Correlated Attributes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_options2_1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end