classdef gui_options2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RestoreDefaultButton           matlab.ui.control.Button
        DivergenceTypeButtonGroup      matlab.ui.container.ButtonGroup
        DivergenceTypeLabel            matlab.ui.control.Label
        KLButton                       matlab.ui.control.RadioButton
        JSButton                       matlab.ui.control.RadioButton
        ParticleSwarmOptimizationParametersPanel  matlab.ui.container.Panel
        MinimumIterationsEditField     matlab.ui.control.NumericEditField
        MaximumIterationsEditField     matlab.ui.control.NumericEditField
        StallIterationsEditField       matlab.ui.control.NumericEditField
        TerminationToleranceEditField  matlab.ui.control.NumericEditField
        InertiaDampingRatioEditField   matlab.ui.control.NumericEditField
        InertiaDampingRatioEditField_2Label  matlab.ui.control.Label
        TerminationToleranceEditFieldLabel  matlab.ui.control.Label
        StallIterationsEditFieldLabel  matlab.ui.control.Label
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        MinimumIterationsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp  % Main app
        params
        components
        div_type = struct()
    end
    
    methods (Access = private)
        
        function value = get_default_param(app,name)
            value = app.CallingApp.PSOparams_default.(name);
        end

        function value = get_specified_param(app,name)
            value = app.CallingApp.PSOparams.(name);
        end

        function set_param(app,name,value)
            app.CallingApp.PSOparams.(name) = value;
        end
        
        function set_div_type(app,div)
            app.DivergenceTypeButtonGroup.SelectedObject = app.div_type.(div);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;    % main app object
            % parameter names and corresponding UI components
            app.params = {'MinIt','MaxIt','MaxStall','FunTol','wdamp'};
            app.components = {app.MinimumIterationsEditField, ...
                app.MaximumIterationsEditField,app.StallIterationsEditField, ...
                app.TerminationToleranceEditField,app.InertiaDampingRatioEditField};
            for button=[app.KLButton,app.JSButton]
                app.div_type.(button.Text) = button;
            end
            % Initialize parameters
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.get_specified_param(name);
                app.components{i}.Value = value;
            end
            app.set_div_type(app.CallingApp.get_specified_param('div_type'));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton_Optim.Enable = "on";   % Enable the button in main app
            delete(app)
        end

        % Value changed function: MinimumIterationsEditField
        function MinimumIterationsEditFieldValueChanged(app, event)
            value = app.MinimumIterationsEditField.Value;
            app.set_param(app.params{1},value);
        end

        % Value changed function: MaximumIterationsEditField
        function MaximumIterationsEditFieldValueChanged(app, event)
            value = app.MaximumIterationsEditField.Value;
            app.set_param(app.params{2},value);
        end

        % Value changed function: StallIterationsEditField
        function StallIterationsEditFieldValueChanged(app, event)
            value = app.StallIterationsEditField.Value;
            app.set_param(app.params{3},value);
        end

        % Value changed function: TerminationToleranceEditField
        function TerminationToleranceEditFieldValueChanged(app, event)
            value = app.TerminationToleranceEditField.Value;
            app.set_param(app.params{4},value);
        end

        % Value changed function: InertiaDampingRatioEditField
        function InertiaDampingRatioEditFieldValueChanged(app, event)
            value = app.InertiaDampingRatioEditField.Value;
            app.set_param(app.params{5},value);
        end

        % Selection changed function: DivergenceTypeButtonGroup
        function DivergenceTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DivergenceTypeButtonGroup.SelectedObject;
            app.CallingApp.set_param('div_type',selectedButton.Text);
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            for i=1:numel(app.params)
                name = app.params{i};
                value = app.get_default_param(name);
                app.components{i}.Value = value;
                app.set_param(name,value);
            end
            app.set_div_type(app.CallingApp.get_default_param('div_type'));
            app.DivergenceTypeButtonGroupSelectionChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options: Optimize Amplitude';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParticleSwarmOptimizationParametersPanel = uipanel(app.UIFigure);
            app.ParticleSwarmOptimizationParametersPanel.Title = 'Particle Swarm Optimization Parameters';
            app.MinimumIterationsEditFieldLabel = uilabel(app.ParticleSwarmOptimizationParametersPanel);
            app.MinimumIterationsEditFieldLabel.Text = 'Minimum Iterations';
            app.MaximumIterationsEditFieldLabel = uilabel(app.ParticleSwarmOptimizationParametersPanel);
            app.MaximumIterationsEditFieldLabel.Text = 'Maximum Iterations';
            app.StallIterationsEditFieldLabel = uilabel(app.ParticleSwarmOptimizationParametersPanel);
            app.StallIterationsEditFieldLabel.Text = 'Stall Iterations';
            app.TerminationToleranceEditFieldLabel = uilabel(app.ParticleSwarmOptimizationParametersPanel);
            app.TerminationToleranceEditFieldLabel.Text = 'Termination Tolerance';
            app.InertiaDampingRatioEditField_2Label = uilabel(app.ParticleSwarmOptimizationParametersPanel);
            app.InertiaDampingRatioEditField_2Label.Text = 'Inertia Damping Ratio';
            app.InertiaDampingRatioEditField = uieditfield(app.ParticleSwarmOptimizationParametersPanel, 'numeric');
            app.InertiaDampingRatioEditField.Limits = [0 1];
            app.InertiaDampingRatioEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaDampingRatioEditFieldValueChanged, true);
            app.InertiaDampingRatioEditField.Value = 0.92;
            app.TerminationToleranceEditField = uieditfield(app.ParticleSwarmOptimizationParametersPanel, 'numeric');
            app.TerminationToleranceEditField.Limits = [2.22044604925031e-16 Inf];
            app.TerminationToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @TerminationToleranceEditFieldValueChanged, true);
            app.TerminationToleranceEditField.Value = 1e-05;
            app.StallIterationsEditField = uieditfield(app.ParticleSwarmOptimizationParametersPanel, 'numeric');
            app.StallIterationsEditField.Limits = [2 Inf];
            app.StallIterationsEditField.RoundFractionalValues = 'on';
            app.StallIterationsEditField.ValueDisplayFormat = '%d';
            app.StallIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @StallIterationsEditFieldValueChanged, true);
            app.StallIterationsEditField.Value = 5;
            app.MaximumIterationsEditField = uieditfield(app.ParticleSwarmOptimizationParametersPanel, 'numeric');
            app.MaximumIterationsEditField.Limits = [3 Inf];
            app.MaximumIterationsEditField.RoundFractionalValues = 'on';
            app.MaximumIterationsEditField.ValueDisplayFormat = '%d';
            app.MaximumIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumIterationsEditFieldValueChanged, true);
            app.MaximumIterationsEditField.Value = 30;
            app.MinimumIterationsEditField = uieditfield(app.ParticleSwarmOptimizationParametersPanel, 'numeric');
            app.MinimumIterationsEditField.Limits = [2 Inf];
            app.MinimumIterationsEditField.RoundFractionalValues = 'on';
            app.MinimumIterationsEditField.ValueDisplayFormat = '%d';
            app.MinimumIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumIterationsEditFieldValueChanged, true);
            app.MinimumIterationsEditField.Value = 15;
            app.DivergenceTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DivergenceTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DivergenceTypeButtonGroupSelectionChanged, true);
            app.JSButton = uiradiobutton(app.DivergenceTypeButtonGroup);
            app.JSButton.Text = 'JS';
            app.KLButton = uiradiobutton(app.DivergenceTypeButtonGroup);
            app.KLButton.Text = 'KL';
            app.KLButton.Value = true;
            app.DivergenceTypeLabel = uilabel(app.DivergenceTypeButtonGroup);
            app.DivergenceTypeLabel.Text = 'Divergence Type';
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_options2_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end