classdef gui_savefig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BackgroundColorEditField       matlab.ui.control.EditField
        BackgroundColorEditFieldLabel  matlab.ui.control.Label
        ResolutionDPIEditField         matlab.ui.control.NumericEditField
        ResolutionDPIEditFieldLabel    matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FiguresListBox                 matlab.ui.control.ListBox
        FiguresListBoxLabel            matlab.ui.control.Label
        ImageFormatDropDown            matlab.ui.control.DropDown
        ImageFormatDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp  % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;    % main app object
            app.FiguresListBox.ItemsData = find(caller.CreatedPanels);
            app.FiguresListBox.Items = caller.PanelNames(app.FiguresListBox.ItemsData);
            app.FiguresListBox.Value = app.FiguresListBox.ItemsData;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SaveFiguresMenu.Enable = "on";   % Enable the button in main app
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            panels = app.FiguresListBox.Value;
            imfmt = app.ImageFormatDropDown.Value;
            res = app.ResolutionDPIEditField.Value;
            bgclr = app.BackgroundColorEditField.Value;
            flag = app.CallingApp.save_fig(panels,imfmt,res,bgclr);
            if flag
                app.UIFigureCloseRequest
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Save Figures';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageFormatDropDownLabel = uilabel(app.UIFigure);
            app.ImageFormatDropDownLabel.Tooltip = {'See input arguments of function "exportgraphics"'};
            app.ImageFormatDropDownLabel.Text = 'Image Format';
            app.ImageFormatDropDown = uidropdown(app.UIFigure);
            app.ImageFormatDropDown.Items = {'jpg', 'jpeg', 'png', 'tif', 'tiff', 'gif', 'pdf', 'emf', 'eps'};
            app.ImageFormatDropDown.Tooltip = {''};
            app.ImageFormatDropDown.Value = 'jpg';
            app.FiguresListBoxLabel = uilabel(app.UIFigure);
            app.FiguresListBoxLabel.Text = 'Figures';
            app.FiguresListBox = uilistbox(app.UIFigure);
            app.FiguresListBox.Items = {};
            app.FiguresListBox.Multiselect = 'on';
            app.FiguresListBox.Tooltip = {'Hold ''Ctrl'' to select multiple'};
            app.FiguresListBox.Value = {};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResolutionDPIEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionDPIEditFieldLabel.Tooltip = {'See input arguments of function "exportgraphics"'};
            app.ResolutionDPIEditFieldLabel.Text = 'Resolution (DPI)';
            app.ResolutionDPIEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionDPIEditField.Limits = [1 Inf];
            app.ResolutionDPIEditField.RoundFractionalValues = 'on';
            app.ResolutionDPIEditField.ValueDisplayFormat = '%d';
            app.ResolutionDPIEditField.Tooltip = {''};
            app.ResolutionDPIEditField.Value = 150;
            app.BackgroundColorEditFieldLabel = uilabel(app.UIFigure);
            app.BackgroundColorEditFieldLabel.Tooltip = {'See input arguments of function "exportgraphics"'};
            app.BackgroundColorEditFieldLabel.Text = 'Background Color';
            app.BackgroundColorEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundColorEditField.Tooltip = {''};
            app.BackgroundColorEditField.Value = '[1,1,1]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_savefig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end